classdef APP_V5_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        U_Main                          matlab.ui.Figure
        Me_M_File                       matlab.ui.container.Menu
        MeFi_MM_NewProject              matlab.ui.container.Menu
        MeFi_MM_LoadProject             matlab.ui.container.Menu
        MeFi_MM_SaveProject             matlab.ui.container.Menu
        MeFi_MM_SaveProjectAs           matlab.ui.container.Menu
        MeFi_MM_SaveSettings            matlab.ui.container.Menu
        MeFi_MM_LoadSettings            matlab.ui.container.Menu
        MeFi_MM_Exit                    matlab.ui.container.Menu
        Me_M_Help                       matlab.ui.container.Menu
        MeHe_MM_Guide                   matlab.ui.container.Menu
        MeHe_MM_About                   matlab.ui.container.Menu
        MeHe_MM_Email                   matlab.ui.container.Menu
        T_TabGroup                      matlab.ui.container.TabGroup
        T_Settings                      matlab.ui.container.Tab
        Se_TP_Directory                 matlab.ui.container.Panel
        SeDi_TPB_CopyAPP                matlab.ui.control.Button
        SeDi_TP_MicroManagerLabel       matlab.ui.control.Label
        SeDi_TPF_MicroManagerDirectory  matlab.ui.control.EditField
        SeDi_TPB_BrowseMicroManager     matlab.ui.control.Button
        SeDi_TPB_CopyMicroManager       matlab.ui.control.Button
        SeDi_TPB_BrowseAMIGO            matlab.ui.control.Button
        SeDi_TPB_CopyAMIGO              matlab.ui.control.Button
        SeDi_TP_AMIGOLabel              matlab.ui.control.Label
        SeDi_TPF_AMIGODirectory         matlab.ui.control.EditField
        SeDi_TP_APPLabel                matlab.ui.control.Label
        SeDi_TPS_InitialiseAMIGO        matlab.ui.control.StateButton
        SeDi_TPF_APPDirectory           matlab.ui.control.EditField
        Se_TP_AddOns                    matlab.ui.container.Panel
        SeAd_TP_AddOnListLabel          matlab.ui.control.Label
        SeAd_TPB_BrowseAddOn            matlab.ui.control.Button
        SeAd_TP_DirectcoryLabel         matlab.ui.control.Label
        SeAd_TPF_AddOnDirectory         matlab.ui.control.EditField
        SeAd_TP_NameLabel               matlab.ui.control.Label
        SeAd_TPF_AddOnName              matlab.ui.control.TextArea
        SeAd_TPA_AddOnTable             matlab.ui.control.Table
        SeAd_TPS_InitialiseAddOn        matlab.ui.control.StateButton
        SeAd_TPS_RemoveSelectedAddOn    matlab.ui.control.StateButton
        SeAd_TPS_RemoveAllAddOn         matlab.ui.control.StateButton
        Se_TP_Calculation               matlab.ui.container.Panel
        SeCa_TP_ParallelPoolLabel       matlab.ui.control.Label
        SeCa_TP_CUDALabel               matlab.ui.control.Label
        SeCa_TP_WorkersSliderLabel      matlab.ui.control.Label
        SeCa_TPR_NumofWorkers           matlab.ui.control.Slider
        SeCa_TPC_CUDA4ImageProcessing   matlab.ui.control.CheckBox
        SeCa_TPC_CUDA4OED               matlab.ui.control.CheckBox
        SeCa_TPE_NumofWorkers           matlab.ui.control.EditField
        SeCa_TPS_ActivatethePool        matlab.ui.control.StateButton
        OEDTab                          matlab.ui.container.Tab
        ActuationSystemTab              matlab.ui.container.Tab
        T_ImageProcessing               matlab.ui.container.Tab
        Im_TG_SelectedImage             matlab.ui.control.UIAxes
        Im_TP_Files                     matlab.ui.container.Panel
        ImFi_TPB_BrowseFiles            matlab.ui.control.Button
        ImFi_TPB_CopyAddress            matlab.ui.control.Button
        ImFi_TPC_TrackNew               matlab.ui.control.CheckBox
        ImFi_TPL_FileListBox            matlab.ui.control.ListBox
        AddressEditFieldLabel           matlab.ui.control.Label
        ImFi_TPF_AddressField           matlab.ui.control.EditField
        Im_TC_QuickAdjust               matlab.ui.control.CheckBox
        debugareaTextAreaLabel          matlab.ui.control.Label
        DB_debugareaTextArea            matlab.ui.control.TextArea
        Im_TP_Background                matlab.ui.container.Panel
        ImBa_TPG_BackgroundImage        matlab.ui.control.UIAxes
        ImBa_TPA_FileTypeTable          matlab.ui.control.Table
        ImBa_TPB_SelectBackground       matlab.ui.control.Button
        ImBa_TPB_AddType                matlab.ui.control.Button
        ImBa_TPB_DeleteType             matlab.ui.control.Button
        Im_TC_RemoveBackground          matlab.ui.control.CheckBox
        ParameterEstimationTab          matlab.ui.container.Tab
        T_OnLineOEDTab                  matlab.ui.container.Tab
        On_TP_Files                     matlab.ui.container.Panel
        OnFi_TPB_BrowseFolder           matlab.ui.control.Button
        OnFi_TPG_SegmentedImage         matlab.ui.control.UIAxes
        OnFi_TP_ImageFolderLabel        matlab.ui.control.Label
        OnFi_TPF_OnlineOEDSettings      matlab.ui.control.EditField
        OnFi_TP_LatestSegmentationImageLabel  matlab.ui.control.Label
        OnFi_TPS_BrowseSettingFile      matlab.ui.control.StateButton
        OnFi_TPs_LoadFromOtherTabs      matlab.ui.control.StateButton
        OnFi_TPG_ODEs                   matlab.ui.control.UIAxes
        On_TP_ExperimentalData          matlab.ui.container.Panel
        OnEx_TPG_ExperimentDataRecord   matlab.ui.control.UIAxes
        OnEx_TP_TotalDurationsLabel     matlab.ui.control.Label
        OnEx_TPF_TotalDurationsec       matlab.ui.control.NumericEditField
        OnEx_TPA_ParameterValues        matlab.ui.control.Table
        OnEx_TPF_PEnOEDTime             matlab.ui.control.NumericEditField
        OnEx_TP_PEOEDLabel              matlab.ui.control.Label
        OnEx_TPS_PEnOEDSlider           matlab.ui.control.Slider
        OnEx_TP_LoopNumberLabel         matlab.ui.control.Label
        OnEx_TPF_NumLoops               matlab.ui.control.NumericEditField
        OnEx_TPB_StartExperiment        matlab.ui.control.StateButton
        At_TI_ExperimentState_2         matlab.ui.control.Lamp
        On_TG_StateDiagram              matlab.ui.control.UIAxes
        On_TL_StateList                 matlab.ui.control.ListBox
        X_StateText                     matlab.ui.control.TextArea
    end

    properties (Access = private)
        G_InitialWorkingDirectory   % working directory
        G_BioFormatsDirectory       % directory of the Bio-Formats toolbox
        
        Im_FileDirectory            % directory of image files
        Im_CurrentFile              % the file under editing
        Im_CurrentImages            % a cell array storing all the images we are currently working on
        % by default,
        % 1st - raw/BGremoved (depends on the setting),
        % 2nd - adjusted of 1st,
        % 3rd - raw
        % 4th - Background
        % nth - further operations
        Im_SelectedImage            % temporary image, image selection only
        Im_FileWatcher              % watching the state of the working folder
        
        Ac_ActuationSystem          % details of the actuation system:
        % BaudRate, modbus, COMPort, Axis_Number, Preset_Speed
        On_CurrentImageData         % data of the current image
        On_Inputs                   % AMIGO inputs structure for on-line OED
        On_ExperimentalData         % Description
        On_FileWatcher              % file watcher of the image folder
        On_ReferenceTime            % it is reset when the experiment is started.

    end
    
    methods (Access = private)
       
    %% G - General/common functions
        function image = G_ReadImage(app,fullFileName)
            % use the toolbox Bio-Formats 5.9.1.to load the image
            tempcd=cd;
            % try to read the nd2 files with the bio-formats toolbox
            try
                cd(app.G_BioFormatsDirectory);
                [~,image]=evalc(['bfopen(''',fullFileName,''')']);
                image=image{1}{1};
            catch
                % if fail, try to use the built-in image reading function
                image=imread(fullFileName);
            end
            if (app.SeCa_TPC_CUDA4ImageProcessing.Value) % if Use CUDA is on
                image=gpuArray(image);
            end
            cd(tempcd);
        end
        
        function G_PrintState(app,text)
            %update the text in the state bar
            app.X_StateText.Value=text;
        end
       
    %% Im - Image processing functions
        function Im_NewFileDetected(app,~,arg)
            tempdir=dir(app.Im_FileDirectory);
            tempdir=struct2cell(tempdir(~cellfun(@(x) x,{tempdir.isdir})));
            app.ImFi_TPL_FileListBox.Items=tempdir(1,:);
            try
                app.ImFi_TPL_FileListBox.Value=char(arg.Name);
                ImFi_TPL_FileListBoxValueChanged(app);
                title({'New file detected:';app.Im_CurrentFile},'Parent', app.Im_TG_SelectedImage);
                app.ImFi_TPL_FileListBox.Value=string(arg.Name);
            catch
            end
            G_PrintState(app,string(arg.Name));
        end
        
    %% On - Online OED functions
        function On_NewFileDetected(app,~,arg)
            % this function will be called when a new file is added to the image folder
            if (app.At_TB_StartExperiment.Value)
                try % the app will not crash if the new added file is not an image.
                    timediff=char(datetime-app.On_ReferenceTime);
                    
                    % segmentation, image processing and plot the new image
                    app.On_CurrentImageData=...
                        G_DrawSegmentedImage(app,app.OnFi_TPG_SegmentedImage,...
                        [app.On_FileWatcher.path,'\',char(arg.Name)]);
                    title({['New image added at: ',timediff];char(arg.Name)},...
                        'Parent',app.OnFi_TPG_SegmentedImage);
                    
                    % if the experiment is started, append the data to the 1st experiment in input
                    if (app.OnEx_TPB_StartExperiment.Value)
                        % add the new experimental data
                        t=regexp(timediff,':','split');
                        app.On_Inputs.exps.n_s{1}=app.On_Inputs.exps.n_s{1}+1;
                        app.On_Inputs.exps.t_s{1}(end+1)=cellfun(@str2num,t)*[3600;60;1];
                        app.On_Inputs.exps.exp_data{1}(end+1)=app.On_CurrentImageData.mean;
                        app.On_Inputs.exps.error_data{1}(end+1)=app.On_CurrentImageData.std;
                    end
                    On_DisplayExperimentalData(app);
                catch
                    On_UpdateState(app,2);
                end
            end
        end
        
    %% Ac - Actuation system functions
        function Ac_InitialAllAxes(app)
            try
                % Connect to actuators
                % Set it to the value the actuators is using
                app.Ac_ActuationSystem.BaudRate = 38400;
                app.Ac_ActuationSystem.COMPort = 'COM5';
                app.Ac_ActuationSystem.AxisNumber=2;
                app.Ac_ActuationSystem.MaxSpeed=120*100;
                % Create MODBUS object for actuators and connect to it
                app.Ac_ActuationSystem.modbus=modbus(...
                    'serialrtu',app.Ac_ActuationSystem.COMPort,...
                    'BaudRate',app.Ac_ActuationSystem.BaudRate);
                indexList=1:app.Ac_ActuationSystem.AxisNumber;
                
                % Set Control Bit ON (Enable MODBUS control) for all axis
                for i=indexList
                    write(app.Ac_ActuationSystem.modbus,'coils',1064,1,i);
                end
                pause(0.2); % Actuators need some time between the rising edges, refer to IAI manual
                
                % Reset Alarms, in case any alarm were present. So that subsequent operations can be performed
                for i=indexList
                    Ac_Alarm_Reset(app,i);
                end
                pause(0.2); % Actuators need some time between the rising edges, refer to IAI manual
                
                % Turn on the Servo for all axis
                for i=indexList
                    write(app.Ac_ActuationSystem.modbus,'coils',1028,1,i);
                    pause(0.2); % Give it a gap between servo turn on
                end
                pause(0.5); % Actuators need some time between the rising edges, refer to IAI manual
                
                % Home return all axis
                for i=indexList
                    Ac_Home_Return(app,i);
                end
                
                % check home return status
                HEND=false(1,app.Ac_ActuationSystem.AxisNumber);
                while (true)
                    for i=indexList
                        % read home return status from all axis
                        HEND(i)=read(app.Ac_ActuationSystem.modbus,'coils',268,1,i);
                    end
                    
                    % pause 0.3s between each read cycle. Also after the last cycle
                    pause(0.3);
                    
                    %jump out of the loop once all axis have homed
                    if (all(HEND))
                        break;
                    end
                end
                % set up Preset speed
                for i=indexList
                    Ac_Write_Speed(app,app.Ac_ActuationSystem.MaxSpeed,i);
                end
                app.Ac_ActuationSystem.Ready=true;
            catch
                uialert(app.U_Main,'The device is not connected!','Cannot Initialize','Icon','error');
            end
        end
        
        function Ac_Alarm_Reset(app,Axis)
            % This function write to the Alarm Reset coil, refer to manual.
            % 1 input (Axis)
            % This function writes to this coil, 0, 1, 0 sequentially, to create an
            % rising edge that reset the alarm.
            
            % Alarm Reset for given axis
            write(app.Ac_ActuationSystem.modbus,'coils',1032,0,Axis);
            write(app.Ac_ActuationSystem.modbus,'coils',1032,1,Axis);
            write(app.Ac_ActuationSystem.modbus,'coils',1032,0,Axis);
        end
        
        function Ac_Home_Return(app,Axis)
            % This function write to the Home Return coil, refer to manual.
            % 1 input (Axis)
            % This function writes to this coil, 0, 1, 0 sequentially, to create an
            % rising edge that homes the axis.
            
            % Home given axis
            write(app.Ac_ActuationSystem.modbus,'coils',1036,0,Axis);
            write(app.Ac_ActuationSystem.modbus,'coils',1036,1,Axis);
            write(app.Ac_ActuationSystem.modbus,'coils',1036,0,Axis);
        end
        
        % This function write to the "Speed Specification Register (VCMD)"
        % refer to manual.
        % Input the speed in a single decimal value unit 0.01mm/s. And Axis number
        function Ac_Write_Speed(app,Speed,Axis_Number)
            % Convert the input speed value to 2 values so as to write in 2 16-bit
            % registers
            [Speed_reg_Hi , Speed_reg_Lo] = Ac_dec2regs2(app,Speed);
            
            % write the data to registers for given axis number
            write(app.Ac_ActuationSystem.modbus,'holdingregs',39173,...
                [Speed_reg_Hi,Speed_reg_Lo],Axis_Number);
            
        end
        
        % This function convert a single decimal value to two separate decimal
        % values, so as to store in 2 16-bits registers.
        % 1 input, 2 output, both in decimal.
        function [reg_Hi,reg_Lo]=Ac_dec2regs2(app,Original)
            
            Original_Hex = dec2hex(Original,8); % Convert decimal input a into hex string with 8 digits
            
            reg_Hi_Hex = Original_Hex(1:4); % Hi register equals to the first 4 digit of Original_Hex
            reg_Hi = hex2dec(reg_Hi_Hex); % Convert hex value back to decimal for Hi reg
            
            reg_Lo_Hex = Original_Hex(5:8); % Lo register equals to the last 4 digit of Original_Hex
            reg_Lo = hex2dec(reg_Lo_Hex); % Convert hex value back to decimal for Lo reg
            
        end
        
        % This function convert two decimal values stored separately in 2 16-bit
        % registers to a single decimal value
        % 2 input, 1 output, both in decimal.
        function Decimal_Value=Ac_regs2_2dec(app,reg_Hi,reg_Lo)
            reg_Hi_Hex = dec2hex(reg_Hi); % Convert the Hi register input into Hex value
            reg_Hi_Hex = pad(reg_Hi_Hex,4,'left','0'); % Pad zeros in front of Hex value to form 4 digit
            
            reg_Lo_Hex = dec2hex(reg_Lo); % Convert the Lo register input into Hex value
            reg_Lo_Hex = pad(reg_Lo_Hex,4,'left','0'); % Pad zeros in front of Hex value to form 4 digit
            
            % Combine reg_Hi_Hex, reg_Lo_Hex together to form a single Hex value
            Decimal_Value_Hex = [reg_Hi_Hex,reg_Lo_Hex];
            
            % Convert the final Hex value to decimal
            Decimal_Value = hex2dec(Decimal_Value_Hex);
        end
        
        % This function write to the "Target Position Specification (PCMD)"
        % registers, refer to manual.
        % Input the target position in a single decimal value, and axis number actuators will start to move
        % immediately after the execution of this funciton.
        % the position (height) is in mm.
        function Ac_WriteTargetPosition(app,Target_Position,Axis_Number)
            % Convert the input target position value to 2 values so as to write in 2 16-bit
            % registers
            [Target_Position_reg_Hi , Target_Position_reg_Lo] = Ac_dec2regs2(app,Target_Position);
            
            % write the data to registers for given axis number
            write(app.Ac_ActuationSystem.modbus,'holdingregs',39169,[Target_Position_reg_Hi,Target_Position_reg_Lo],Axis_Number);
        end
        
        % This function read the "Current Position (PNOW)"
        % registers, refer to manual.
        % Input the axis number. Output is a single decimal value unit 0.01mm.
        %
        % A correction method was added because sometimes when at home position (0mm),
        % Current_Position would be read as 400k+ (some very large number)
        % If Current_Position falls out resonable range Current_Position=0
        function Current_Position = Ac_ReadCurrentPosition(app,Axis_Number)
            % Read current position from PNOW registers into two decimal values, for
            % given axis number
            Position = read(app.Ac_ActuationSystem.modbus,'holdingregs',36865,2,Axis_Number);
            
            % Convert 2 decimal values into 1 single decimal value
            Current_Position=Ac_regs2_2dec(app,Position(1),Position(2));
            
            % This was added because sometimes when at home position (0mm),
            % Current_Position would be read as 400k+ (some very large number)
            % If Current_Position falls out resonable range Current_Position=0
            if ((Current_Position>200000) || (Current_Position<0))
                Current_Position=0;
            end
        end
        
        % This function will move the syringers according to the imput design.
        % later this will cope with the calibration module.
        function Ac_input2motion(app,input)
            height=[round(input*10,0);round((1000-input)*10,0)];
            for ind=1:2
                try
                    Ac_WriteTargetPosition(app,height(ind),ind);
                catch
                end
            end
        end
        
        
        function G_InitialPaths(app)
            %inialise the file paths
            
            % read initial directory of the APP
            app.G_InitialWorkingDirectory=cd;
            app.SeDi_TPF_APPDirectory.Value=app.G_InitialWorkingDirectory;
            p=regexp(path,';','split')';
            AMIGOIndex=contains(p,'AMIGO');
            
            % read AMIGO's directory if it has been initialised
            if (sum(AMIGOIndex)>100)
                pAMIGO=p(AMIGOIndex);
                [~,id]=min(cellfun(@length,pAMIGO));
                app.SeDi_TPF_AMIGODirectory.Value=pAMIGO{id};
            end
            
            % read Bio-Formats directory if it is available
            tempcd=cd;
            try
                cd('bfmatlab');
                app.G_BioFormatsDirectory=cd;
            catch
                app.G_BioFormatsDirectory=tempcd;
            end
            cd(tempcd);
        end
        
        
        function Data = G_DrawSegmentedImage(app,ax,file)
            % read the image, implement the segmeation and draw the results, the data is returned.
            Image=G_ReadImage(app,file);
            tempcd=cd;
            cd(app.G_InitialWorkingDirectory);
            % CellData is in a seperate function file.
            Data=CellData(Image);
            cd(tempcd);
            
            % Show the segmentation
            CC = bwconncomp(Data.cellIm);
            L = labelmatrix(CC);
            BWoutline = bwperim(Data.cellIm);
            Lrgb = label2rgb(L, 'jet', 'k', 'shuffle');
            BWoutline=imdilate(BWoutline,strel('disk',3));
            BWoutline1=repmat(BWoutline,1,1,3);
            
            BWoutline1(:,:,2)=false;
            BWoutline0=false(size(BWoutline1));
            BWoutline0(:,:,2)=BWoutline;
            
            Lrgb(BWoutline0)=0;
            Lrgb(BWoutline1)=255;
            %%{
            %draw the image, switch between '%%{' and '%{' to activate or deactivate the plotting
            imshow(imadjust(Image),'Parent',ax);
            hold(ax,'on');
            himage = imshow(Lrgb,'Parent',ax);
            himage.AlphaData = 0.6;
            hold(ax,'off');
            %}
            
            % data from the image.
            Data=table(...
                {'Number of Cells';
                'Effective Pixels';
                'Average value';
                'Absolute Standard Deviation';
                'Relative Standard Deviation'},...
                {length(Data.fluoLevels);
                [num2str(Data.effectiveP*100,'%.2f'),'%'];
                Data.mean;
                Data.std;
                [num2str(Data.std/Data.mean*100,'%.2f'),'%'];
                });
        end
        
        function G_DrawFSMState(app,ax,x,y,ind,selected)
            % draw the finite state machine. states have radius of 1, and the distance is 2.56
            hold (ax,'on');
            if (~selected)
                % unselected states are in blue with black edge
                rectangle(ax,...
                    'Position',[x*2.56-0.5,y*2.56-0.5,1,1],...
                    'Curvature',[1,1],...
                    'linewidth',0.75,...
                    'EdgeColor','k','FaceColor',[0.8,0.8,1]);
            else
                % selected state is in yellow with red edge.
                rectangle(ax,...
                    'Position',[x*2.56-0.5,y*2.56-0.5,1,1],...
                    'Curvature',[1,1],...
                    'linewidth',0.75,...
                    'EdgeColor','r','FaceColor',[1,1,0.8]);
            end
            text(ax,...
                x*2.56,y*2.56,num2str(ind),'HorizontalAlignment','center','FontSize',20);
            hold (ax,'off');
        end
        
        function G_DrawFSMArrow(app,ax,x1,y1,x2,y2)
            % Draw arrows for the finite state machine 
            hold (ax,'on');
            x1=x1*2.56;
            x2=x2*2.56;
            y1=y1*2.56;
            y2=y2*2.56;
            dist=sqrt((x2-x1)^2+(y2-y1)^2);
            xy=interp1([x1,y1;x2,y2],1+0.5/dist);
            uv=interp1([x1,y1;x2,y2],2-0.5/dist)-xy;
            quiver(ax,xy(1),xy(2),uv(1),uv(2),'-k','AutoScale','off','LineWidth',2,'MaxHeadSize',2);
            hold (ax,'off');
        end
        
        function On_UpdateState(app,currentS)
            % update the finite state machine particularly for the online OED
            % states=-1 leave all states unselected
            himage = imshow(true(2,2),'Parent',app.On_TG_StateDiagram);
            himage.AlphaData = 0;
            
            S={[0;-1;1];[1;-1;0];[2;0;0];[3;0;1];[4;1;1];[5;1;0]};
            
            G_DrawFSMArrow(app,app.On_TG_StateDiagram,-1,1,-1,0); %0-1
            G_DrawFSMArrow(app,app.On_TG_StateDiagram,-1,0,0,0); %1-2
            G_DrawFSMArrow(app,app.On_TG_StateDiagram,0,0,0,1); %2-3
            G_DrawFSMArrow(app,app.On_TG_StateDiagram,0,1,1,1); %3-4
            G_DrawFSMArrow(app,app.On_TG_StateDiagram,1,1,0,0); %4-2
            G_DrawFSMArrow(app,app.On_TG_StateDiagram,0,0,1,0); %2-5
            
            cellfun(@(Si)G_DrawFSMState(app,app.On_TG_StateDiagram,...
                Si(2),Si(3),Si(1),false), S);
            
            app.On_TL_StateList.Value={};
            if (currentS>-1)
                G_DrawFSMState(app,app.On_TG_StateDiagram,...
                    S{currentS+1}(2),S{currentS+1}(3),currentS,true);
                app.On_TL_StateList.Value=app.On_TL_StateList.Items(currentS+1);
            end
        end
        
        function On_DisplayExperimentalData(app)
            % draw the experimental data so far
            try
                xlabel(app.OnEx_TPG_ExperimentDataRecord,'time (s)');
                % draw the input values
                yyaxis(app.OnEx_TPG_ExperimentDataRecord,'left');
                ylabel(app.OnEx_TPG_ExperimentDataRecord,'Input');
                stairs(app.OnEx_TPG_ExperimentDataRecord,...
                    [app.On_Inputs.exps.t_con{1},app.On_Inputs.exps.t_con{1}(end)+app.On_ExperimentalData.stepDuration],...
                    [app.On_Inputs.exps.u{1},app.On_Inputs.exps.u{1}(end)],'LineWidth',1.5);
                % draw the observable(s)
                yyaxis(app.OnEx_TPG_ExperimentDataRecord,'right');
                ylabel(app.OnEx_TPG_ExperimentDataRecord,'Observables (A.U.)');
                errorbar(app.OnEx_TPG_ExperimentDataRecord,...
                    app.On_Inputs.exps.t_s{1},...
                    app.On_Inputs.exps.exp_data{1},...
                    app.On_Inputs.exps.error_data{1},'-o',...
                    'CapSize',10);
                xlim(app.OnEx_TPG_ExperimentDataRecord,[0,app.OnEx_TPF_TotalDurationsec.Value]);
            catch
                % this may happen when the experiment has just stared and there is no data yet.
                disp(app.On_Inputs.exps);
            end
        end
        
        function On_PE(app)
            % function to call the PE function (in the initial working directory)
            On_UpdateState(app,3);
            tempcd=cd;
            cd(app.G_InitialWorkingDirectory);
            app.On_Inputs=PE(app.On_Inputs);
            app.OnEx_TPA_ParameterValues.Data=table(...
                cellstr(app.On_Inputs.model.par_names),...
                num2cell(app.On_Inputs.model.par)');
            cd(tempcd);
        end
        
        function On_PEnOED(app)
            % PE followed by OED, the main script is in a seperate file.
            On_PE(app);
            On_UpdateState(app,4);
            tempcd=cd;
            cd(app.G_InitialWorkingDirectory);
            [app.On_ExperimentalData.y0,nextinput,nextpred]=...
                OED(...
                app.On_Inputs,...
                app.On_ExperimentalData.duration,...
                app.On_ExperimentalData.y0,...
                app.On_ExperimentalData.stepDuration);
            app.On_ExperimentalData.input=[app.On_ExperimentalData.input,nextinput];
            app.On_ExperimentalData.pred=[app.On_ExperimentalData.pred;nextpred];
            cd(tempcd);
            On_UpdateState(app,2);
        end
        
        function temp_Newimage(app, obj, event, string_arg)
            % timer function simulating the measured data from app.On_ExperimentalData.pred
            app.On_Inputs.exps.n_s{1}=app.On_Inputs.exps.n_s{1}+1;
            try
                app.On_Inputs.exps.t_s{1}(end+1)=app.On_Inputs.exps.t_s{1}(end)+app.On_ExperimentalData.obsstep;
            catch
                app.On_Inputs.exps.t_s{1}=0;
            end
            try
                app.On_Inputs.exps.error_data{1}(end+1)=app.On_ExperimentalData.pred(1)*0.05;
            catch
                app.On_Inputs.exps.error_data{1}=app.On_ExperimentalData.pred(1)*0.05;
            end
            try
                app.On_Inputs.exps.exp_data{1}(end+1)=...
                    random('norm',1,0.05,1,1)*app.On_ExperimentalData.pred(1);
            catch
                app.On_Inputs.exps.exp_data{1}=...
                    random('norm',1,0.05,1,1)*app.On_ExperimentalData.pred(1);
            end
            app.On_ExperimentalData.pred(1)=[];
            On_DisplayExperimentalData(app);
        end
        
        function temp_Newinput(app, obj, event, string_arg)
            % timer function for carrying out a new input value.
            app.On_Inputs.exps.n_steps{1}=app.On_Inputs.exps.n_steps{1}+1;
            try
                app.On_Inputs.exps.t_con{1}(end+1)=...
                    app.On_Inputs.exps.t_con{1}(end)+app.On_ExperimentalData.stepDuration;
            catch
                app.On_Inputs.exps.t_con{1}=0;
            end
            app.On_Inputs.exps.t_f{1}=app.On_Inputs.exps.t_f{1}+app.On_ExperimentalData.stepDuration;
            try
                app.On_Inputs.exps.u{1}(end+1)=app.On_ExperimentalData.input(1);
            catch
                app.On_Inputs.exps.u{1}=app.On_ExperimentalData.input(1);
            end
            Ac_input2motion(app,app.On_ExperimentalData.input(1));
            app.On_ExperimentalData.input(1)=[];
            
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function APP_StartupFcn(app)
            G_InitialPaths(app);
            app.Im_CurrentImages={};
            app.Im_SelectedImage=cell(3,1);
            
            % define the file watchers, set the initial working directory as the inial watching folder.
            app.Im_FileWatcher=System.IO.FileSystemWatcher(app.G_InitialWorkingDirectory);
            app.Im_FileWatcher.EnableRaisingEvents=false;
            addlistener(app.Im_FileWatcher,'Created',@app.Im_NewFileDetected);
            app.At_FileWatcher=app.Im_FileWatcher;
            addlistener(app.At_FileWatcher,'Created',@app.At_NewFileDetected);
            app.On_FileWatcher=app.Im_FileWatcher;
            addlistener(app.On_FileWatcher,'Created',@app.On_NewFileDetected);
            
            % the actuation system is not yet initialised
            app.Ac_ActuationSystem.Ready=false;
            
            % just show the finite state machine, without selecting any states.
            On_UpdateState(app,-1);
        end

        % Button pushed function: ImFi_TPB_BrowseFiles
        function ImFi_TPB_BrowseFilesPushed(app, event)
            % Get directory
            [tempfile,tempdir]=uigetfile('*.*');
            % Bring the window back to the front
            figure(app.U_Main);
            
            % Display directory to the "Select Folder" edit field
            if (tempdir==0)
                app.Im_FileDirectory=app.G_InitialWorkingDirectory;
            else
                tempdir(end)=[];
                app.Im_FileDirectory=tempdir;
            end
            app.ImFi_TPL_FileListBox.Enable=true;
            app.ImFi_TPF_AddressField.Value=app.Im_FileDirectory;
            tempdir=dir(app.Im_FileDirectory);
            tempdir=struct2cell(tempdir(~cellfun(@(x) x,{tempdir.isdir})));
            app.ImFi_TPL_FileListBox.Items=tempdir(1,:);
            if (tempfile~=0)
                app.ImFi_TPL_FileListBox.Value=tempfile;
                ImFi_TPL_FileListBoxValueChanged(app);
            end
        end

        % Button pushed function: ImFi_TPB_CopyAddress
        function ImFi_TPB_CopyAddressPushed(app, event)
            if (isempty(app.ImFi_TPF_AddressField.Value))
                uialert(app.U_Main,{'The address field is empty.'},...
                    'Copy address','Icon','warning');
            else
                clipboard('copy',app.ImFi_TPF_AddressField.Value);
                uialert(app.U_Main,{'Current address copied:';app.ImFi_TPF_AddressField.Value},...
                    'Copy address','Icon','success');
            end
        end

        % Value changed function: ImFi_TPL_FileListBox
        function ImFi_TPL_FileListBoxValueChanged(app, event)
            try
                app.Im_CurrentFile=app.ImFi_TPL_FileListBox.Value;
                app.DB_debugareaTextArea.Value=[app.Im_FileDirectory,'\',app.Im_CurrentFile];
                app.Im_SelectedImage{3}=G_ReadImage(app,[app.Im_FileDirectory,'\',app.Im_CurrentFile]);
                if (app.Im_TC_RemoveBackground.Value)
                    try
                        d4=double(app.Im_SelectedImage{4});
                        d3=double(app.Im_SelectedImage{3});
                        d1=d3-d4*sum(d3(:).*d4(:))/sum(sum(d4(:).^2));
                        if (isa(app.Im_SelectedImage{3},'uint8'))
                            app.Im_SelectedImage{1}=uint8(d1);
                        else
                            app.Im_SelectedImage{1}=uint16(d1);
                        end
                    catch
                        uialert(app.U_Main,{'current image size does not match with the background, ',...
                            'stop removing the background.'},...
                            'Size do not match','Icon','warning');
                        app.Im_TC_RemoveBackground.Value=false;
                        app.Im_SelectedImage{1}=app.Im_SelectedImage{3};
                    end
                else
                    app.Im_SelectedImage{1}=app.Im_SelectedImage{3};
                end
                app.Im_SelectedImage{2}=[];
                Im_TC_QuickAdjustValueChanged(app);
                app.ImFi_TPL_FileListBox.Value={};
            catch
            end
        end

        % Value changed function: ImFi_TPF_AddressField
        function ImFi_TPF_AddressFieldValueChanged(app, event)
            tempdir = app.ImFi_TPF_AddressField.Value;
            % Display directory to the "Select Folder" edit field
            if (tempdir==0)
                app.Im_FileDirectory=[app.G_InitialWorkingDirectory,'\'];
            else
                app.Im_FileDirectory=tempdir;
            end
            app.ImFi_TPL_FileListBox.Enable=true;
            app.ImFi_TPF_AddressField.Value=app.Im_FileDirectory;
            
            tempdir=dir(app.Im_FileDirectory);
            tempdir=struct2cell(tempdir(~cellfun(@(x) x,{tempdir.isdir})));
            app.ImFi_TPL_FileListBox.Items=tempdir(1,:);
            app.ImFi_TPL_FileListBox.Value=tempfile;
            ImFi_TPL_FileListBoxValueChanged(app);
        end

        % Value changed function: Im_TC_QuickAdjust
        function Im_TC_QuickAdjustValueChanged(app, event)
            if (app.Im_TC_QuickAdjust.Value)
                if (isempty(app.Im_SelectedImage{2}))
                    if (size( app.Im_SelectedImage{1},3)==3)
                        app.Im_SelectedImage{2}=imadjust(app.Im_SelectedImage{1},...
                            [0,0,0;1,1,1],[]);
                    else
                        app.Im_SelectedImage{2}=imadjust(app.Im_SelectedImage{1});
                    end
                end
                imshow(app.Im_SelectedImage{2},'Parent', app.Im_TG_SelectedImage);
            else
                imshow(app.Im_SelectedImage{1},'Parent', app.Im_TG_SelectedImage);
            end
            title(app.Im_CurrentFile,'Parent', app.Im_TG_SelectedImage);
        end

        % Value changed function: ImFi_TPC_TrackNew
        function ImFi_TPC_TrackNewValueChanged(app, event)
            if(app.ImFi_TPC_TrackNew.Value)
                app.Im_FileWatcher.Path=app.Im_FileDirectory;
                app.Im_FileWatcher.EnableRaisingEvents=true;
            else
                app.Im_FileWatcher.EnableRaisingEvents=false;
            end
        end

        % Value changed function: Im_TC_RemoveBackground
        function Im_TC_RemoveBackgroundValueChanged(app, event)
            try
                app.ImFi_TPL_FileListBox.Value=app.Im_CurrentFile;
            catch
            end
            ImFi_TPL_FileListBoxValueChanged(app);
        end

        % Button pushed function: ImBa_TPB_SelectBackground
        function ImBa_TPB_SelectBackgroundButtonPushed(app, event)
            try
                [tempfile,tempdir]=uigetfile(app.Im_FileDirectory,'filter','*.*');
                figure(app.U_Main);
                if (tempdir~=0)
                    app.Im_SelectedImage{4}=G_ReadImage(app,[tempdir,'\',tempfile]);
                    imshow(app.Im_SelectedImage{4},'Parent',app.ImBa_TPG_BackgroundImage);
                end
            catch
                uialert(app.U_Main,{'Cannot read this file as the background.'},...
                    'Invalid file','Icon','warning');
            end
        end

        % Button pushed function: SeDi_TPB_BrowseAMIGO
        function SeDi_TPB_BrowseAMIGOButtonPushed(app, event)
            tempdir=uigetdir([],'AMIGO Directory');
            figure(app.U_Main);
            if (tempdir~=0)
                app.SeDi_TPF_AMIGODirectory.Value=tempdir;
            end
        end

        % Button pushed function: SeDi_TPB_BrowseMicroManager
        function SeDi_TPB_BrowseMicroManagerButtonPushed(app, event)
            tempdir=uigetdir([],'μManager Directory');
            figure(app.U_Main);
            if (tempdir~=0)
                app.SeDi_TPF_MicroManagerDirectory.Value=tempdir;
            end
        end

        % Button pushed function: OnFi_TPB_BrowseFolder
        function OnFi_TPB_BrowseFolderButtonPushed(app, event)
            tempdir=uigetdir();
            figure(app.U_Main);
            if (tempdir~=0)
                app.On_FileWatcher.path=tempdir;
            end
        end

        % Value changed function: OnEx_TPS_PEnOEDSlider
        function OnEx_TPS_PEnOEDSliderValueChanged(app, event)
            app.OnEx_TPF_PEnOEDTime.Value=app.OnEx_TPS_PEnOEDSlider.Value;
        end

        % Value changed function: OnEx_TPB_StartExperiment
        function OnEx_TPB_StartExperimentValueChanged(app, event)
            if (app.OnEx_TPB_StartExperiment.Value)
                % it will start the experiment and not pop up until the experiment is completed.
                On_UpdateState(app,0);
                %app.On_FileWatcher.EnableRaisingEvents=true;
                app.At_TI_ExperimentState.Color='r';
                
                %Ac_InitialAllAxes(app);
                
                On_UpdateState(app,1);
                
                [app.On_ExperimentalData.y0,app.On_ExperimentalData.input,app.On_ExperimentalData.pred]=...
                    OED(...
                    app.On_Inputs,...
                    app.On_ExperimentalData.duration,...
                    app.On_ExperimentalData.y0,...
                    app.On_ExperimentalData.stepDuration);
                
                On_UpdateState(app,2);
                app.On_ReferenceTime=datetime;
                
                tinput = timer('Period',app.On_ExperimentalData.stepDuration,...
                    'ExecutionMode','fixedRate','BusyMode','error','Name','tinput',...
                    'TasksToExecute',app.OnEx_TPF_TotalDurationsec.Value/app.On_ExperimentalData.stepDuration,...
                    'StartDelay',2);
                tinput.TimerFcn = {@app.temp_Newinput,'input'};
                
                timage = timer('Period',app.On_ExperimentalData.obsstep,...
                    'ExecutionMode','fixedRate','BusyMode','error','Name','timage',...
                    'TasksToExecute',app.OnEx_TPF_TotalDurationsec.Value/app.On_ExperimentalData.obsstep,...
                    'StartDelay',2);
                timage.TimerFcn = {@app.temp_Newimage,'image'};
                
                start(tinput);
                start(timage);
                
                for i=1:(app.OnEx_TPF_NumLoops.Value-1)
                    pause(app.On_ExperimentalData.duration-app.OnEx_TPF_PEnOEDTime.Value);
                    On_PEnOED(app);
                end
                
                On_PE(app);
                app.OnEx_TPB_StartExperiment.Value=false;
                OnEx_TPB_StartExperimentValueChanged(app);
            else
                app.On_FileWatcher.EnableRaisingEvents=false;
                app.At_TI_ExperimentState.Color='g';
                On_UpdateState(app,-1);
                delete('tinput');
                delete('timage');
            end
        end

        % Callback function: MeFi_MM_Exit, U_Main
        function APP_CloseFcn(app, event)
            delete(app);
        end

        % Value changed function: SeDi_TPS_InitialiseAMIGO
        function SeDi_TPS_InitialiseAMIGOValueChanged(app, event)
            if(app.SeDi_TPS_InitialiseAMIGO.Value)
                % try to initialise the AMIGO toolbox
                tempcd=cd;
                try
                    % first try
                    cd(app.SeDi_TPF_AMIGODirectory.Value);
                    AMIGO_Startup;
                    uialert(app.U_Main,'AMIGO initlised.',...
                        'AMIGO initlised.','Icon','info');
                catch
                    try
                        % if failed, try to remove the old paths, and try again
                        p=regexp(path,';','split')';
                        cellfun(@rmpath,p(contains(p,'AMIGO')))
                        try
                            addpath(app.G_InitialWorkingDirectory);
                            addpath(app.SeDi_TPF_MicroManagerDirectory.Value);
                        catch
                        end
                        cd(app.SeDi_TPF_AMIGODirectory.Value);
                        AMIGO_Startup;
                        uialert(app.U_Main,'AMIGO initlised.',...
                            'AMIGO initlised.','Icon','info');
                    catch
                        % if still fail, send an error.
                        uialert(app.U_Main,'Cannot initialise AMIGO with this directory.',...
                            'Cannot Initialise','Icon','error');
                    end
                end
                cd(tempcd);
                app.SeDi_TPS_InitialiseAMIGO.Value=false;
            end
        end

        % Value changed function: OnFi_TPS_BrowseSettingFile
        function OnFi_TPS_BrowseSettingFileValueChanged(app, event)
            if(app.OnFi_TPS_BrowseSettingFile.Value)
                
                % it will less than 100 is AMIGO is not properly loaded. It is more robust than
                % checking the 'amigoversion' variable (The user may clear it but AMIGO is still working)
                if (sum(contains(regexp(path,';','split')','AMIGO'))<100)
                    uialert(app.U_Main,{'You need to initialise AMIGO toolbox before proceed.';...
                        'Please fo to the sattings tab to allocate the toolbox and initialise it.'},...
                        'AMIGO not initialised','Icon','error');
                else
                    
                    [tempfile,tempdir]=uigetfile('*.m');
                    % Bring the window back to the front
                    figure(app.U_Main);
                    
                    if (tempdir~=0)
                        tempdir(end)=[];
                        tempfile([end-1;end])=[];
                        tempcd=cd;
                        %try
                        % try loading the setting.
                        cd(tempdir);
                        eval(tempfile);
                        app.On_Inputs=inputs;
                        app.OnFi_TPF_OnlineOEDSettings.Value=[tempdir,'\',tempfile,'.m'];
                        app.OnFi_TPL_ODEList.Items=cellstr(app.On_Inputs.model.eqns);
                        app.OnEx_TPA_ParameterValues.Data=table(...
                            cellstr(app.On_Inputs.model.par_names),...
                            num2cell(app.On_Inputs.model.par)');
                        app.OnEx_TPF_TotalDurationsec.Value=totalDuration;
                        app.OnEx_TPF_NumLoops.Value=numLoops;
                        app.OnEx_TPS_PEnOEDSlider.Limits=[0,totalDuration/numLoops];
                        
                        app.On_ExperimentalData.totalDuration=totalDuration;
                        app.On_ExperimentalData.numLoops=numLoops;
                        app.On_ExperimentalData.y0=y0;
                        app.On_ExperimentalData.duration=Duration;
                        app.On_ExperimentalData.stepDuration=stepDuration;
                        app.On_ExperimentalData.obsstep=obsstep;
                        %catch
                        % uialert(app.U_Main,'Cannot Read this setting.',...
                        %  'Invalid setting file','Icon','error');
                        %end
                        cd(tempcd);
                    end
                end
                app.OnFi_TPS_BrowseSettingFile.Value=false;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.U_Main = uifigure;
            app.U_Main.Name = 'All-In-One APP';
            app.U_Main.CloseRequestFcn = createCallbackFcn(app, @APP_CloseFcn, true);
            app.Me_M_File = uimenu(app.U_Main);
            app.Me_M_File.Text = 'File';
            app.MeFi_MM_NewProject = uimenu(app.Me_M_File);
            app.MeFi_MM_NewProject.Text = 'New Project';
            app.MeFi_MM_LoadProject = uimenu(app.Me_M_File);
            app.MeFi_MM_LoadProject.Text = 'Load Project';
            app.MeFi_MM_SaveProject = uimenu(app.Me_M_File);
            app.MeFi_MM_SaveProject.Text = 'Save Project';
            app.MeFi_MM_SaveProjectAs = uimenu(app.Me_M_File);
            app.MeFi_MM_SaveProjectAs.Text = 'Save Project As';
            app.MeFi_MM_SaveSettings = uimenu(app.Me_M_File);
            app.MeFi_MM_SaveSettings.Separator = 'on';
            app.MeFi_MM_SaveSettings.Text = 'Save Settings';
            app.MeFi_MM_LoadSettings = uimenu(app.Me_M_File);
            app.MeFi_MM_LoadSettings.Text = 'Load Settings';
            app.MeFi_MM_Exit = uimenu(app.Me_M_File);
            app.MeFi_MM_Exit.MenuSelectedFcn = createCallbackFcn(app, @APP_CloseFcn, true);
            app.MeFi_MM_Exit.Separator = 'on';
            app.MeFi_MM_Exit.Text = 'Exit';
            app.Me_M_Help = uimenu(app.U_Main);
            app.Me_M_Help.Text = 'Help';
            app.MeHe_MM_Guide = uimenu(app.Me_M_Help);
            app.MeHe_MM_Guide.Text = 'User''s Guide';
            app.MeHe_MM_About = uimenu(app.Me_M_Help);
            app.MeHe_MM_About.Separator = 'on';
            app.MeHe_MM_About.Text = 'About';
            app.MeHe_MM_Email = uimenu(app.Me_M_Help);
            app.MeHe_MM_Email.Text = 'Email Us';
            app.T_TabGroup = uitabgroup(app.U_Main);
            app.T_Settings = uitab(app.T_TabGroup);
            app.T_Settings.Title = 'Settings';
            app.Se_TP_Directory = uipanel(app.T_Settings);
            app.Se_TP_Directory.Title = 'Directory';
            app.SeDi_TPB_CopyAPP = uibutton(app.Se_TP_Directory, 'push');
            app.SeDi_TPB_CopyAPP.Text = 'Copy Directory';
            app.SeDi_TP_MicroManagerLabel = uilabel(app.Se_TP_Directory);
            app.SeDi_TP_MicroManagerLabel.Text = 'μManager';
            app.SeDi_TPF_MicroManagerDirectory = uieditfield(app.Se_TP_Directory, 'text');
            app.SeDi_TPB_BrowseMicroManager = uibutton(app.Se_TP_Directory, 'push');
            app.SeDi_TPB_BrowseMicroManager.ButtonPushedFcn = createCallbackFcn(app, @SeDi_TPB_BrowseMicroManagerButtonPushed, true);
            app.SeDi_TPB_BrowseMicroManager.Text = 'Browse';
            app.SeDi_TPB_CopyMicroManager = uibutton(app.Se_TP_Directory, 'push');
            app.SeDi_TPB_CopyMicroManager.Text = 'Copy';
            app.SeDi_TPB_BrowseAMIGO = uibutton(app.Se_TP_Directory, 'push');
            app.SeDi_TPB_BrowseAMIGO.ButtonPushedFcn = createCallbackFcn(app, @SeDi_TPB_BrowseAMIGOButtonPushed, true);
            app.SeDi_TPB_BrowseAMIGO.Text = 'Browse';
            app.SeDi_TPB_CopyAMIGO = uibutton(app.Se_TP_Directory, 'push');
            app.SeDi_TPB_CopyAMIGO.Text = 'Copy';
            app.SeDi_TP_AMIGOLabel = uilabel(app.Se_TP_Directory);
            app.SeDi_TP_AMIGOLabel.Text = 'AMIGO';
            app.SeDi_TPF_AMIGODirectory = uieditfield(app.Se_TP_Directory, 'text');
            app.SeDi_TP_APPLabel = uilabel(app.Se_TP_Directory);
            app.SeDi_TP_APPLabel.Text = 'APP';
            app.SeDi_TPS_InitialiseAMIGO = uibutton(app.Se_TP_Directory, 'state');
            app.SeDi_TPS_InitialiseAMIGO.ValueChangedFcn = createCallbackFcn(app, @SeDi_TPS_InitialiseAMIGOValueChanged, true);
            app.SeDi_TPS_InitialiseAMIGO.Text = 'Initialise AMIGO';
            app.SeDi_TPF_APPDirectory = uieditfield(app.Se_TP_Directory, 'text');
            app.SeDi_TPF_APPDirectory.Editable = 'off';
            app.Se_TP_AddOns = uipanel(app.T_Settings);
            app.Se_TP_AddOns.Title = 'Add-Ons';
            app.SeAd_TP_AddOnListLabel = uilabel(app.Se_TP_AddOns);
            app.SeAd_TP_AddOnListLabel.Text = 'Add-on List';
            app.SeAd_TPB_BrowseAddOn = uibutton(app.Se_TP_AddOns, 'push');
            app.SeAd_TPB_BrowseAddOn.Text = 'Browse';
            app.SeAd_TP_DirectcoryLabel = uilabel(app.Se_TP_AddOns);
            app.SeAd_TP_DirectcoryLabel.Text = 'Directcory';
            app.SeAd_TPF_AddOnDirectory = uieditfield(app.Se_TP_AddOns, 'text');
            app.SeAd_TP_NameLabel = uilabel(app.Se_TP_AddOns);
            app.SeAd_TP_NameLabel.Text = 'Name';
            app.SeAd_TPF_AddOnName = uitextarea(app.Se_TP_AddOns);
            app.SeAd_TPA_AddOnTable = uitable(app.Se_TP_AddOns);
            app.SeAd_TPA_AddOnTable.ColumnName = {'Name'; 'Directory'};
            app.SeAd_TPA_AddOnTable.RowName = {};
            app.SeAd_TPS_InitialiseAddOn = uibutton(app.Se_TP_AddOns, 'state');
            app.SeAd_TPS_InitialiseAddOn.Text = 'Initialise';
            app.SeAd_TPS_RemoveSelectedAddOn = uibutton(app.Se_TP_AddOns, 'state');
            app.SeAd_TPS_RemoveSelectedAddOn.Text = 'Remove Selected Add-Ons';
            app.SeAd_TPS_RemoveAllAddOn = uibutton(app.Se_TP_AddOns, 'state');
            app.SeAd_TPS_RemoveAllAddOn.Text = 'Remove All the Add-Ons';
            app.Se_TP_Calculation = uipanel(app.T_Settings);
            app.Se_TP_Calculation.Title = 'Calculation';
            app.SeCa_TP_ParallelPoolLabel = uilabel(app.Se_TP_Calculation);
            app.SeCa_TP_ParallelPoolLabel.Text = 'Parallel Pool';
            app.SeCa_TP_CUDALabel = uilabel(app.Se_TP_Calculation);
            app.SeCa_TP_CUDALabel.Text = 'CUDA';
            app.SeCa_TP_WorkersSliderLabel = uilabel(app.Se_TP_Calculation);
            app.SeCa_TP_WorkersSliderLabel.Text = 'Workers';
            app.SeCa_TPR_NumofWorkers = uislider(app.Se_TP_Calculation);
            app.SeCa_TPC_CUDA4ImageProcessing = uicheckbox(app.Se_TP_Calculation);
            app.SeCa_TPC_CUDA4ImageProcessing.Text = ' for Image Processing';
            app.SeCa_TPC_CUDA4OED = uicheckbox(app.Se_TP_Calculation);
            app.SeCa_TPC_CUDA4OED.Text = ' for OED';
            app.SeCa_TPE_NumofWorkers = uieditfield(app.Se_TP_Calculation, 'text');
            app.SeCa_TPS_ActivatethePool = uibutton(app.Se_TP_Calculation, 'state');
            app.SeCa_TPS_ActivatethePool.Text = 'Activate the Pool';
            app.OEDTab = uitab(app.T_TabGroup);
            app.OEDTab.Title = 'OED';
            app.ActuationSystemTab = uitab(app.T_TabGroup);
            app.ActuationSystemTab.Title = 'Actuation System';
            app.T_ImageProcessing = uitab(app.T_TabGroup);
            app.T_ImageProcessing.Title = 'Image Processing';
            app.Im_TG_SelectedImage = uiaxes(app.T_ImageProcessing);
            title(app.Im_TG_SelectedImage, 'Current Image')
            app.Im_TP_Files = uipanel(app.T_ImageProcessing);
            app.Im_TP_Files.Title = 'Files';
            app.ImFi_TPB_BrowseFiles = uibutton(app.Im_TP_Files, 'push');
            app.ImFi_TPB_BrowseFiles.ButtonPushedFcn = createCallbackFcn(app, @ImFi_TPB_BrowseFilesPushed, true);
            app.ImFi_TPB_BrowseFiles.Text = 'Browse Files';
            app.ImFi_TPB_CopyAddress = uibutton(app.Im_TP_Files, 'push');
            app.ImFi_TPB_CopyAddress.ButtonPushedFcn = createCallbackFcn(app, @ImFi_TPB_CopyAddressPushed, true);
            app.ImFi_TPB_CopyAddress.Text = 'Copy Address';
            app.ImFi_TPC_TrackNew = uicheckbox(app.Im_TP_Files);
            app.ImFi_TPC_TrackNew.ValueChangedFcn = createCallbackFcn(app, @ImFi_TPC_TrackNewValueChanged, true);
            app.ImFi_TPC_TrackNew.Text = 'Track New Images';
            app.ImFi_TPL_FileListBox = uilistbox(app.Im_TP_Files);
            app.ImFi_TPL_FileListBox.Items = {};
            app.ImFi_TPL_FileListBox.ValueChangedFcn = createCallbackFcn(app, @ImFi_TPL_FileListBoxValueChanged, true);
            app.ImFi_TPL_FileListBox.Enable = 'off';
            app.ImFi_TPL_FileListBox.Value = {};
            app.AddressEditFieldLabel = uilabel(app.Im_TP_Files);
            app.AddressEditFieldLabel.Text = 'Address:';
            app.ImFi_TPF_AddressField = uieditfield(app.Im_TP_Files, 'text');
            app.ImFi_TPF_AddressField.ValueChangedFcn = createCallbackFcn(app, @ImFi_TPF_AddressFieldValueChanged, true);
            app.Im_TC_QuickAdjust = uicheckbox(app.T_ImageProcessing);
            app.Im_TC_QuickAdjust.ValueChangedFcn = createCallbackFcn(app, @Im_TC_QuickAdjustValueChanged, true);
            app.Im_TC_QuickAdjust.Text = 'Quick adjust';
            app.Im_TC_QuickAdjust.Value = true;
            app.debugareaTextAreaLabel = uilabel(app.T_ImageProcessing);
            app.debugareaTextAreaLabel.Text = 'debug area';
            app.DB_debugareaTextArea = uitextarea(app.T_ImageProcessing);
            app.Im_TP_Background = uipanel(app.T_ImageProcessing);
            app.Im_TP_Background.Title = 'Background';
            app.ImBa_TPG_BackgroundImage = uiaxes(app.Im_TP_Background);
            title(app.ImBa_TPG_BackgroundImage, 'Background')
            app.ImBa_TPA_FileTypeTable = uitable(app.Im_TP_Background);
            app.ImBa_TPA_FileTypeTable.ColumnName = {'Type'; 'Key Word'};
            app.ImBa_TPA_FileTypeTable.RowName = {};
            app.ImBa_TPA_FileTypeTable.ColumnEditable = true;
            app.ImBa_TPB_SelectBackground = uibutton(app.Im_TP_Background, 'push');
            app.ImBa_TPB_SelectBackground.ButtonPushedFcn = createCallbackFcn(app, @ImBa_TPB_SelectBackgroundButtonPushed, true);
            app.ImBa_TPB_SelectBackground.Text = 'Select Background';
            app.ImBa_TPB_AddType = uibutton(app.Im_TP_Background, 'push');
            app.ImBa_TPB_AddType.Text = 'Add';
            app.ImBa_TPB_DeleteType = uibutton(app.Im_TP_Background, 'push');
            app.ImBa_TPB_DeleteType.Text = 'Delete';
            app.Im_TC_RemoveBackground = uicheckbox(app.T_ImageProcessing);
            app.Im_TC_RemoveBackground.ValueChangedFcn = createCallbackFcn(app, @Im_TC_RemoveBackgroundValueChanged, true);
            app.Im_TC_RemoveBackground.Text = 'Remove Background';
            app.ParameterEstimationTab = uitab(app.T_TabGroup);
            app.ParameterEstimationTab.Title = 'Parameter Estimation';
            app.T_OnLineOEDTab = uitab(app.T_TabGroup);
            app.T_OnLineOEDTab.Title = 'On-Line OED';
            app.On_TP_Files = uipanel(app.T_OnLineOEDTab);
            app.On_TP_Files.Title = 'Files';
            app.OnFi_TPB_BrowseFolder = uibutton(app.On_TP_Files, 'push');
            app.OnFi_TPB_BrowseFolder.ButtonPushedFcn = createCallbackFcn(app, @OnFi_TPB_BrowseFolderButtonPushed, true);
            app.OnFi_TPB_BrowseFolder.Text = 'Browse Image Folder';
            app.OnFi_TPG_SegmentedImage = uiaxes(app.On_TP_Files);
            title(app.OnFi_TPG_SegmentedImage, 'Segmentation')
            app.OnFi_TP_ImageFolderLabel = uilabel(app.On_TP_Files);
            app.OnFi_TP_ImageFolderLabel.Text = 'Image Folder';
            app.OnFi_TPF_OnlineOEDSettings = uieditfield(app.On_TP_Files, 'text');
            app.OnFi_TPF_OnlineOEDSettings.Editable = 'off';
            app.OnFi_TP_LatestSegmentationImageLabel = uilabel(app.On_TP_Files);
            app.OnFi_TP_LatestSegmentationImageLabel.Text = 'Latest Segmentation Image';
            app.OnFi_TPS_BrowseSettingFile = uibutton(app.On_TP_Files, 'state');
            app.OnFi_TPS_BrowseSettingFile.ValueChangedFcn = createCallbackFcn(app, @OnFi_TPS_BrowseSettingFileValueChanged, true);
            app.OnFi_TPS_BrowseSettingFile.Text = 'Browse Settings File';
            app.OnFi_TPs_LoadFromOtherTabs = uibutton(app.On_TP_Files, 'state');
            app.OnFi_TPs_LoadFromOtherTabs.Text = 'Load From Other Tabs';
            app.OnFi_TPG_ODEs = uiaxes(app.On_TP_Files);
            title(app.OnFi_TPG_ODEs, 'ODEs')
            app.On_TP_ExperimentalData = uipanel(app.T_OnLineOEDTab);
            app.On_TP_ExperimentalData.Title = 'Experimental Data';
            app.OnEx_TPG_ExperimentDataRecord = uiaxes(app.On_TP_ExperimentalData);
            title(app.OnEx_TPG_ExperimentDataRecord, 'Experimental Data')
            xlabel(app.OnEx_TPG_ExperimentDataRecord, 'Time')
            ylabel(app.OnEx_TPG_ExperimentDataRecord, 'Value')
            app.OnEx_TP_TotalDurationsLabel = uilabel(app.On_TP_ExperimentalData);
            app.OnEx_TP_TotalDurationsLabel.Text = 'Total Duration (s)';
            app.OnEx_TPF_TotalDurationsec = uieditfield(app.On_TP_ExperimentalData, 'numeric');
            app.OnEx_TPF_TotalDurationsec.Limits = [0 Inf];
            app.OnEx_TPF_TotalDurationsec.Editable = 'off';
            app.OnEx_TPF_TotalDurationsec.Value = 10;
            app.OnEx_TPA_ParameterValues = uitable(app.On_TP_ExperimentalData);
            app.OnEx_TPA_ParameterValues.ColumnName = {'Parameter'; 'Previous Est.'; 'Current Est.'};
            app.OnEx_TPA_ParameterValues.RowName = {};
            app.OnEx_TPF_PEnOEDTime = uieditfield(app.On_TP_ExperimentalData, 'numeric');
            app.OnEx_TPF_PEnOEDTime.Limits = [0 100];
            app.OnEx_TP_PEOEDLabel = uilabel(app.On_TP_ExperimentalData);
            app.OnEx_TP_PEOEDLabel.Text = 'PE+OED (%)';
            app.OnEx_TPS_PEnOEDSlider = uislider(app.On_TP_ExperimentalData);
            app.OnEx_TPS_PEnOEDSlider.ValueChangedFcn = createCallbackFcn(app, @OnEx_TPS_PEnOEDSliderValueChanged, true);
            app.OnEx_TP_LoopNumberLabel = uilabel(app.On_TP_ExperimentalData);
            app.OnEx_TP_LoopNumberLabel.Text = 'Loop Number';
            app.OnEx_TPF_NumLoops = uieditfield(app.On_TP_ExperimentalData, 'numeric');
            app.OnEx_TPF_NumLoops.Limits = [0 Inf];
            app.OnEx_TPF_NumLoops.Editable = 'off';
            app.OnEx_TPF_NumLoops.Value = 1;
            app.OnEx_TPB_StartExperiment = uibutton(app.On_TP_ExperimentalData, 'state');
            app.OnEx_TPB_StartExperiment.ValueChangedFcn = createCallbackFcn(app, @OnEx_TPB_StartExperimentValueChanged, true);
            app.OnEx_TPB_StartExperiment.Text = 'Start the Experiment';
            app.At_TI_ExperimentState_2 = uilamp(app.On_TP_ExperimentalData);
            app.On_TG_StateDiagram = uiaxes(app.On_TP_ExperimentalData);
            app.On_TG_StateDiagram.XAxisLocation = 'origin';
            app.On_TL_StateList = uilistbox(app.On_TP_ExperimentalData);
            app.On_TL_StateList.Items = {'S0: Initialisation', 'S1: Initial OED', 'S2: Experiment', 'S3: Experiment + PE', 'S4: Experiment + OED', 'S5: Final PE'};
            app.On_TL_StateList.Value = 'S0: Initialisation';
            app.X_StateText = uitextarea(app.U_Main);
            app.X_StateText.Editable = 'off';
            app.X_StateText.Value = {'Ready.'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = APP_V5_1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.U_Main)

            % Execute the startup function
            runStartupFcn(app, @APP_StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.U_Main)
        end
    end
end