classdef APP_V4_0Demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        U_Main                          matlab.ui.Figure
        Me_M_File                       matlab.ui.container.Menu
        Fi_MM_SaveSettings              matlab.ui.container.Menu
        Fi_MM_LoadSettings              matlab.ui.container.Menu
        Fi_MM_SaveImage                 matlab.ui.container.Menu
        Fi_MM_Exit                      matlab.ui.container.Menu
        Me_M_SeSettings                 matlab.ui.container.Menu
        MeSe_MM_CUDAMenu                matlab.ui.container.Menu
        MeSeCU_MMM_ImageProcessing      matlab.ui.container.Menu
        MeSeCU_MMM_ParameterEstimation  matlab.ui.container.Menu
        MeSeCU_MMM_OED                  matlab.ui.container.Menu
        Me_M_Help                       matlab.ui.container.Menu
        MeHe_MM_Menu                    matlab.ui.container.Menu
        MeHe_MM_About                   matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        Se_TP_Directory                 matlab.ui.container.Panel
        APPDirectoryLabel               matlab.ui.control.Label
        SeDi_TPX_APPDirectory           matlab.ui.control.TextArea
        SeDi_TPB_CopyAPPDirectory       matlab.ui.control.Button
        BioFormatsLabel                 matlab.ui.control.Label
        SeDi_TPF_BioFormatsDirectory    matlab.ui.control.EditField
        SeDi_TPB_BrowseBioFormats       matlab.ui.control.Button
        SeDi_TPB_CopyBioFormats         matlab.ui.control.Button
        SeDi_TPB_InitialiseAMIGO        matlab.ui.control.Button
        SeDi_TPB_BrowseAMIGO            matlab.ui.control.Button
        SeDi_TPB_CopyAMIGO              matlab.ui.control.Button
        AMIGOLabel                      matlab.ui.control.Label
        SeDi_TPF_AMIGODirectory         matlab.ui.control.EditField
        OEDTab                          matlab.ui.container.Tab
        ActuationSystemTab              matlab.ui.container.Tab
        T_ImageProcessing               matlab.ui.container.Tab
        Im_TG_SelectedImage             matlab.ui.control.UIAxes
        Im_TP_Files                     matlab.ui.container.Panel
        ImFi_TPB_BrowseFiles            matlab.ui.control.Button
        ImFi_TPB_CopyAddress            matlab.ui.control.Button
        ImFi_TPC_TrackNew               matlab.ui.control.CheckBox
        ImFi_TPL_FileListBox            matlab.ui.control.ListBox
        AddressEditFieldLabel           matlab.ui.control.Label
        ImFi_TPF_AddressField           matlab.ui.control.EditField
        Im_TC_QuickAdjust               matlab.ui.control.CheckBox
        debugareaTextAreaLabel          matlab.ui.control.Label
        DB_debugareaTextArea            matlab.ui.control.TextArea
        Im_TP_Background                matlab.ui.container.Panel
        ImBa_TPG_BackgroundImage        matlab.ui.control.UIAxes
        ImBa_TPA_FileTypeTable          matlab.ui.control.Table
        ImBa_TPB_SelectBackground       matlab.ui.control.Button
        ImBa_TPB_AddType                matlab.ui.control.Button
        ImBa_TPB_DeleteType             matlab.ui.control.Button
        Im_TC_RemoveBackground          matlab.ui.control.CheckBox
        ParameterEstimationTab          matlab.ui.container.Tab
        T_AutomaticInferenceTab         matlab.ui.container.Tab
        At_TP_Files                     matlab.ui.container.Panel
        AtFi_TPB_BrowseFile             matlab.ui.control.Button
        AtFi_TPG_SegmentedImage         matlab.ui.control.UIAxes
        AtFi_TPA_CurrentMeasuredData    matlab.ui.control.Table
        At_TP_ExperimentalData          matlab.ui.container.Panel
        AtEx_TPG_ExperimentDataRecord   matlab.ui.control.UIAxes
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        StepEditFieldLabel              matlab.ui.control.Label
        StepEditField                   matlab.ui.control.NumericEditField
        AtEx_TPA_ExperimentalData       matlab.ui.control.Table
        At_TB_StartExperiment           matlab.ui.control.StateButton
        At_TI_ExperimentState           matlab.ui.control.Lamp
        At_TG_StateDiagram              matlab.ui.control.UIAxes
        At_TL_StateList                 matlab.ui.control.ListBox
        T_OnLineOEDTab                  matlab.ui.container.Tab
        On_TP_Files_2                   matlab.ui.container.Panel
        OnFi_TPB_BrowseFolder           matlab.ui.control.Button
        OnFi_TPG_SegmentedImage         matlab.ui.control.UIAxes
        SettingFileLabel                matlab.ui.control.Label
        OnFi_TPF_OnlineOEDSettings      matlab.ui.control.EditField
        OnFi_TPL_ODEList                matlab.ui.control.ListBox
        ModelDifferentialEquationsLabel  matlab.ui.control.Label
        BrowseSettingFileButton         matlab.ui.control.StateButton
        On_TP_ExperimentalData          matlab.ui.container.Panel
        OnEx_TPG_ExperimentDataRecord   matlab.ui.control.UIAxes
        TotalDurationminLabel           matlab.ui.control.Label
        OnEx_TPF_TotalDurationsec       matlab.ui.control.NumericEditField
        OnEx_TPA_ParameterValues        matlab.ui.control.Table
        OnEx_TPF_PEnOEDTime             matlab.ui.control.NumericEditField
        PEOEDSliderLabel                matlab.ui.control.Label
        OnEx_TPS_PEnOEDSlider           matlab.ui.control.Slider
        NumberofLoopsLabel              matlab.ui.control.Label
        OnEx_TPF_NumLoops               matlab.ui.control.NumericEditField
        On_TG_StateDiagram              matlab.ui.control.UIAxes
        On_TL_StateList                 matlab.ui.control.ListBox
        On_TB_StartExperiment           matlab.ui.control.StateButton
        On_TI_ExperimentState           matlab.ui.control.Lamp
        OnEx_TPX_UnitLabel              matlab.ui.control.Label
        DropDownLabel                   matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        X_StateText                     matlab.ui.control.TextArea
    end

    properties (Access = private)
        Im_FileDirectory               % directory of image files
        Im_CurrentFile                 % the file under editing
        G_InitialWorkingDirectory     % working directory
        Im_CurrentImages               % A cell array storing all the images we are currently working on
        % by default,
        % 1st - raw/BGremoved (depends on the setting),
        % 2nd - adjusted of 1st,
        % 3rd - raw
        % 4th - Background
        % nth - further operations
        Im_SelectedImage         % temporary image, image selection only
        Im_FileWatcher              % watching the state of the working folder, System.IO.FileSystemWatcher
        At_FileDirectory            % the file directory for automatic inference
        At_FileWatcher              % watching the state of the working folder
        At_CurrentFile              % name of the current image file for automatic inference
        At_ReferenceTime            % The time whe the experiment is started
        At_CurrentImageData         % Data of the current image
        On_CurrentImageData         % Data of the current image
        Ac_ActuationSystem             % details of the actuation system:
        % BaudRate, modbus, COMPort, Axis_Number, Preset_Speed
        On_Inputs                   % AMIGO inputs structure for on-line OED
        On_ExperimentalData         % Description
        On_FileWatcher              % Description
        On_ReferenceTime            % Description
        demoData;                   % data for demo
        On_FileListener             % File listener for on-line OED
        On_Democount                % Description
        On_DemoImages               % Description
        On_DemoPreInput      % Description
    end
    
    methods (Access = private)
        
        function image = G_ReadImage(app,fullFileName)
            % use the toolbox Bio-Formats 5.9.1. and the script I wrote ('bfReadImage.m') to load the image
            tempcd=cd;
            try
                cd(app.SeDi_TPF_BioFormatsDirectory.Value);
                [~,image]=evalc(['bfopen(''',fullFileName,''')']);
                image=image{1}{1};
            catch
                image=imread(fullFileName);
            end
            if (length(app.MeSeCU_MMM_ImageProcessing.Checked)==2) % if Use CUDA is on
                image=gpuArray(image);
            end
            cd(tempcd);
        end
        
        
        function G_PrintState(app,text)
            app.X_StateText.Value=text;
        end
        
        function Im_NewFileDetected(app,~,arg)
            tempdir=dir(app.Im_FileDirectory);
            tempdir=struct2cell(tempdir(~cellfun(@(x) x,{tempdir.isdir})));
            app.ImFi_TPL_FileListBox.Items=tempdir(1,:);
            try
                app.ImFi_TPL_FileListBox.Value=char(arg.Name);
                ImFi_TPL_FileListBoxValueChanged(app);
                title({'New file detected:';app.Im_CurrentFile},'Parent', app.Im_TG_SelectedImage);
                app.ImFi_TPL_FileListBox.Value=string(arg.Name);
            catch
            end
            G_PrintState(app,string(arg.Name));
        end
        
        
        function At_NewFileDetected(app,~,arg)
            if (app.At_TB_StartExperiment.Value)
                try
                    timediff=char(datetime-app.At_ReferenceTime);
                    At_UpdateState(app,3);
                    
                    app.At_CurrentImageData=...
                        G_DrawSegmentedImage(app,app.AtFi_TPG_SegmentedImage,[app.At_FileDirectory,'\',char(arg.Name)]);
                    app.At_CurrentFile=char(arg.Name);
                    title({['New image added at: ',timediff];char(arg.Name)},...
                        'Parent',app.AtFi_TPG_SegmentedImage);
                    
                    if (app.At_TB_StartExperiment.Value)
                        % add the new experimental data
                        t=regexp(timediff,':','split');
                        app.AtEx_TPA_ExperimentalData.Data.time{end}=cellfun(@str2num,t)*[3600;60;1];
                        app.AtEx_TPA_ExperimentalData.Data.input{end}=At_NextInput(app);
                        app.AtEx_TPA_ExperimentalData.Data.mean{end}=app.At_CurrentImageData.mean;
                        app.AtEx_TPA_ExperimentalData.Data.std{end}=app.At_CurrentImageData.std;
                        app.AtEx_TPA_ExperimentalData.Data=[app.AtEx_TPA_ExperimentalData.Data;...
                            {app.AtEx_TPA_ExperimentalData.Data.time{end}+app.StepEditField.Value,...
                            app.AtEx_TPA_ExperimentalData.Data.input{end},...
                            [],[]}];
                        At_UpdateState(app,4);
                        At_DisplayExperimentalData(app);
                        At_UpdateState(app,1);
                        Ac_input2motion(app,At_NextInput(app));
                        At_UpdateState(app,2);
                    end
                catch
                    At_UpdateState(app,2);
                end
            end
        end
        
        function On_NewFileDetected(app,~,arg)
            try
                %timediff=char(datetime-app.On_ReferenceTime);
                
                if (length(app.OnFi_TPG_SegmentedImage.Visible)==3)
                    pos=app.OnFi_TPG_SegmentedImage.Position;
                    r=0;
                    app.OnFi_TPG_SegmentedImage.Position=pos*[...
                        1,0,0,0;
                        0,1,0,0;
                        -0.5*r,0,1+2*r,0;
                        0,-2*r,0,1+2*r];
                    app.OnFi_TPG_SegmentedImage.Visible='on';
                end
                
                app.On_CurrentImageData=...
                    G_DrawSegmentedImage(app,app.OnFi_TPG_SegmentedImage,[char(app.On_FileWatcher.Path),'\',char(arg.Name)]);
                title({['New image added at: ',num2str(5*(app.On_Democount.measure+1)),' min'];char(arg.Name)},...
                    'Parent',app.OnFi_TPG_SegmentedImage);


                if (app.On_TB_StartExperiment.Value)
                    %app.On_Democount;%sub 123,input 123,measure 0123
                    app.On_Democount.measure=app.On_Democount.measure+1;
                    On_DemoDisplay(app);
                    if (mod(app.On_Democount.measure,40)==39)
                        app.On_Democount.input=app.On_Democount.input+1;
                    end
                end
                %On_DisplayExperimentalData(app);
            catch
                %On_UpdateState(app,2);
            end
        end
        
        function At_ResetReferenceTime(app)
            app.At_ReferenceTime=datetime;
        end
        
        function value = At_NextInput(app)
            if (app.At_CurrentImageData.mean>app.ThresholdEditField.Value)
                value=1;
            else
                value=0;
            end
        end
        
        
        function Ac_InitialAllAxes(app)
            try
                % Connect to actuators
                % Set it to the value the actuators is using
                app.Ac_ActuationSystem.BaudRate = 38400;
                app.Ac_ActuationSystem.COMPort = 'COM5';
                app.Ac_ActuationSystem.AxisNumber=2;
                app.Ac_ActuationSystem.MaxSpeed=120*100;
                % Create MODBUS object for actuators and connect to it
                app.Ac_ActuationSystem.modbus=modbus(...
                    'serialrtu',app.Ac_ActuationSystem.COMPort,...
                    'BaudRate',app.Ac_ActuationSystem.BaudRate);
                indexList=1:app.Ac_ActuationSystem.AxisNumber;
                
                % Set Control Bit ON (Enable MODBUS control) for all axis
                for i=indexList
                    write(app.Ac_ActuationSystem.modbus,'coils',1064,1,i);
                end
                pause(0.2); % Actuators need some time between the rising edges, refer to IAI manual
                
                % Reset Alarms, in case any alarm were present. So that subsequent operations can be performed
                for i=indexList
                    Ac_Alarm_Reset(app,i);
                end
                pause(0.2); % Actuators need some time between the rising edges, refer to IAI manual
                
                % Turn on the Servo for all axis
                for i=indexList
                    write(app.Ac_ActuationSystem.modbus,'coils',1028,1,i);
                    pause(0.2); % Give it a gap between servo turn on
                end
                pause(0.5); % Actuators need some time between the rising edges, refer to IAI manual
                
                % Home return all axis
                for i=indexList
                    Ac_Home_Return(app,i);
                end
                
                % check home return status
                HEND=false(1,app.Ac_ActuationSystem.AxisNumber);
                while (true)
                    for i=indexList
                        % read home return status from all axis
                        HEND(i)=read(app.Ac_ActuationSystem.modbus,'coils',268,1,i);
                    end
                    
                    % pause 0.3s between each read cycle. Also after the last cycle
                    pause(0.3);
                    
                    %jump out of the loop once all axis have homed
                    if (all(HEND))
                        break;
                    end
                end
                % set up Preset speed
                for i=indexList
                    Ac_Write_Speed(app,app.Ac_ActuationSystem.MaxSpeed,i);
                end
                Ac_input2motion(app,0);
                app.Ac_ActuationSystem.Ready=true;
            catch
                uialert(app.U_Main,'The device is not connected!','Cannot Initialize','Icon','error');
            end
        end
        
        function Ac_Alarm_Reset(app,Axis)
            % This function write to the Alarm Reset coil, refer to manual.
            % 1 input (Axis)
            % This function writes to this coil, 0, 1, 0 sequentially, to create an
            % rising edge that reset the alarm.
            
            % Alarm Reset for given axis
            write(app.Ac_ActuationSystem.modbus,'coils',1032,0,Axis);
            write(app.Ac_ActuationSystem.modbus,'coils',1032,1,Axis);
            write(app.Ac_ActuationSystem.modbus,'coils',1032,0,Axis);
        end
        
        function Ac_Home_Return(app,Axis)
            % This function write to the Home Return coil, refer to manual.
            % 1 input (Axis)
            % This function writes to this coil, 0, 1, 0 sequentially, to create an
            % rising edge that homes the axis.
            
            % Home given axis
            write(app.Ac_ActuationSystem.modbus,'coils',1036,0,Axis);
            write(app.Ac_ActuationSystem.modbus,'coils',1036,1,Axis);
            write(app.Ac_ActuationSystem.modbus,'coils',1036,0,Axis);
        end
        
        % This function write to the "Speed Specification Register (VCMD)"
        % refer to manual.
        % Input the speed in a single decimal value unit 0.01mm/s. And Axis number
        function Ac_Write_Speed(app,Speed,Axis_Number)
            % Convert the input speed value to 2 values so as to write in 2 16-bit
            % registers
            [Speed_reg_Hi , Speed_reg_Lo] = Ac_dec2regs2(app,Speed);
            
            % write the data to registers for given axis number
            write(app.Ac_ActuationSystem.modbus,'holdingregs',39173,...
                [Speed_reg_Hi,Speed_reg_Lo],Axis_Number);
            
        end
        
        % This function convert a single decimal value to two separate decimal
        % values, so as to store in 2 16-bits registers.
        % 1 input, 2 output, both in decimal.
        function [reg_Hi,reg_Lo]=Ac_dec2regs2(app,Original)
            
            Original_Hex = dec2hex(Original,8); % Convert decimal input a into hex string with 8 digits
            
            reg_Hi_Hex = Original_Hex(1:4); % Hi register equals to the first 4 digit of Original_Hex
            reg_Hi = hex2dec(reg_Hi_Hex); % Convert hex value back to decimal for Hi reg
            
            reg_Lo_Hex = Original_Hex(5:8); % Lo register equals to the last 4 digit of Original_Hex
            reg_Lo = hex2dec(reg_Lo_Hex); % Convert hex value back to decimal for Lo reg
            
        end
        
        % This function convert two decimal values stored separately in 2 16-bit
        % registers to a single decimal value
        % 2 input, 1 output, both in decimal.
        function Decimal_Value=Ac_regs2_2dec(app,reg_Hi,reg_Lo)
            reg_Hi_Hex = dec2hex(reg_Hi); % Convert the Hi register input into Hex value
            reg_Hi_Hex = pad(reg_Hi_Hex,4,'left','0'); % Pad zeros in front of Hex value to form 4 digit
            
            reg_Lo_Hex = dec2hex(reg_Lo); % Convert the Lo register input into Hex value
            reg_Lo_Hex = pad(reg_Lo_Hex,4,'left','0'); % Pad zeros in front of Hex value to form 4 digit
            
            % Combine reg_Hi_Hex, reg_Lo_Hex together to form a single Hex value
            Decimal_Value_Hex = [reg_Hi_Hex,reg_Lo_Hex];
            
            % Convert the final Hex value to decimal
            Decimal_Value = hex2dec(Decimal_Value_Hex);
        end
        
        % This function write to the "Target Position Specification (PCMD)"
        % registers, refer to manual.
        % Input the target position in a single decimal value, and axis number actuators will start to move
        % immediately after the execution of this funciton.
        % the position (height) is in mm.
        function Ac_WriteTargetPosition(app,Target_Position,Axis_Number)
            % Convert the input target position value to 2 values so as to write in 2 16-bit
            % registers
            [Target_Position_reg_Hi , Target_Position_reg_Lo] = Ac_dec2regs2(app,Target_Position);
            
            % write the data to registers for given axis number
            write(app.Ac_ActuationSystem.modbus,'holdingregs',39169,[Target_Position_reg_Hi,Target_Position_reg_Lo],Axis_Number);
        end
        
        % This function read the "Current Position (PNOW)"
        % registers, refer to manual.
        % Input the axis number. Output is a single decimal value unit 0.01mm.
        %
        % A correction method was added because sometimes when at home position (0mm),
        % Current_Position would be read as 400k+ (some very large number)
        % If Current_Position falls out resonable range Current_Position=0
        function Current_Position = Ac_ReadCurrentPosition(app,Axis_Number)
            % Read current position from PNOW registers into two decimal values, for
            % given axis number
            Position = read(app.Ac_ActuationSystem.modbus,'holdingregs',36865,2,Axis_Number);
            
            % Convert 2 decimal values into 1 single decimal value
            Current_Position=Ac_regs2_2dec(app,Position(1),Position(2));
            
            % This was added because sometimes when at home position (0mm),
            % Current_Position would be read as 400k+ (some very large number)
            % If Current_Position falls out resonable range Current_Position=0
            if ((Current_Position>200000) || (Current_Position<0))
                Current_Position=0;
            end
        end
        
        % This function will move the syringers according to the imput design.
        % later this will cope with the calibration module.
        function Ac_input2motion(app,input)
            height=[round(input*10,0);round((1000-input)*10,0)];
            for ind=1:2
                try
                    Ac_WriteTargetPosition(app,height(ind),ind);
                catch
                end
            end
        end
        
        function At_DisplayExperimentalData(app)
            xlabel(app.AtEx_TPG_ExperimentDataRecord,'time (s)');
            % draw the input values
            yyaxis(app.AtEx_TPG_ExperimentDataRecord,'left');
            ylabel(app.AtEx_TPG_ExperimentDataRecord,'Input (\muM)');
            stairs(app.AtEx_TPG_ExperimentDataRecord,...
                cell2mat(app.AtEx_TPA_ExperimentalData.Data.time),...
                cell2mat(app.AtEx_TPA_ExperimentalData.Data.input));
            % draw the observable(s)
            yyaxis(app.AtEx_TPG_ExperimentDataRecord,'right');
            ylabel(app.AtEx_TPG_ExperimentDataRecord,'Observables (A.U.)');
            errorbar(app.AtEx_TPG_ExperimentDataRecord,...
                cell2mat(app.AtEx_TPA_ExperimentalData.Data.time(1:end-1)),...
                cell2mat(app.AtEx_TPA_ExperimentalData.Data.mean(1:end-1)),...
                cell2mat(app.AtEx_TPA_ExperimentalData.Data.std(1:end-1)),'o');
        end
        
        function G_InitialPaths(app)
            % read initial directory of the APP
            app.G_InitialWorkingDirectory=cd;
            app.SeDi_TPX_APPDirectory.Value=app.G_InitialWorkingDirectory;
            p=regexp(path,';','split')';
            AMIGOIndex=contains(p,'AMIGO');
            
            % read AMIGO's directory if it has been initialised
            if (sum(AMIGOIndex)>100)
                pAMIGO=p(AMIGOIndex);
                [~,id]=min(cellfun(@length,pAMIGO));
                app.SeDi_TPF_AMIGODirectory.Value=pAMIGO{id};
            end
            
            % read Bio-Formats directory if it is available
            tempcd=cd;
            try
                cd('bfmatlab');
                app.SeDi_TPF_BioFormatsDirectory.Value=cd;
            catch
            end
            cd(tempcd);
        end
        
        
        function Data = G_DrawSegmentedImage(app,ax,file)
            Image=G_ReadImage(app,file);
            tempcd=cd;
            cd(app.G_InitialWorkingDirectory);
            Data=CellData(Image);
            cd(tempcd);
            
            % Show the segmentation
            CC = bwconncomp(Data.cellIm);
            L = labelmatrix(CC);
            BWoutline = bwperim(Data.cellIm);
            Lrgb = label2rgb(L, 'jet', 'k', 'shuffle');
            BWoutline=imdilate(BWoutline,strel('disk',3));
            BWoutline1=repmat(BWoutline,1,1,3);
            
            BWoutline1(:,:,2)=false;
            BWoutline0=false(size(BWoutline1));
            BWoutline0(:,:,2)=BWoutline;
            
            Lrgb(BWoutline0)=0;
            Lrgb(BWoutline1)=255;
            %%{
            imshow(imadjust(Image),'Parent',ax);
            hold(ax,'on');
            himage = imshow(Lrgb,'Parent',ax);
            himage.AlphaData = 0.6;
            hold(ax,'off');
            %}
            
            Data=table(...
                {'Number of Cells';
                'Effective Pixels';
                'Average value';
                'Absolute Standard Deviation';
                'Relative Standard Deviation'},...
                {length(Data.fluoLevels);
                [num2str(Data.effectiveP*100,'%.2f'),'%'];
                Data.mean;
                Data.std;
                [num2str(Data.std/Data.mean*100,'%.2f'),'%'];
                });
        end
        
        function G_DrawFSMState(app,ax,x,y,ind,selected)
            hold (ax,'on');
            if (selected)
                rectangle(ax,...
                    'Position',[x*2.56-0.5,y*2.56-0.5,1,1],...
                    'Curvature',[1,1],...
                    'linewidth',0.75,...
                    'EdgeColor','r','FaceColor',[1,1,0.8]);
            else
                rectangle(ax,...
                    'Position',[x*2.56-0.5,y*2.56-0.5,1,1],...
                    'Curvature',[1,1],...
                    'linewidth',0.75,...
                    'EdgeColor','k','FaceColor',[0.8,0.8,1]);
            end
            text(ax,...
                x*2.56,y*2.56,num2str(ind),'HorizontalAlignment','center','FontSize',20);
            hold (ax,'off');
        end
        
        function G_DrawFSMArrow(app,ax,x1,y1,x2,y2)
            hold (ax,'on');
            x1=x1*2.56;
            x2=x2*2.56;
            y1=y1*2.56;
            y2=y2*2.56;
            dist=sqrt((x2-x1)^2+(y2-y1)^2);
            xy=interp1([x1,y1;x2,y2],1+0.5/dist);
            uv=interp1([x1,y1;x2,y2],2-0.5/dist)-xy;
            quiver(ax,xy(1),xy(2),uv(1),uv(2),'-k','AutoScale','off','LineWidth',2,'MaxHeadSize',1);
            hold (ax,'off');
        end
        
        function At_UpdateState(app,currentS)
            himage = imshow(true(2,2),'Parent',app.At_TG_StateDiagram);
            himage.AlphaData = 0;
            
            S={[0;0;1];[1;0;0];[2;0;-1];[3;1;-1];[4;1;0]};
            
            G_DrawFSMArrow(app,app.At_TG_StateDiagram,0,1,0,0); %0-1
            G_DrawFSMArrow(app,app.At_TG_StateDiagram,0,0,0,-1); %1-2
            G_DrawFSMArrow(app,app.At_TG_StateDiagram,0,-1,1,-1); %2-3
            G_DrawFSMArrow(app,app.At_TG_StateDiagram,1,-1,1,0); %3-4
            G_DrawFSMArrow(app,app.At_TG_StateDiagram,1,0,0,0); %4-1
            
            cellfun(@(Si)G_DrawFSMState(app,app.At_TG_StateDiagram,...
                Si(2),Si(3),Si(1),false), S);
            
            app.At_TL_StateList.Value={};
            if (currentS>-1)
                G_DrawFSMState(app,app.At_TG_StateDiagram,...
                    S{currentS+1}(2),S{currentS+1}(3),currentS,true);
                app.At_TL_StateList.Value=app.At_TL_StateList.Items(currentS+1);
            end
        end
        
        function On_UpdateState(app,currentS)
            himage = imshow(true(2,2),'Parent',app.On_TG_StateDiagram);
            himage.AlphaData = 0;
            
            S={[0;-1;1];[1;-1;0];[2;0;0];[3;0;1];[4;1;1];[5;1;0]};
            
            G_DrawFSMArrow(app,app.On_TG_StateDiagram,-1,1,-1,0); %0-1
            G_DrawFSMArrow(app,app.On_TG_StateDiagram,-1,0,0,0); %1-2
            G_DrawFSMArrow(app,app.On_TG_StateDiagram,0,0,0,1); %2-3
            G_DrawFSMArrow(app,app.On_TG_StateDiagram,0,1,1,1); %3-4
            G_DrawFSMArrow(app,app.On_TG_StateDiagram,1,1,0,0); %4-2
            G_DrawFSMArrow(app,app.On_TG_StateDiagram,0,0,1,0); %2-5
            
            cellfun(@(Si)G_DrawFSMState(app,app.On_TG_StateDiagram,...
                Si(2),Si(3),Si(1),false), S);
            
            app.On_TL_StateList.Value={};
            if (currentS>-1)
                G_DrawFSMState(app,app.On_TG_StateDiagram,...
                    S{currentS+1}(2),S{currentS+1}(3),currentS,true);
                app.On_TL_StateList.Value=app.On_TL_StateList.Items(currentS+1);
            end
        end
        
        function On_DisplayExperimentalData(app)
            try
                xlabel(app.OnEx_TPG_ExperimentDataRecord,'time (s)');
                % draw the input values
                yyaxis(app.OnEx_TPG_ExperimentDataRecord,'left');
                ylabel(app.OnEx_TPG_ExperimentDataRecord,'Input (\muM)');
                stairs(app.OnEx_TPG_ExperimentDataRecord,...
                    [app.On_Inputs.exps.t_con{1},app.On_Inputs.exps.t_con{1}(end)+app.On_ExperimentalData.stepDuration],...
                    [app.On_Inputs.exps.u{1},app.On_Inputs.exps.u{1}(end)],'LineWidth',1.5);
                % draw the observable(s)
                yyaxis(app.OnEx_TPG_ExperimentDataRecord,'right');
                ylabel(app.OnEx_TPG_ExperimentDataRecord,'Observables (A.U.)');
                errorbar(app.OnEx_TPG_ExperimentDataRecord,...
                    app.On_Inputs.exps.t_s{1},...
                    app.On_Inputs.exps.exp_data{1},...
                    app.On_Inputs.exps.error_data{1},'-o',...
                    'CapSize',10);
                xlim(app.OnEx_TPG_ExperimentDataRecord,[0,app.OnEx_TPF_TotalDurationsec.Value]);
            catch
                disp(app.On_Inputs.exps);
            end
        end
        
        function On_PE(app)
            On_UpdateState(app,3);
            pause(0.5);
            app.OnEx_TPA_ParameterValues.Data=table(...
                cellstr(app.On_Inputs.model.par_names),...
                num2cell(app.demoData.p(:,app.On_Democount.sub)),num2cell(app.demoData.p(:,app.On_Democount.sub+1)));
            On_UpdateState(app,4);
            pause(1);
            app.On_Democount.sub=app.On_Democount.sub+1;
            On_UpdateState(app,2);
        end
        
        function On_PEnOED(app)
            On_PE(app);
            On_UpdateState(app,4);
            tempcd=cd;
            cd(app.G_InitialWorkingDirectory);
            [app.On_ExperimentalData.y0,nextinput,nextpred]=...
                OED(...
                app.On_Inputs,...
                app.On_ExperimentalData.duration,...
                app.On_ExperimentalData.y0,...
                app.On_ExperimentalData.stepDuration);
            app.On_ExperimentalData.input=[app.On_ExperimentalData.input,nextinput];
            app.On_ExperimentalData.pred=[app.On_ExperimentalData.pred;nextpred];
            app.On_ExperimentalData.Allinput=[app.On_ExperimentalData.Allinput,app.On_ExperimentalData.input,nextinput];
            cd(tempcd);
            On_UpdateState(app,2);
        end
        
        function temp_Newimage(app, obj, event, string_arg)
            app.On_Inputs.exps.n_s{1}=app.On_Inputs.exps.n_s{1}+1;
            try
                app.On_Inputs.exps.t_s{1}(end+1)=app.On_Inputs.exps.t_s{1}(end)+app.On_ExperimentalData.obsstep;
            catch
                app.On_Inputs.exps.t_s{1}=0;
            end
            try
                app.On_Inputs.exps.error_data{1}(end+1)=app.On_ExperimentalData.pred(1)*0.05;
            catch
                app.On_Inputs.exps.error_data{1}=app.On_ExperimentalData.pred(1)*0.05;
            end
            try
                app.On_Inputs.exps.exp_data{1}(end+1)=...
                    random('norm',1,0.05,1,1)*app.On_ExperimentalData.pred(1);
            catch
                app.On_Inputs.exps.exp_data{1}=...
                    random('norm',1,0.05,1,1)*app.On_ExperimentalData.pred(1);
            end
            
            app.On_ExperimentalData.pred(1)=[];
            On_DisplayExperimentalData(app);
        end
        
        function temp_Newinput(app, obj, event, string_arg)
            % app.On_Inputs.exps.n_steps{1}=app.On_Inputs.exps.n_steps{1}+1;
            %try
            % app.On_Inputs.exps.t_con{1}(end+1)=...
            %    app.On_Inputs.exps.t_con{1}(end)+15;
            % catch
            %    app.On_Inputs.exps.t_con{1}=0;
            % end
            %app.On_Inputs.exps.t_f{1}=app.On_Inputs.exps.t_f{1}+15;
            % try
            % app.On_Inputs.exps.u{1}(end+1)=app.On_ExperimentalData.input(1);
            % catch
            %     app.On_Inputs.exps.u{1}=app.On_ExperimentalData.input(1);
            % end
            Ac_input2motion(app,app.On_ExperimentalData.input(1));
            app.On_ExperimentalData.input(1)=[];
            disp(app.On_ExperimentalData.input);
        end
        
        
        function On_DemoDisplay(app)
            stairs(app.OnEx_TPG_ExperimentDataRecord,...
                0:200:app.On_Democount.sub*1000,...
                app.demoData.u([1:5*app.On_Democount.sub,5*app.On_Democount.sub]),'-b','LineWidth',1.5);
            hold(app.OnEx_TPG_ExperimentDataRecord,'on');
            %app.On_Democount;%sub 123,input 123,measure 0123
            xlabel(app.OnEx_TPG_ExperimentDataRecord,'time (min)');
            
            % draw the input values
            yyaxis(app.OnEx_TPG_ExperimentDataRecord,'left');
            %plan
            ylabel(app.OnEx_TPG_ExperimentDataRecord,'Input (\muM)');
            %real
            try
            stairs(app.OnEx_TPG_ExperimentDataRecord,...
                200*(0:app.On_Democount.input),...
                app.demoData.u([1:app.On_Democount.input,app.On_Democount.input]),'-b','LineWidth',2.5);
            catch
            end
            % draw the observable(s)
            yyaxis(app.OnEx_TPG_ExperimentDataRecord,'right');
            ylabel(app.OnEx_TPG_ExperimentDataRecord,'Observables (A.U.)');
            errorbar(app.OnEx_TPG_ExperimentDataRecord,...
                25*(0:app.On_Democount.measure),...
                app.demoData.obs(1:app.On_Democount.measure+1),...
                0.05*app.demoData.obs(1:app.On_Democount.measure+1),'-o','LineWidth',2.5,...
                'CapSize',6);
            
            hold(app.OnEx_TPG_ExperimentDataRecord,'off');
            yyaxis(app.OnEx_TPG_ExperimentDataRecord,'left');
            app.OnEx_TPG_ExperimentDataRecord.YColor='b';
            hold(app.OnEx_TPG_ExperimentDataRecord,'on');
            rectangle(app.OnEx_TPG_ExperimentDataRecord,...
                'Position',[1000*(app.On_Democount.sub-1),0,1000,1000],...
                'FaceColor',[0.98,0.98,0.88]);
            rectangle(app.OnEx_TPG_ExperimentDataRecord,...
                'Position',[1000-app.OnEx_TPF_PEnOEDTime.Value,0,app.OnEx_TPF_PEnOEDTime.Value,1000],...
                'FaceColor',[0.88,0.88,0.98]);
            rectangle(app.OnEx_TPG_ExperimentDataRecord,...
                'Position',[2000-app.OnEx_TPF_PEnOEDTime.Value,0,app.OnEx_TPF_PEnOEDTime.Value,1000],...
                'FaceColor',[0.88,0.88,0.98]);
            plot(app.OnEx_TPG_ExperimentDataRecord,[1000,1000],...
                app.OnEx_TPG_ExperimentDataRecord.YLim,'--k','LineWidth',1);
            plot(app.OnEx_TPG_ExperimentDataRecord,[2000,2000],...
                app.OnEx_TPG_ExperimentDataRecord.YLim,'--k','LineWidth',1);
            stairs(app.OnEx_TPG_ExperimentDataRecord,...
                0:200:app.On_Democount.sub*1000,...
                app.demoData.u([1:5*app.On_Democount.sub,5*app.On_Democount.sub]),'--b','LineWidth',1.5);
            stairs(app.OnEx_TPG_ExperimentDataRecord,...
                200*(0:app.On_Democount.input),...
                app.demoData.u([1:app.On_Democount.input,app.On_Democount.input]),'-b','LineWidth',2.5);
            
            xlim(app.OnEx_TPG_ExperimentDataRecord,[0,3000]);
            
            
            yyaxis(app.OnEx_TPG_ExperimentDataRecord,'right');
            app.OnEx_TPG_ExperimentDataRecord.YColor='r';
            
            errorbar(app.OnEx_TPG_ExperimentDataRecord,...
                25*(0:app.On_Democount.measure),...
                app.demoData.obs(1:app.On_Democount.measure+1),...
                0.05*app.demoData.obs(1:app.On_Democount.measure+1),'-or','LineWidth',2.5,...
                'CapSize',6);
            
            hold(app.OnEx_TPG_ExperimentDataRecord,'off');
            yyaxis(app.OnEx_TPG_ExperimentDataRecord,'left');
            hold(app.OnEx_TPG_ExperimentDataRecord,'off');
            if (app.On_Democount.sub>1)
                app.OnEx_TPA_ParameterValues.Data.Var2=num2str(app.demoData.p(:,app.On_Democount.sub-1),'%1.4e');
                app.OnEx_TPA_ParameterValues.Data.Var3=num2str(app.demoData.p(:,app.On_Democount.sub),'%1.4e');
            end
        end
        
        function Demofunction(app, obj, event, string_arg)
            On_DemoDisplay(app);
            throwimage(app);
            if (mod(app.On_Democount.measure,40)==39)
                Ac_input2motion(app,app.demoData(app.On_Democount.input));
            end
        end
        
        function throwimage(app)
            On_NewFileDetected(app);
        end
        
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            G_InitialPaths(app);
            app.Im_CurrentImages={};
            app.Im_SelectedImage=cell(3,1);


            if(isempty(app.SeDi_TPF_BioFormatsDirectory.Value))
                uialert(app.U_Main,{'Bio-Formats toolbox is not included the working folder.';
                    'You need this toolbox to read nd2 files.';
                    '(Fine with other image types)'},...
                    'Bio-Formats toolbox is not included','Icon','warning');
            end
            app.Ac_ActuationSystem.Ready=false;
            app.DB_debugareaTextArea.Value=app.MeSeCU_MMM_ImageProcessing.Checked;
            At_UpdateState(app,-1);
            On_UpdateState(app,-1);
        end

        % Button pushed function: ImFi_TPB_BrowseFiles
        function ImFi_TPB_BrowseFilesPushed(app, event)
            % Get directory
            [tempfile,tempdir]=uigetfile('*.*');
            % Bring the window back to the front
            figure(app.U_Main);
            
            % Display directory to the "Select Folder" edit field
            if (tempdir==0)
                app.Im_FileDirectory=app.G_InitialWorkingDirectory;
            else
                tempdir(end)=[];
                app.Im_FileDirectory=tempdir;
            end
            app.ImFi_TPL_FileListBox.Enable=true;
            app.ImFi_TPF_AddressField.Value=app.Im_FileDirectory;
            tempdir=dir(app.Im_FileDirectory);
            tempdir=struct2cell(tempdir(~cellfun(@(x) x,{tempdir.isdir})));
            app.ImFi_TPL_FileListBox.Items=tempdir(1,:);
            if (tempfile~=0)
                app.ImFi_TPL_FileListBox.Value=tempfile;
                ImFi_TPL_FileListBoxValueChanged(app);
            end
        end

        % Button pushed function: ImFi_TPB_CopyAddress
        function ImFi_TPB_CopyAddressPushed(app, event)
            if (isempty(app.ImFi_TPF_AddressField.Value))
                uialert(app.U_Main,{'The address field is empty.'},...
                    'Copy address','Icon','warning');
            else
                clipboard('copy',app.ImFi_TPF_AddressField.Value);
                uialert(app.U_Main,{'Current address copied:';app.ImFi_TPF_AddressField.Value},...
                    'Copy address','Icon','success');
            end
        end

        % Value changed function: ImFi_TPL_FileListBox
        function ImFi_TPL_FileListBoxValueChanged(app, event)
            try
                app.Im_CurrentFile=app.ImFi_TPL_FileListBox.Value;
                app.DB_debugareaTextArea.Value=[app.Im_FileDirectory,'\',app.Im_CurrentFile];
                app.Im_SelectedImage{3}=G_ReadImage(app,[app.Im_FileDirectory,'\',app.Im_CurrentFile]);
                if (app.Im_TC_RemoveBackground.Value)
                    try
                        d4=double(app.Im_SelectedImage{4});
                        d3=double(app.Im_SelectedImage{3});
                        d1=d3-d4*sum(d3(:).*d4(:))/sum(sum(d4(:).^2));
                        if (isa(app.Im_SelectedImage{3},'uint8'))
                            app.Im_SelectedImage{1}=uint8(d1);
                        else
                            app.Im_SelectedImage{1}=uint16(d1);
                        end
                    catch
                        uialert(app.U_Main,{'current image size does not match with the background, ',...
                            'stop removing the background.'},...
                            'Size do not match','Icon','warning');
                        app.Im_TC_RemoveBackground.Value=false;
                        app.Im_SelectedImage{1}=app.Im_SelectedImage{3};
                    end
                else
                    app.Im_SelectedImage{1}=app.Im_SelectedImage{3};
                end
                app.Im_SelectedImage{2}=[];
                Im_TC_QuickAdjustValueChanged(app);
                app.ImFi_TPL_FileListBox.Value={};
            catch
            end
        end

        % Value changed function: ImFi_TPF_AddressField
        function ImFi_TPF_AddressFieldValueChanged(app, event)
            tempdir = app.ImFi_TPF_AddressField.Value;
            % Display directory to the "Select Folder" edit field
            if (tempdir==0)
                app.Im_FileDirectory=[app.G_InitialWorkingDirectory,'\'];
            else
                app.Im_FileDirectory=tempdir;
            end
            app.ImFi_TPL_FileListBox.Enable=true;
            app.ImFi_TPF_AddressField.Value=app.Im_FileDirectory;
            
            tempdir=dir(app.Im_FileDirectory);
            tempdir=struct2cell(tempdir(~cellfun(@(x) x,{tempdir.isdir})));
            app.ImFi_TPL_FileListBox.Items=tempdir(1,:);
            app.ImFi_TPL_FileListBox.Value=tempfile;
            ImFi_TPL_FileListBoxValueChanged(app);
        end

        % Value changed function: Im_TC_QuickAdjust
        function Im_TC_QuickAdjustValueChanged(app, event)
            if (app.Im_TC_QuickAdjust.Value)
                if (isempty(app.Im_SelectedImage{2}))
                    if (size( app.Im_SelectedImage{1},3)==3)
                        app.Im_SelectedImage{2}=imadjust(app.Im_SelectedImage{1},...
                            [0,0,0;1,1,1],[]);
                    else
                        app.Im_SelectedImage{2}=imadjust(app.Im_SelectedImage{1});
                    end
                end
                imshow(app.Im_SelectedImage{2},'Parent', app.Im_TG_SelectedImage);
            else
                imshow(app.Im_SelectedImage{1},'Parent', app.Im_TG_SelectedImage);
            end
            title(app.Im_CurrentFile,'Parent', app.Im_TG_SelectedImage);
        end

        % Menu selected function: Fi_MM_SaveImage
        function Fi_MM_SaveImageMenuSelected(app, event)
            imsave(app.Im_TG_SelectedImage);
        end

        % Menu selected function: MeSeCU_MMM_ImageProcessing
        function MeSeCU_MMM_ImageProcessingMenuSelected(app, event)
            if (length(app.MeSeCU_MMM_ImageProcessing.Checked)==3)
                try
                    G_PrintState(app,'Activating CUDA...');
                    app.Im_SelectedImage=cellfun(@gpuArray,app.Im_SelectedImage,'UniformOutput',false);
                    app.MeSeCU_MMM_ImageProcessing.Checked='on';
                    uialert(app.U_Main,{'CUDA is Speeding up the operations.'},...
                        'CUDA being used','Icon','success');
                    G_PrintState(app,'Ready.');
                catch
                    app.MeSeCU_MMM_ImageProcessing.Checked='off';
                    uialert(app.U_Main,{'CUDA is not available.';...
                        'Switched back to the no CUDA setting.'},...
                        'CUDA not available','Icon','warning');
                    G_PrintState(app,'Ready.');
                end
            else
                G_PrintState(app,'Stopping CUDA...');
                app.Im_SelectedImage=cellfun(@gather,app.Im_SelectedImage,'UniformOutput',false);
                app.MeSeCU_MMM_ImageProcessing.Checked='off';
                uialert(app.U_Main,{'Stop using CUDA successfully.'},...
                    'CUDA stop being used','Icon','success');
                G_PrintState(app,'Ready.');
            end
        end

        % Value changed function: ImFi_TPC_TrackNew
        function ImFi_TPC_TrackNewValueChanged(app, event)
            if(app.ImFi_TPC_TrackNew.Value)
                app.Im_FileWatcher.Path=app.Im_FileDirectory;
                app.Im_FileWatcher.EnableRaisingEvents=true;
            else
                app.Im_FileWatcher.EnableRaisingEvents=false;
            end
        end

        % Value changed function: Im_TC_RemoveBackground
        function Im_TC_RemoveBackgroundValueChanged(app, event)
            try
                app.ImFi_TPL_FileListBox.Value=app.Im_CurrentFile;
            catch
            end
            ImFi_TPL_FileListBoxValueChanged(app);
        end

        % Button pushed function: ImBa_TPB_SelectBackground
        function ImBa_TPB_SelectBackgroundButtonPushed(app, event)
            try
                [tempfile,tempdir]=uigetfile(app.Im_FileDirectory,'filter','*.*');
                figure(app.U_Main);
                if (tempdir~=0)
                    app.Im_SelectedImage{4}=G_ReadImage(app,[tempdir,'\',tempfile]);
                    imshow(app.Im_SelectedImage{4},'Parent',app.ImBa_TPG_BackgroundImage);
                end
            catch
                uialert(app.U_Main,{'Cannot read this file as the background.'},...
                    'Invalid file','Icon','warning');
            end
        end

        % Button pushed function: AtFi_TPB_BrowseFile
        function AtFi_TPB_BrowseFileButtonPushed(app, event)
            [tempfile,tempdir]=uigetfile('*.*');
            % Bring the window back to the front
            figure(app.U_Main);
            
            % Display directory to the "Select Folder" edit field
            if (tempdir~=0)
                if (length(app.AtFi_TPG_SegmentedImage.Visible)==3)
                    pos=app.AtFi_TPG_SegmentedImage.Position;
                    r=0.2;
                    app.AtFi_TPG_SegmentedImage.Position=pos*[...
                        1,0,0,0;
                        0,1,0,0;
                        -0.5*r,0,1+2*r,0;
                        0,-2*r,0,1+2*r];
                    app.AtFi_TPG_SegmentedImage.Visible='on';
                end
                tempdir(end)=[];
                app.At_FileDirectory=tempdir;
                app.At_FileWatcher.Path=tempdir;
                try
                    app.AtFi_TPA_CurrentMeasuredData.Data=...
                        G_DrawSegmentedImage(app,app.AtFi_TPG_SegmentedImage,[app.At_FileDirectory,'\',tempfile]);
                    app.At_CurrentFile=tempfile;
                    title({'Selected Image:';app.At_CurrentFile},...
                        'Parent',app.AtFi_TPG_SegmentedImage);
                catch
                end
            end
        end

        % Value changed function: At_TB_StartExperiment
        function At_TB_StartExperimentValueChanged(app, event)
            if (app.At_TB_StartExperiment.Value)
                app.At_FileWatcher.EnableRaisingEvents=True;
                app.At_TI_ExperimentState.Color='r';
                
                % refresh the state diagram
                At_UpdateState(app,0);
                
                At_ResetReferenceTime(app);
                
                % reset the experimental data table
                app.AtEx_TPA_ExperimentalData.Data=table(...
                    {0;app.StepEditField.Value},...
                    {At_NextInput(app);At_NextInput(app)},...
                    {app.At_CurrentImageData.mean;[]},...
                    {app.At_CurrentImageData.std;[]},...
                    'VariableNames',{'time','input','mean','std'});
                
                % initialise the actuation system
                Ac_InitialAllAxes(app);
                if (app.Ac_ActuationSystem.Ready)
                    
                    At_UpdateState(app,1);
                    
                    Ac_input2motion(app,app.AtEx_TPA_ExperimentalData.Data.input{end});
                    % plot the figure
                    At_DisplayExperimentalData(app);
                    
                    At_UpdateState(app,2);
                else
                    app.At_TB_StartExperiment.Value=false;
                    At_TB_StartExperimentValueChanged(app);
                end
            else
                app.At_FileWatcher.EnableRaisingEvents=False;
                app.At_TI_ExperimentState.Color='g';
                At_UpdateState(app,-1);
            end
        end

        % Button pushed function: SeDi_TPB_InitialiseAMIGO
        function SeDi_TPB_InitialiseAMIGOButtonPushed(app, event)
            % try to initialise the AMIGO toolbox
            tempcd=cd;
            try
                % first try
                cd(app.SeDi_TPF_AMIGODirectory.Value);
                AMIGO_Startup;
                uialert(app.U_Main,'AMIGO initlised.',...
                    'AMIGO initlised.','Icon','info');
            catch
                try
                    % if failed, try to remove the old paths, and try again
                    p=regexp(path,';','split')';
                    cellfun(@rmpath,p(contains(p,'AMIGO')))
                    try
                        addpath(app.G_InitialWorkingDirectory);
                        addpath(app.SeDi_TPF_BioFormatsDirectory.Value);
                    catch
                    end
                    cd(app.SeDi_TPF_AMIGODirectory.Value);
                    AMIGO_Startup;
                    uialert(app.U_Main,'AMIGO initlised.',...
                        'AMIGO initlised.','Icon','info');
                catch
                    % if still fail, send an error.
                    uialert(app.U_Main,'Cannot initialise AMIGO with this directory.',...
                        'Cannot Initialise','Icon','error');
                end
            end
            cd(tempcd);
        end

        % Button pushed function: SeDi_TPB_BrowseAMIGO
        function SeDi_TPB_BrowseAMIGOButtonPushed(app, event)
            tempdir=uigetdir([],'AMIGO Directory');
            figure(app.U_Main);
            if (tempdir~=0)
                app.SeDi_TPF_AMIGODirectory.Value=tempdir;
            end
        end

        % Button pushed function: SeDi_TPB_BrowseBioFormats
        function SeDi_TPB_BrowseBioFormatsButtonPushed(app, event)
            tempdir=uigetdir([],'Bio-Formats Directory');
            figure(app.U_Main);
            if (tempdir~=0)
                app.SeDi_TPF_BioFormatsDirectory.Value=tempdir;
            end
        end

        % Button pushed function: OnFi_TPB_BrowseFolder
        function OnFi_TPB_BrowseFolderButtonPushed(app, event)
            tempdir=uigetdir();
            figure(app.U_Main);
            if (tempdir~=0)
                app.On_FileWatcher.Path=tempdir;
                %app.On_FileWatcher.EnableRaisingEvents=true;
            end
        end

        % Value changed function: On_TB_StartExperiment
        function On_TB_StartExperimentValueChanged(app, event)
            if (app.On_TB_StartExperiment.Value)
                On_UpdateState(app,0);
                
                app.On_TI_ExperimentState.Color='r';
                
                
                %Ac_InitialAllAxes(app);
                pause(5);
                
                On_UpdateState(app,1);
                
                %app.On_Democount;%sub 123,input 123,measure 0123
                app.On_Democount.sub=1;
                app.On_Democount.input=1;
                app.On_Democount.measure=-1;
                app.On_DemoPreInput=0;
                
                
                pause(1);
                
                On_UpdateState(app,2);
                
                tic;
                t=0.0001;
                m=0;
                m1=0;
                while t<=45
                    app.On_Democount.sub=ceil((t+0.5)/15);
                    if (app.On_Democount.sub>3)
                        app.On_Democount.sub=3;
                    end
                    app.On_Democount.input=ceil(t/3);
                    if (app.On_Democount.input==ceil(t/3)>15)
                        app.On_Democount.input=15;
                    end
                    app.On_Democount.measure=ceil(t/45*121);
                    On_DemoDisplay(app);
                    if (t<30)
                        m=mod(t,15);
                        if (m>13.5)
                            if (m>14)
                                if(m>14.75)
                                    On_UpdateState(app,2);
                                end
                                On_UpdateState(app,4);
                            end
                            On_UpdateState(app,3);
                        else
                            On_UpdateState(app,2);
                        end
                    else
                        On_UpdateState(app,2);
                    end
                    
                    if (app.On_DemoPreInput~=app.On_Democount.input)
                        app.On_DemoPreInput=app.On_Democount.input;
                        if (app.On_Democount.input==1)
                            pos=app.OnFi_TPG_SegmentedImage.Position;
                            r=0.1;
                            app.OnFi_TPG_SegmentedImage.Position=pos*[...
                                1,0,0,0;
                                0,1,0,0;
                                -0.5*r,0,1+2*r,0;
                                0,-2*r,0,1+2*r];
                            app.OnFi_TPG_SegmentedImage.Visible='on';
                            imshow(app.On_DemoImages{1},'Parent',app.OnFi_TPG_SegmentedImage);
                        else
                            m1=mod(app.On_Democount.input,3)+1;
                            imshow(app.On_DemoImages{m1},'Parent',app.OnFi_TPG_SegmentedImage);
                        end
                    end
                    pause(0.75);
                    t=toc;
                end
                %app.On_Democount;%sub 123,input 123,measure 0123
                xlabel(app.OnEx_TPG_ExperimentDataRecord,'time (min)');
                
                % draw the input values
                yyaxis(app.OnEx_TPG_ExperimentDataRecord,'left');
                app.OnEx_TPG_ExperimentDataRecord.YColor='b';
                %plan
                ylabel(app.OnEx_TPG_ExperimentDataRecord,'Input (\muM)');
                %real
                stairs(app.OnEx_TPG_ExperimentDataRecord,...
                    200*(0:app.On_Democount.input),...
                    app.demoData.u([1:app.On_Democount.input,app.On_Democount.input]),'-b','LineWidth',2.5);
                hold(app.OnEx_TPG_ExperimentDataRecord,'on');
                
                % draw the observable(s)
                yyaxis(app.OnEx_TPG_ExperimentDataRecord,'right');
                app.OnEx_TPG_ExperimentDataRecord.YColor='r';
                ylabel(app.OnEx_TPG_ExperimentDataRecord,'Observables (A.U.)');
                errorbar(app.OnEx_TPG_ExperimentDataRecord,...
                    25*(0:app.On_Democount.measure),...
                    app.demoData.obs(1:app.On_Democount.measure+1),...
                    0.05*app.demoData.obs(1:app.On_Democount.measure+1),'-or','LineWidth',2.5,...
                    'CapSize',10);
                
                On_UpdateState(app,5);
                pause(1);
                On_UpdateState(app,-1);
                app.On_TB_StartExperiment.Value=false;
                app.On_TI_ExperimentState.Color='g';
                On_TB_StartExperimentValueChanged(app);
            else
                app.On_FileWatcher.EnableRaisingEvents=false;
                app.On_TI_ExperimentState.Color='g';
                % On_UpdateState(app,-1);
                %delete('tinput');
                %delete('timage');
            end
        end

        % Value changed function: OnEx_TPS_PEnOEDSlider
        function OnEx_TPS_PEnOEDSliderValueChanged(app, event)
            app.OnEx_TPS_PEnOEDSlider.Value=round(app.OnEx_TPS_PEnOEDSlider.Value);
            app.OnEx_TPF_PEnOEDTime.Value=app.OnEx_TPS_PEnOEDSlider.Value/100*1000;
            
            
            yyaxis(app.OnEx_TPG_ExperimentDataRecord,'right');
            hold(app.OnEx_TPG_ExperimentDataRecord,'off');
            yyaxis(app.OnEx_TPG_ExperimentDataRecord,'left');
            hold(app.OnEx_TPG_ExperimentDataRecord,'off');
            
            xlabel(app.OnEx_TPG_ExperimentDataRecord,'time (min)');
            
            % draw the input values
            yyaxis(app.OnEx_TPG_ExperimentDataRecord,'left');
            %plan
            ylabel(app.OnEx_TPG_ExperimentDataRecord,'Input (\muM)');
            %real
            % draw the observable(s)
            yyaxis(app.OnEx_TPG_ExperimentDataRecord,'right');
            ylabel(app.OnEx_TPG_ExperimentDataRecord,'Observables (A.U.)');
            
            yyaxis(app.OnEx_TPG_ExperimentDataRecord,'left');
            app.OnEx_TPG_ExperimentDataRecord.YColor='b';
            
            
            plot(app.OnEx_TPG_ExperimentDataRecord,[1000,1000],...
                app.OnEx_TPG_ExperimentDataRecord.YLim,'--k','LineWidth',1);
            hold(app.OnEx_TPG_ExperimentDataRecord,'on');
            plot(app.OnEx_TPG_ExperimentDataRecord,[2000,2000],...
                app.OnEx_TPG_ExperimentDataRecord.YLim,'--k','LineWidth',1);
            rectangle(app.OnEx_TPG_ExperimentDataRecord,...
                'Position',[1000-app.OnEx_TPF_PEnOEDTime.Value,0,app.OnEx_TPF_PEnOEDTime.Value,1000],...
                'FaceColor',[0.88,0.88,0.98]);
            rectangle(app.OnEx_TPG_ExperimentDataRecord,...
                'Position',[2000-app.OnEx_TPF_PEnOEDTime.Value,0,app.OnEx_TPF_PEnOEDTime.Value,1000],...
                'FaceColor',[0.88,0.88,0.98]);


            yyaxis(app.OnEx_TPG_ExperimentDataRecord,'right');
            hold(app.OnEx_TPG_ExperimentDataRecord,'off');
            yyaxis(app.OnEx_TPG_ExperimentDataRecord,'left');
            hold(app.OnEx_TPG_ExperimentDataRecord,'off');


        end

        % Size changed function: On_TP_ExperimentalData
        function On_TP_ExperimentalDataSizeChanged(app, event)
            position = app.On_TP_ExperimentalData.Position;
            
        end

        % Value changed function: BrowseSettingFileButton
        function BrowseSettingFileButtonValueChanged(app, event)
            
            % it will less than 100 is AMIGO is not properly loaded. It is more robust than
            % checking the 'amigoversion' variable (The user may clear it but AMIGO is still working)
            %{
            if (sum(contains(regexp(path,';','split')','AMIGO'))<100)
                uialert(app.U_Main,{'You need to initialise AMIGO toolbox before proceed.';...
                    'Please fo to the sattings tab to allocate the toolbox and initialise it.'},...
                    'AMIGO not initialised','Icon','error');
            else
            %}


            [tempfile,tempdir]=uigetfile('*.m');
            % Bring the window back to the front
            figure(app.U_Main);
            
            if (tempdir~=0)
                app.OnFi_TPF_OnlineOEDSettings.Value=[tempdir,tempfile];
                tempdir(end)=[];
                tempfile([end-1;end])=[];
                tempcd=cd;
                try
                    % try loading the setting.
                    cd(tempdir);
                    eval(tempfile);
                    app.demoData.u=u;
                    app.demoData.obs=obs;
                    app.demoData.p=p;
                    app.OnEx_TPF_TotalDurationsec.Value=3000;
                    app.OnEx_TPF_NumLoops.Value=3;
                    app.OnFi_TPL_ODEList.Items=cellstr(inputs.model.eqns);
                    app.OnEx_TPA_ParameterValues.Data=table(...
                        inputs.model.par_names,...
                        num2str(inputs.model.par','%1.4e'),...
                        repmat(' ',inputs.model.n_par,1));
                    app.On_DemoImages=images;
                catch
                    uialert(app.U_Main,'Cannot read this setting file.',...
                        'Invalid setting file','Icon','error');
                end
                cd(tempcd);
                
                xlabel(app.OnEx_TPG_ExperimentDataRecord,'time (min)');
                
                % draw the input values
                yyaxis(app.OnEx_TPG_ExperimentDataRecord,'left');
                %plan
                ylabel(app.OnEx_TPG_ExperimentDataRecord,'Input (\muM)');
                app.OnEx_TPG_ExperimentDataRecord.YColor='b';
                plot(app.OnEx_TPG_ExperimentDataRecord,[1000,1000],...
                    app.OnEx_TPG_ExperimentDataRecord.YLim,'--k','LineWidth',1);
                hold(app.OnEx_TPG_ExperimentDataRecord,'on');
                
                plot(app.OnEx_TPG_ExperimentDataRecord,[2000,2000],...
                    app.OnEx_TPG_ExperimentDataRecord.YLim,'--k','LineWidth',1);
                hold(app.OnEx_TPG_ExperimentDataRecord,'off');
                %real
                % draw the observable(s)
                yyaxis(app.OnEx_TPG_ExperimentDataRecord,'right');
                ylabel(app.OnEx_TPG_ExperimentDataRecord,'Observables (A.U.)');
                app.OnEx_TPG_ExperimentDataRecord.YColor='r';
                xlim(app.OnEx_TPG_ExperimentDataRecord,[0,3000]);
                app.OnEx_TPG_ExperimentDataRecord.Color = 'w';


                hold(app.OnEx_TPG_ExperimentDataRecord,'off');
                yyaxis(app.OnEx_TPG_ExperimentDataRecord,'left');
                
                
                hold(app.OnEx_TPG_ExperimentDataRecord,'off');
            end
            %end
            app.BrowseSettingFileButton.Value=false;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.U_Main = uifigure;
            app.U_Main.Name = 'All-In-One APP';
            app.Me_M_File = uimenu(app.U_Main);
            app.Me_M_File.Text = 'File';
            app.Fi_MM_SaveSettings = uimenu(app.Me_M_File);
            app.Fi_MM_SaveSettings.Text = 'Save Settings';
            app.Fi_MM_LoadSettings = uimenu(app.Me_M_File);
            app.Fi_MM_LoadSettings.Text = 'Load Settings';
            app.Fi_MM_SaveImage = uimenu(app.Me_M_File);
            app.Fi_MM_SaveImage.MenuSelectedFcn = createCallbackFcn(app, @Fi_MM_SaveImageMenuSelected, true);
            app.Fi_MM_SaveImage.Separator = 'on';
            app.Fi_MM_SaveImage.Text = 'Save Image';
            app.Fi_MM_Exit = uimenu(app.Me_M_File);
            app.Fi_MM_Exit.Separator = 'on';
            app.Fi_MM_Exit.Text = 'Exit';
            app.Me_M_SeSettings = uimenu(app.U_Main);
            app.Me_M_SeSettings.Text = 'Settings';
            app.MeSe_MM_CUDAMenu = uimenu(app.Me_M_SeSettings);
            app.MeSe_MM_CUDAMenu.Text = 'CUDA';
            app.MeSeCU_MMM_ImageProcessing = uimenu(app.MeSe_MM_CUDAMenu);
            app.MeSeCU_MMM_ImageProcessing.MenuSelectedFcn = createCallbackFcn(app, @MeSeCU_MMM_ImageProcessingMenuSelected, true);
            app.MeSeCU_MMM_ImageProcessing.Text = 'Image Processing';
            app.MeSeCU_MMM_ParameterEstimation = uimenu(app.MeSe_MM_CUDAMenu);
            app.MeSeCU_MMM_ParameterEstimation.Checked = 'on';
            app.MeSeCU_MMM_ParameterEstimation.Text = 'Parameter Estimation';
            app.MeSeCU_MMM_OED = uimenu(app.MeSe_MM_CUDAMenu);
            app.MeSeCU_MMM_OED.Checked = 'on';
            app.MeSeCU_MMM_OED.Text = 'OED';
            app.Me_M_Help = uimenu(app.U_Main);
            app.Me_M_Help.Text = 'Help';
            app.MeHe_MM_Menu = uimenu(app.Me_M_Help);
            app.MeHe_MM_Menu.Text = 'Menu';
            app.MeHe_MM_About = uimenu(app.Me_M_Help);
            app.MeHe_MM_About.Text = 'About';
            app.TabGroup = uitabgroup(app.U_Main);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.Se_TP_Directory = uipanel(app.SettingsTab);
            app.Se_TP_Directory.Title = 'Directory';
            app.APPDirectoryLabel = uilabel(app.Se_TP_Directory);
            app.APPDirectoryLabel.Text = 'APP Directory:';
            app.SeDi_TPX_APPDirectory = uitextarea(app.Se_TP_Directory);
            app.SeDi_TPX_APPDirectory.Editable = 'off';
            app.SeDi_TPB_CopyAPPDirectory = uibutton(app.Se_TP_Directory, 'push');
            app.SeDi_TPB_CopyAPPDirectory.Text = 'Copy Address';
            app.BioFormatsLabel = uilabel(app.Se_TP_Directory);
            app.BioFormatsLabel.Text = 'Bio-Formats:';
            app.SeDi_TPF_BioFormatsDirectory = uieditfield(app.Se_TP_Directory, 'text');
            app.SeDi_TPB_BrowseBioFormats = uibutton(app.Se_TP_Directory, 'push');
            app.SeDi_TPB_BrowseBioFormats.ButtonPushedFcn = createCallbackFcn(app, @SeDi_TPB_BrowseBioFormatsButtonPushed, true);
            app.SeDi_TPB_BrowseBioFormats.Text = 'Browse Folder';
            app.SeDi_TPB_CopyBioFormats = uibutton(app.Se_TP_Directory, 'push');
            app.SeDi_TPB_CopyBioFormats.Text = 'Copy Address';
            app.SeDi_TPB_InitialiseAMIGO = uibutton(app.Se_TP_Directory, 'push');
            app.SeDi_TPB_InitialiseAMIGO.ButtonPushedFcn = createCallbackFcn(app, @SeDi_TPB_InitialiseAMIGOButtonPushed, true);
            app.SeDi_TPB_InitialiseAMIGO.Text = 'Initialise AMIGO';
            app.SeDi_TPB_BrowseAMIGO = uibutton(app.Se_TP_Directory, 'push');
            app.SeDi_TPB_BrowseAMIGO.ButtonPushedFcn = createCallbackFcn(app, @SeDi_TPB_BrowseAMIGOButtonPushed, true);
            app.SeDi_TPB_BrowseAMIGO.Text = 'Browse Folder';
            app.SeDi_TPB_CopyAMIGO = uibutton(app.Se_TP_Directory, 'push');
            app.SeDi_TPB_CopyAMIGO.Text = 'Copy Address';
            app.AMIGOLabel = uilabel(app.Se_TP_Directory);
            app.AMIGOLabel.Text = 'AMIGO:';
            app.SeDi_TPF_AMIGODirectory = uieditfield(app.Se_TP_Directory, 'text');
            app.OEDTab = uitab(app.TabGroup);
            app.OEDTab.Title = 'OED';
            app.ActuationSystemTab = uitab(app.TabGroup);
            app.ActuationSystemTab.Title = 'Actuation System';
            app.T_ImageProcessing = uitab(app.TabGroup);
            app.T_ImageProcessing.Title = 'Image Processing';
            app.Im_TG_SelectedImage = uiaxes(app.T_ImageProcessing);
            title(app.Im_TG_SelectedImage, 'Current Image')
            app.Im_TP_Files = uipanel(app.T_ImageProcessing);
            app.Im_TP_Files.Title = 'Files';
            app.ImFi_TPB_BrowseFiles = uibutton(app.Im_TP_Files, 'push');
            app.ImFi_TPB_BrowseFiles.ButtonPushedFcn = createCallbackFcn(app, @ImFi_TPB_BrowseFilesPushed, true);
            app.ImFi_TPB_BrowseFiles.Text = 'Browse Files';
            app.ImFi_TPB_CopyAddress = uibutton(app.Im_TP_Files, 'push');
            app.ImFi_TPB_CopyAddress.ButtonPushedFcn = createCallbackFcn(app, @ImFi_TPB_CopyAddressPushed, true);
            app.ImFi_TPB_CopyAddress.Text = 'Copy Address';
            app.ImFi_TPC_TrackNew = uicheckbox(app.Im_TP_Files);
            app.ImFi_TPC_TrackNew.ValueChangedFcn = createCallbackFcn(app, @ImFi_TPC_TrackNewValueChanged, true);
            app.ImFi_TPC_TrackNew.Text = 'Track New Images';
            app.ImFi_TPL_FileListBox = uilistbox(app.Im_TP_Files);
            app.ImFi_TPL_FileListBox.Items = {};
            app.ImFi_TPL_FileListBox.ValueChangedFcn = createCallbackFcn(app, @ImFi_TPL_FileListBoxValueChanged, true);
            app.ImFi_TPL_FileListBox.Enable = 'off';
            app.ImFi_TPL_FileListBox.Value = {};
            app.AddressEditFieldLabel = uilabel(app.Im_TP_Files);
            app.AddressEditFieldLabel.Text = 'Address:';
            app.ImFi_TPF_AddressField = uieditfield(app.Im_TP_Files, 'text');
            app.ImFi_TPF_AddressField.ValueChangedFcn = createCallbackFcn(app, @ImFi_TPF_AddressFieldValueChanged, true);
            app.Im_TC_QuickAdjust = uicheckbox(app.T_ImageProcessing);
            app.Im_TC_QuickAdjust.ValueChangedFcn = createCallbackFcn(app, @Im_TC_QuickAdjustValueChanged, true);
            app.Im_TC_QuickAdjust.Text = 'Quick adjust';
            app.Im_TC_QuickAdjust.Value = true;
            app.debugareaTextAreaLabel = uilabel(app.T_ImageProcessing);
            app.debugareaTextAreaLabel.Text = 'debug area';
            app.DB_debugareaTextArea = uitextarea(app.T_ImageProcessing);
            app.Im_TP_Background = uipanel(app.T_ImageProcessing);
            app.Im_TP_Background.Title = 'Background';
            app.ImBa_TPG_BackgroundImage = uiaxes(app.Im_TP_Background);
            title(app.ImBa_TPG_BackgroundImage, 'Background')
            app.ImBa_TPA_FileTypeTable = uitable(app.Im_TP_Background);
            app.ImBa_TPA_FileTypeTable.ColumnName = {'Type'; 'Key Word'};
            app.ImBa_TPA_FileTypeTable.RowName = {};
            app.ImBa_TPA_FileTypeTable.ColumnEditable = true;
            app.ImBa_TPB_SelectBackground = uibutton(app.Im_TP_Background, 'push');
            app.ImBa_TPB_SelectBackground.ButtonPushedFcn = createCallbackFcn(app, @ImBa_TPB_SelectBackgroundButtonPushed, true);
            app.ImBa_TPB_SelectBackground.Text = 'Select Background';
            app.ImBa_TPB_AddType = uibutton(app.Im_TP_Background, 'push');
            app.ImBa_TPB_AddType.Text = 'Add';
            app.ImBa_TPB_DeleteType = uibutton(app.Im_TP_Background, 'push');
            app.ImBa_TPB_DeleteType.Text = 'Delete';
            app.Im_TC_RemoveBackground = uicheckbox(app.T_ImageProcessing);
            app.Im_TC_RemoveBackground.ValueChangedFcn = createCallbackFcn(app, @Im_TC_RemoveBackgroundValueChanged, true);
            app.Im_TC_RemoveBackground.Text = 'Remove Background';
            app.ParameterEstimationTab = uitab(app.TabGroup);
            app.ParameterEstimationTab.Title = 'Parameter Estimation';
            app.T_AutomaticInferenceTab = uitab(app.TabGroup);
            app.T_AutomaticInferenceTab.Title = 'Automatic Inference';
            app.At_TP_Files = uipanel(app.T_AutomaticInferenceTab);
            app.At_TP_Files.Title = 'Files';
            app.AtFi_TPB_BrowseFile = uibutton(app.At_TP_Files, 'push');
            app.AtFi_TPB_BrowseFile.ButtonPushedFcn = createCallbackFcn(app, @AtFi_TPB_BrowseFileButtonPushed, true);
            app.AtFi_TPB_BrowseFile.Text = 'Browse Initial Image';
            app.AtFi_TPG_SegmentedImage = uiaxes(app.At_TP_Files);
            title(app.AtFi_TPG_SegmentedImage, 'Segmentation')
            app.AtFi_TPG_SegmentedImage.Visible = 'off';
            app.AtFi_TPA_CurrentMeasuredData = uitable(app.At_TP_Files);
            app.AtFi_TPA_CurrentMeasuredData.ColumnName = {'Name'; 'Value'};
            app.AtFi_TPA_CurrentMeasuredData.RowName = {};
            app.At_TP_ExperimentalData = uipanel(app.T_AutomaticInferenceTab);
            app.At_TP_ExperimentalData.Title = 'Experimental Data';
            app.AtEx_TPG_ExperimentDataRecord = uiaxes(app.At_TP_ExperimentalData);
            title(app.AtEx_TPG_ExperimentDataRecord, 'Experimental Data')
            xlabel(app.AtEx_TPG_ExperimentDataRecord, 'Time')
            ylabel(app.AtEx_TPG_ExperimentDataRecord, 'Value')
            app.ThresholdEditFieldLabel = uilabel(app.At_TP_ExperimentalData);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.At_TP_ExperimentalData, 'numeric');
            app.ThresholdEditField.Limits = [0 Inf];
            app.ThresholdEditField.Value = 260000;
            app.StepEditFieldLabel = uilabel(app.At_TP_ExperimentalData);
            app.StepEditFieldLabel.Text = 'Step';
            app.StepEditField = uieditfield(app.At_TP_ExperimentalData, 'numeric');
            app.StepEditField.Limits = [0 Inf];
            app.StepEditField.Value = 5;
            app.AtEx_TPA_ExperimentalData = uitable(app.At_TP_ExperimentalData);
            app.AtEx_TPA_ExperimentalData.ColumnName = {'time (s)'; 'u'; 'y (mean)'; 'y (STD)'};
            app.AtEx_TPA_ExperimentalData.RowName = {};
            app.At_TB_StartExperiment = uibutton(app.T_AutomaticInferenceTab, 'state');
            app.At_TB_StartExperiment.ValueChangedFcn = createCallbackFcn(app, @At_TB_StartExperimentValueChanged, true);
            app.At_TB_StartExperiment.Text = 'Start the Experiment';
            app.At_TI_ExperimentState = uilamp(app.T_AutomaticInferenceTab);
            app.At_TG_StateDiagram = uiaxes(app.T_AutomaticInferenceTab);
            app.At_TG_StateDiagram.XAxisLocation = 'origin';
            app.At_TL_StateList = uilistbox(app.T_AutomaticInferenceTab);
            app.At_TL_StateList.Items = {'S0: Initialisation', 'S1: Moving Syringers', 'S2: Waiting for new Images', 'S3: Image Processing', 'S4: Calculating the next input'};
            app.At_TL_StateList.Value = 'S0: Initialisation';
            app.T_OnLineOEDTab = uitab(app.TabGroup);
            app.T_OnLineOEDTab.Title = 'On-Line OED';
            app.On_TP_Files_2 = uipanel(app.T_OnLineOEDTab);
            app.On_TP_Files_2.Title = 'Files';
            app.OnFi_TPB_BrowseFolder = uibutton(app.On_TP_Files_2, 'push');
            app.OnFi_TPB_BrowseFolder.ButtonPushedFcn = createCallbackFcn(app, @OnFi_TPB_BrowseFolderButtonPushed, true);
            app.OnFi_TPB_BrowseFolder.Text = 'Browse Image Folder';
            app.OnFi_TPG_SegmentedImage = uiaxes(app.On_TP_Files_2);
            title(app.OnFi_TPG_SegmentedImage, 'Segmentation Image')
            app.SettingFileLabel = uilabel(app.On_TP_Files_2);
            app.SettingFileLabel.Text = 'Setting File';
            app.OnFi_TPF_OnlineOEDSettings = uieditfield(app.On_TP_Files_2, 'text');
            app.OnFi_TPF_OnlineOEDSettings.Editable = 'off';
            app.OnFi_TPL_ODEList = uilistbox(app.On_TP_Files_2);
            app.OnFi_TPL_ODEList.Items = {};
            app.OnFi_TPL_ODEList.Value = {};
            app.ModelDifferentialEquationsLabel = uilabel(app.On_TP_Files_2);
            app.ModelDifferentialEquationsLabel.Text = 'Model Differential Equations';
            app.BrowseSettingFileButton = uibutton(app.On_TP_Files_2, 'state');
            app.BrowseSettingFileButton.ValueChangedFcn = createCallbackFcn(app, @BrowseSettingFileButtonValueChanged, true);
            app.BrowseSettingFileButton.Text = 'Browse Setting File';
            app.On_TP_ExperimentalData = uipanel(app.T_OnLineOEDTab);
            app.On_TP_ExperimentalData.Title = 'Experimental Data';
            app.On_TP_ExperimentalData.SizeChangedFcn = createCallbackFcn(app, @On_TP_ExperimentalDataSizeChanged, true);
            app.OnEx_TPG_ExperimentDataRecord = uiaxes(app.On_TP_ExperimentalData);
            title(app.OnEx_TPG_ExperimentDataRecord, 'Experimental Data')
            xlabel(app.OnEx_TPG_ExperimentDataRecord, 'Time')
            ylabel(app.OnEx_TPG_ExperimentDataRecord, 'Value')
            app.TotalDurationminLabel = uilabel(app.On_TP_ExperimentalData);
            app.TotalDurationminLabel.Text = 'Total Duration (min)';
            app.OnEx_TPF_TotalDurationsec = uieditfield(app.On_TP_ExperimentalData, 'numeric');
            app.OnEx_TPF_TotalDurationsec.Limits = [0 Inf];
            app.OnEx_TPF_TotalDurationsec.Editable = 'off';
            app.OnEx_TPF_TotalDurationsec.Value = 10;
            app.OnEx_TPA_ParameterValues = uitable(app.On_TP_ExperimentalData);
            app.OnEx_TPA_ParameterValues.ColumnName = {'Name'; 'Previous Estimate'; 'Current Estimate'};
            app.OnEx_TPA_ParameterValues.RowName = {};
            app.OnEx_TPF_PEnOEDTime = uieditfield(app.On_TP_ExperimentalData, 'numeric');
            app.OnEx_TPF_PEnOEDTime.Limits = [0 Inf];
            app.PEOEDSliderLabel = uilabel(app.On_TP_ExperimentalData);
            app.PEOEDSliderLabel.Text = 'PE+OED';
            app.OnEx_TPS_PEnOEDSlider = uislider(app.On_TP_ExperimentalData);
            app.OnEx_TPS_PEnOEDSlider.ValueChangedFcn = createCallbackFcn(app, @OnEx_TPS_PEnOEDSliderValueChanged, true);
            app.NumberofLoopsLabel = uilabel(app.On_TP_ExperimentalData);
            app.NumberofLoopsLabel.Text = 'Number of Loops';
            app.OnEx_TPF_NumLoops = uieditfield(app.On_TP_ExperimentalData, 'numeric');
            app.OnEx_TPF_NumLoops.Limits = [0 Inf];
            app.OnEx_TPF_NumLoops.Editable = 'off';
            app.OnEx_TPF_NumLoops.Value = 1;
            app.On_TG_StateDiagram = uiaxes(app.On_TP_ExperimentalData);
            app.On_TG_StateDiagram.XAxisLocation = 'origin';
            app.On_TL_StateList = uilistbox(app.On_TP_ExperimentalData);
            app.On_TL_StateList.Items = {'S0: Initialisation', 'S1: Initial OED', 'S2: Experiment', 'S3: Experiment + PE', 'S4: Experiment + OED', 'S5: Final PE'};
            app.On_TL_StateList.Value = 'S0: Initialisation';
            app.On_TB_StartExperiment = uibutton(app.On_TP_ExperimentalData, 'state');
            app.On_TB_StartExperiment.ValueChangedFcn = createCallbackFcn(app, @On_TB_StartExperimentValueChanged, true);
            app.On_TB_StartExperiment.Text = 'Start Experiment';
            app.On_TI_ExperimentState = uilamp(app.On_TP_ExperimentalData);
            app.OnEx_TPX_UnitLabel = uilabel(app.On_TP_ExperimentalData);
            app.OnEx_TPX_UnitLabel.Text = 'min';
            app.DropDownLabel = uilabel(app.T_OnLineOEDTab);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.T_OnLineOEDTab);
            app.X_StateText = uitextarea(app.U_Main);
            app.X_StateText.Editable = 'off';
            app.X_StateText.Value = {'Ready.'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = APP_V4_0Demo

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.U_Main)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.U_Main)
        end
    end
end