classdef ClosedLoop_V1_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        U_Main                          matlab.ui.Figure
        Me_M_File                       matlab.ui.container.Menu
        Fi_MM_SaveSettings              matlab.ui.container.Menu
        Fi_MM_LoadSettings              matlab.ui.container.Menu
        Fi_MM_SaveImage                 matlab.ui.container.Menu
        Fi_MM_Exit                      matlab.ui.container.Menu
        Me_M_SeSettings                 matlab.ui.container.Menu
        MeSe_MM_CUDAMenu                matlab.ui.container.Menu
        MeSeCU_MMM_ImageProcessing      matlab.ui.container.Menu
        MeSeCU_MMM_ParameterEstimation  matlab.ui.container.Menu
        MeSeCU_MMM_OED                  matlab.ui.container.Menu
        Me_M_Help                       matlab.ui.container.Menu
        MeHe_MM_Menu                    matlab.ui.container.Menu
        MeHe_MM_About                   matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        SettingsTab                     matlab.ui.container.Tab
        Se_TP_Directory                 matlab.ui.container.Panel
        APPDirectoryTextAreaLabel       matlab.ui.control.Label
        Se_TPX_APPDirectory             matlab.ui.control.TextArea
        AMIGOLabel                      matlab.ui.control.Label
        Se_TPF_Se_AMIGODirectory        matlab.ui.control.EditField
        ImFi_TPB_CopyAddress_2          matlab.ui.control.Button
        ImFi_TPB_BrowseFiles_2          matlab.ui.control.Button
        ImFi_TPB_CopyAddress_3          matlab.ui.control.Button
        BioFormatsLabel                 matlab.ui.control.Label
        ImFi_TPF_AddressField_3         matlab.ui.control.EditField
        ImFi_TPB_BrowseFiles_3          matlab.ui.control.Button
        ImFi_TPB_CopyAddress_4          matlab.ui.control.Button
        OEDTab                          matlab.ui.container.Tab
        ActuationSystemTab              matlab.ui.container.Tab
        T_ImageProcessing               matlab.ui.container.Tab
        Im_TG_SelectedImage             matlab.ui.control.UIAxes
        Im_TP_Files                     matlab.ui.container.Panel
        ImFi_TPB_BrowseFiles            matlab.ui.control.Button
        ImFi_TPB_CopyAddress            matlab.ui.control.Button
        ImFi_TPC_TrackNew               matlab.ui.control.CheckBox
        ImFi_TPL_FileListBox            matlab.ui.control.ListBox
        AddressEditFieldLabel           matlab.ui.control.Label
        ImFi_TPF_AddressField           matlab.ui.control.EditField
        Im_TC_QuickAdjust               matlab.ui.control.CheckBox
        debugareaTextAreaLabel          matlab.ui.control.Label
        DB_debugareaTextArea            matlab.ui.control.TextArea
        Im_TP_Background                matlab.ui.container.Panel
        ImBa_TPG_BackgroundImage        matlab.ui.control.UIAxes
        ImBa_TPA_FileTypeTable          matlab.ui.control.Table
        ImBa_TPB_SelectBackground       matlab.ui.control.Button
        ImBa_TPB_AddType                matlab.ui.control.Button
        ImBa_TPB_DeleteType             matlab.ui.control.Button
        Im_TC_RemoveBackground          matlab.ui.control.CheckBox
        ParameterEstimationTab          matlab.ui.container.Tab
        FilesPanel_2                    matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        AutomaticInferenceTab           matlab.ui.container.Tab
        At_TP_Files                     matlab.ui.container.Panel
        AtFi_TPB_BrowseFiles            matlab.ui.control.Button
        AtFi_TPB_CopyAddress            matlab.ui.control.Button
        AddressEditFieldLabel_2         matlab.ui.control.Label
        AtFi_TPF_AddressField           matlab.ui.control.EditField
        AtFi_TPA_FileTable              matlab.ui.control.Table
        At_TP_ExperimentalData          matlab.ui.container.Panel
        AtEx_TPG_ExperimentDataRecord   matlab.ui.control.UIAxes
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        StepEditFieldLabel              matlab.ui.control.Label
        StepEditField                   matlab.ui.control.NumericEditField
        AtEx_TPA_ExperimentalData       matlab.ui.control.Table
        At_TG_CurrentImage              matlab.ui.control.UIAxes
        At_TA_CurrentMeasuredData       matlab.ui.control.Table
        At_TB_StartExperiment           matlab.ui.control.StateButton
        At_TI_ExperimentState           matlab.ui.control.Lamp
        At_TC_QuickAdjust               matlab.ui.control.CheckBox
        At_TG_SegmentedImage            matlab.ui.control.UIAxes
        AutomaticInference2Tab          matlab.ui.container.Tab
        At_TP_Files_2                   matlab.ui.container.Panel
        AtFi_TPB_BrowseFiles_2          matlab.ui.control.Button
        AtFi_TPB_CopyAddress_2          matlab.ui.control.Button
        AddressEditFieldLabel_3         matlab.ui.control.Label
        AtFi_TPF_AddressField_2         matlab.ui.control.EditField
        AtFi_TPA_FileTable_2            matlab.ui.control.Table
        At_TP_ExperimentalData_2        matlab.ui.container.Panel
        AtEx_TPG_ExperimentDataRecord_2  matlab.ui.control.UIAxes
        ThresholdEditField_2Label       matlab.ui.control.Label
        ThresholdEditField_2            matlab.ui.control.NumericEditField
        StepEditField_2Label            matlab.ui.control.Label
        StepEditField_2                 matlab.ui.control.NumericEditField
        AtEx_TPA_ExperimentalData_2     matlab.ui.control.Table
        At_TG_CurrentImage_2            matlab.ui.control.UIAxes
        At_TA_CurrentMeasuredData_2     matlab.ui.control.Table
        At_TB_StartExperiment_2         matlab.ui.control.StateButton
        At_TI_ExperimentState_2         matlab.ui.control.Lamp
        At_TC_QuickAdjust_2             matlab.ui.control.CheckBox
        At_TG_SegmentedImage_2          matlab.ui.control.UIAxes
        X_StateText                     matlab.ui.control.TextArea
    end

    properties (Access = private)
        FileDirectory               % directory of image files
        CurrentFile                 % the file under editing
        InitialWorkingDirectory     % working directory
        CurrentImages               % A cell array storing all the images we are currently working on
        % by default,
        % 1st - raw/BGremoved (depends on the setting),
        % 2nd - adjusted of 1st,
        % 3rd - raw
        % 4th - Background
        % nth - further operations
        Im_SelectedImage         % temporary image, image selection only
        Im_FileWatcher              % watching the state of the working folder, System.IO.FileSystemWatcher
        At_FileDirectory            % the file directory for automatic inference
        At_FileWatcher              % watching the state of the working folder
        At_CurrentFile              % name of the current image file for automatic inference
        At_CurrentImage             % the current input image for automatic inference
        At_ReferenceTime            % The time whe the experiment is started
        At_CurrentImageData         % Data of the current image
        Ac_ActuationSystem             % details of the actuation system:
        % BaudRate, modbus, COMPort, Axis_Number, Preset_Speed
    end
    
    methods (Access = private)
        
        function image = G_ReadImage(app,fullFileName)
            % use the toolbox Bio-Formats 5.9.1. and the script I wrote ('bfReadImage.m') to load the image
            tempcd=cd;
            cd(app.InitialWorkingDirectory);
            try
                try
                    cd('bfmatlab');
                catch
                end
                [~,image]=evalc(['bfopen(''',fullFileName,''')']);
                image=image{1}{1};
            catch
                image=imread(fullFileName);
            end
            if (length(app.MeSeCU_MMM_ImageProcessing.Checked)==2) % if Use CUDA is on
                image=gpuArray(image);
            end
            cd(tempcd);
        end
        
        
        function G_PrintState(app,text)
            app.X_StateText.Value=text;
        end
        
        function Im_NewFileDetected(app,~,arg)
            tempdir=dir(app.FileDirectory);
            tempdir=struct2cell(tempdir(~cellfun(@(x) x,{tempdir.isdir})));
            app.ImFi_TPL_FileListBox.Items=tempdir(1,:);
            try
                app.ImFi_TPL_FileListBox.Value=char(arg.Name);
                ImFi_TPL_FileListBoxValueChanged(app);
                title({'New file detected:';app.CurrentFile},'Parent', app.Im_TG_SelectedImage);
                app.ImFi_TPL_FileListBox.Value=string(arg.Name);
            catch
            end
            G_PrintState(app,string(arg.Name));
        end
        
        
        function At_NewFileDetected(app,~,arg)
            % add the new file to the file list
            app.AtFi_TPA_FileTable.Data=[{char(arg.Name),char(datetime-app.At_ReferenceTime)};...
                app.AtFi_TPA_FileTable.Data];
            try
                app.At_CurrentFile=char(arg.Name);
                app.At_CurrentImage=G_ReadImage(app,[app.At_FileDirectory,'\',app.At_CurrentFile]);
                At_TC_QuickAdjustValueChanged(app);
                title({['New image added at: ',char(app.AtFi_TPA_FileTable.Data.Time{1})];char(arg.Name)},...
                    'Parent',app.At_TG_CurrentImage);
                
                if (app.At_TB_StartExperiment.Value)
                    % add the new experimental data
                    t=regexp(app.AtFi_TPA_FileTable.Data.Time{1},':','split');
                    app.AtEx_TPA_ExperimentalData.Data.time{end}=cellfun(@str2num,t)*[3600;60;1];
                    app.AtEx_TPA_ExperimentalData.Data.input{end}=At_NextInput(app);
                    app.AtEx_TPA_ExperimentalData.Data.mean{end}=app.At_CurrentImageData.mean;
                    app.AtEx_TPA_ExperimentalData.Data.std{end}=app.At_CurrentImageData.std;
                    app.AtEx_TPA_ExperimentalData.Data=[app.AtEx_TPA_ExperimentalData.Data;...
                        {app.AtEx_TPA_ExperimentalData.Data.time{end}+app.StepEditField.Value,...
                        app.AtEx_TPA_ExperimentalData.Data.input{end},...
                        [],[]}];
                    At_DisplayExperimentalData(app);
                    Ac_input2motion(app,At_NextInput(app));
                end
            catch
            end
        end
        
        function At_ResetReferenceTime(app)
            app.At_ReferenceTime=datetime;
        end
        
        function value = At_NextInput(app)
            if (app.At_CurrentImageData.mean>app.ThresholdEditField.Value)
                value=1;
            else
                value=0;
            end
        end
        
        
        function Ac_InitialAllAxes(app)
            try
                % Connect to actuators
                % Set it to the value the actuators is using
                app.Ac_ActuationSystem.BaudRate = 38400;
                app.Ac_ActuationSystem.COMPort = 'COM5';
                app.Ac_ActuationSystem.AxisNumber=2;
                app.Ac_ActuationSystem.MaxSpeed=120*100;
                % Create MODBUS object for actuators and connect to it
                app.Ac_ActuationSystem.modbus=modbus(...
                    'serialrtu',app.Ac_ActuationSystem.COMPort,...
                    'BaudRate',app.Ac_ActuationSystem.BaudRate);
                indexList=1:app.Ac_ActuationSystem.AxisNumber;
                
                % Set Control Bit ON (Enable MODBUS control) for all axis
                for i=indexList
                    write(app.Ac_ActuationSystem.modbus,'coils',1064,1,i);
                end
                pause(0.2); % Actuators need some time between the rising edges, refer to IAI manual
                
                % Reset Alarms, in case any alarm were present. So that subsequent operations can be performed
                for i=indexList
                    Ac_Alarm_Reset(app,i);
                end
                pause(0.2); % Actuators need some time between the rising edges, refer to IAI manual
                
                % Turn on the Servo for all axis
                for i=indexList
                    write(app.Ac_ActuationSystem.modbus,'coils',1028,1,i);
                    pause(0.2); % Give it a gap between servo turn on
                end
                pause(0.5); % Actuators need some time between the rising edges, refer to IAI manual
                
                % Home return all axis
                for i=indexList
                    Ac_Home_Return(app,i);
                end
                
                % check home return status
                HEND=false(1,app.Ac_ActuationSystem.AxisNumber);
                while (true)
                    for i=indexList
                        % read home return status from all axis
                        HEND(i)=read(app.Ac_ActuationSystem.modbus,'coils',268,1,i);
                    end
                    
                    % pause 0.3s between each read cycle. Also after the last cycle
                    pause(0.3);
                    
                    %jump out of the loop once all axis have homed
                    if (all(HEND))
                        break;
                    end
                end
                % set up Preset speed
                for i=indexList
                    Ac_Write_Speed(app,app.Ac_ActuationSystem.MaxSpeed,i);
                end
                app.Ac_ActuationSystem.Ready=true;
            catch
                uialert(app.U_Main,'The device is not connected!','Cannot Initialize');
            end
        end
        
        % This function write to the Alarm Reset coil, refer to manual.
        % 1 input (Axis)
        % This function writes to this coil, 0, 1, 0 sequentially, to create an
        % rising edge that reset the alarm.
        function Ac_Alarm_Reset(app,Axis)
            % Alarm Reset for given axis
            write(app.Ac_ActuationSystem.modbus,'coils',1032,0,Axis);
            write(app.Ac_ActuationSystem.modbus,'coils',1032,1,Axis);
            write(app.Ac_ActuationSystem.modbus,'coils',1032,0,Axis);
        end
        
        % This function write to the Home Return coil, refer to manual.
        % 1 input (Axis)
        % This function writes to this coil, 0, 1, 0 sequentially, to create an
        % rising edge that homes the axis.
        function Ac_Home_Return(app,Axis)
            % Home given axis
            write(app.Ac_ActuationSystem.modbus,'coils',1036,0,Axis);
            write(app.Ac_ActuationSystem.modbus,'coils',1036,1,Axis);
            write(app.Ac_ActuationSystem.modbus,'coils',1036,0,Axis);
        end
        
        % This function write to the "Speed Specification Register (VCMD)"
        % refer to manual.
        % Input the speed in a single decimal value unit 0.01mm/s. And Axis number
        function Ac_Write_Speed(app,Speed,Axis_Number)
            % Convert the input speed value to 2 values so as to write in 2 16-bit
            % registers
            [Speed_reg_Hi , Speed_reg_Lo] = Ac_dec2regs2(app,Speed);
            
            % write the data to registers for given axis number
            write(app.Ac_ActuationSystem.modbus,'holdingregs',39173,...
                [Speed_reg_Hi,Speed_reg_Lo],Axis_Number);
            
        end
        
        % This function convert a single decimal value to two separate decimal
        % values, so as to store in 2 16-bits registers.
        % 1 input, 2 output, both in decimal.
        function [reg_Hi,reg_Lo]=Ac_dec2regs2(app,Original)
            
            Original_Hex = dec2hex(Original,8); % Convert decimal input a into hex string with 8 digits
            
            reg_Hi_Hex = Original_Hex(1:4); % Hi register equals to the first 4 digit of Original_Hex
            reg_Hi = hex2dec(reg_Hi_Hex); % Convert hex value back to decimal for Hi reg
            
            reg_Lo_Hex = Original_Hex(5:8); % Lo register equals to the last 4 digit of Original_Hex
            reg_Lo = hex2dec(reg_Lo_Hex); % Convert hex value back to decimal for Lo reg
            
        end
        
        % This function convert two decimal values stored separately in 2 16-bit
        % registers to a single decimal value
        % 2 input, 1 output, both in decimal.
        function Decimal_Value=Ac_regs2_2dec(app,reg_Hi,reg_Lo)
            reg_Hi_Hex = dec2hex(reg_Hi); % Convert the Hi register input into Hex value
            reg_Hi_Hex = pad(reg_Hi_Hex,4,'left','0'); % Pad zeros in front of Hex value to form 4 digit
            
            reg_Lo_Hex = dec2hex(reg_Lo); % Convert the Lo register input into Hex value
            reg_Lo_Hex = pad(reg_Lo_Hex,4,'left','0'); % Pad zeros in front of Hex value to form 4 digit
            
            % Combine reg_Hi_Hex, reg_Lo_Hex together to form a single Hex value
            Decimal_Value_Hex = [reg_Hi_Hex,reg_Lo_Hex];
            
            % Convert the final Hex value to decimal
            Decimal_Value = hex2dec(Decimal_Value_Hex);
        end
        
        % This function write to the "Target Position Specification (PCMD)"
        % registers, refer to manual.
        % Input the target position in a single decimal value, and axis number actuators will start to move
        % immediately after the execution of this funciton.
        % the position (height) is in mm.
        function Ac_WriteTargetPosition(app,Target_Position,Axis_Number)
            % Convert the input target position value to 2 values so as to write in 2 16-bit
            % registers
            [Target_Position_reg_Hi , Target_Position_reg_Lo] = Ac_dec2regs2(app,Target_Position);
            
            % write the data to registers for given axis number
            write(app.Ac_ActuationSystem.modbus,'holdingregs',39169,[Target_Position_reg_Hi,Target_Position_reg_Lo],Axis_Number);
        end
        
        % This function read the "Current Position (PNOW)"
        % registers, refer to manual.
        % Input the axis number. Output is a single decimal value unit 0.01mm.
        %
        % A correction method was added because sometimes when at home position (0mm),
        % Current_Position would be read as 400k+ (some very large number)
        % If Current_Position falls out resonable range Current_Position=0
        function Current_Position=Ac_ReadCurrentPosition(app,Axis_Number)
            % Read current position from PNOW registers into two decimal values, for
            % given axis number
            Position = read(app.Ac_ActuationSystem.modbus,'holdingregs',36865,2,Axis_Number);
            
            % Convert 2 decimal values into 1 single decimal value
            Current_Position=Ac_regs2_2dec(app,Position(1),Position(2));
            
            % This was added because sometimes when at home position (0mm),
            % Current_Position would be read as 400k+ (some very large number)
            % If Current_Position falls out resonable range Current_Position=0
            if ((Current_Position>200000) || (Current_Position<0))
                Current_Position=0;
            end
        end
        
        % This function will move the syringers according to the imput design.
        % later this will cope with the calibration module.
        function Ac_input2motion(app,input)
            height=[input*10000;(1-input)*10000];
            for ind=1:2
                try
                    Write_Target_Position(app,height(ind),ind);
                catch
                end
            end
        end
        
        function At_DisplayExperimentalData(app)
            xlabel(app.AtEx_TPG_ExperimentDataRecord,'time (s)');
            % draw the input values
            yyaxis(app.AtEx_TPG_ExperimentDataRecord,'left');
            ylabel(app.AtEx_TPG_ExperimentDataRecord,'Input');
            stairs(app.AtEx_TPG_ExperimentDataRecord,...
                cell2mat(app.AtEx_TPA_ExperimentalData.Data.time),...
                cell2mat(app.AtEx_TPA_ExperimentalData.Data.input));
            % draw the observable(s)
            yyaxis(app.AtEx_TPG_ExperimentDataRecord,'right');
            ylabel(app.AtEx_TPG_ExperimentDataRecord,'Observables (A.U.)');
            errorbar(app.AtEx_TPG_ExperimentDataRecord,...
                cell2mat(app.AtEx_TPA_ExperimentalData.Data.time(1:end-1)),...
                cell2mat(app.AtEx_TPA_ExperimentalData.Data.mean(1:end-1)),...
                cell2mat(app.AtEx_TPA_ExperimentalData.Data.std(1:end-1)),'o');
        end
        
        function G_InitialPaths(app)
            app.InitialWorkingDirectory=cd;
            app.Se_TPX_APPDirectory.Value=app.InitialWorkingDirectory;
            p=regexp(path,';','split')';
            AMIGOIndex=contains(p,'AMIGO');

            if (sum(AMIGOIndex)>100)
                pAMIGO=p(AMIGOIndex);
                [~,id]=min(cellfun(@length,pAMIGO));
                app.Se_TPF_Se_AMIGODirectory.Value=pAMIGO{id};
            end
            
            try
                cd('bfmatlab')  
            catch
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.InitialWorkingDirectory=cd;
            app.FileDirectory=app.InitialWorkingDirectory;
            app.CurrentImages={};
            app.Im_SelectedImage=cell(3,1);
            app.Im_FileWatcher=System.IO.FileSystemWatcher(app.InitialWorkingDirectory);
            app.Im_FileWatcher.EnableRaisingEvents=false;
            app.At_FileWatcher=app.Im_FileWatcher;
            addlistener(app.Im_FileWatcher,'Created',@app.Im_NewFileDetected);
            addlistener(app.At_FileWatcher,'Created',@app.At_NewFileDetected);
            if(~exist('bfmatlab','dir'))
                uialert(app.U_Main,{'Bio-Formats toolbox is not included the working folder.';...
                    'You need this toolbox to read nd2 files.';...
                    '(Fine with other image types)'},...
                    'Bio-Formats toolbox is not included','Icon','warning');
            end
            app.Ac_ActuationSystem.Ready=false;            
            app.DB_debugareaTextArea.Value=app.MeSeCU_MMM_ImageProcessing.Checked;           
        end

        % Button pushed function: ImFi_TPB_BrowseFiles
        function ImFi_TPB_BrowseFilesPushed(app, event)
            % Get directory
            [tempfile,tempdir]=uigetfile('*.*');
            % Bring the window back to the front
            figure(app.U_Main);
            
            % Display directory to the "Select Folder" edit field
            if (tempdir==0)
                app.FileDirectory=app.InitialWorkingDirectory;
            else
                tempdir(end)=[];
                app.FileDirectory=tempdir;
            end
            app.ImFi_TPL_FileListBox.Enable=true;
            app.ImFi_TPF_AddressField.Value=app.FileDirectory;
            tempdir=dir(app.FileDirectory);
            tempdir=struct2cell(tempdir(~cellfun(@(x) x,{tempdir.isdir})));
            app.ImFi_TPL_FileListBox.Items=tempdir(1,:);
            if (tempfile~=0)
                app.ImFi_TPL_FileListBox.Value=tempfile;
                ImFi_TPL_FileListBoxValueChanged(app);
            end
        end

        % Button pushed function: ImFi_TPB_CopyAddress
        function ImFi_TPB_CopyAddressPushed(app, event)
            if (isempty(app.ImFi_TPF_AddressField.Value))
                uialert(app.U_Main,{'The address field is empty.'},...
                    'Copy address','Icon','warning');
            else
                clipboard('copy',app.ImFi_TPF_AddressField.Value);
                uialert(app.U_Main,{'Current address copied:';app.ImFi_TPF_AddressField.Value},...
                    'Copy address','Icon','success');
            end
        end

        % Value changed function: ImFi_TPL_FileListBox
        function ImFi_TPL_FileListBoxValueChanged(app, event)
            try
                app.CurrentFile=app.ImFi_TPL_FileListBox.Value;
                app.DB_debugareaTextArea.Value=[app.FileDirectory,'\',app.CurrentFile];
                app.Im_SelectedImage{3}=G_ReadImage(app,[app.FileDirectory,'\',app.CurrentFile]);
                if (app.Im_TC_RemoveBackground.Value)
                    try
                        d4=double(app.Im_SelectedImage{4});
                        d3=double(app.Im_SelectedImage{3});
                        d1=d3-d4*sum(d3(:).*d4(:))/sum(sum(d4(:).^2));
                        if (isa(app.Im_SelectedImage{3},'uint8'))
                            app.Im_SelectedImage{1}=uint8(d1);
                        else
                            app.Im_SelectedImage{1}=uint16(d1);
                        end
                    catch
                        uialert(app.U_Main,{'current image size does not match with the background, ',...
                            'stop removing the background.'},...
                            'Size do not match','Icon','warning');
                        app.Im_TC_RemoveBackground.Value=false;
                        app.Im_SelectedImage{1}=app.Im_SelectedImage{3};
                    end
                else
                    app.Im_SelectedImage{1}=app.Im_SelectedImage{3};
                end
                app.Im_SelectedImage{2}=[];
                Im_TC_QuickAdjustValueChanged(app);
                app.ImFi_TPL_FileListBox.Value={};
            catch
            end
        end

        % Value changed function: ImFi_TPF_AddressField
        function ImFi_TPF_AddressFieldValueChanged(app, event)
            tempdir = app.ImFi_TPF_AddressField.Value;
            % Display directory to the "Select Folder" edit field
            if (tempdir==0)
                app.FileDirectory=[app.InitialWorkingDirectory,'\'];
            else
                app.FileDirectory=tempdir;
            end
            app.ImFi_TPL_FileListBox.Enable=true;
            app.ImFi_TPF_AddressField.Value=app.FileDirectory;
            
            tempdir=dir(app.FileDirectory);
            tempdir=struct2cell(tempdir(~cellfun(@(x) x,{tempdir.isdir})));
            app.ImFi_TPL_FileListBox.Items=tempdir(1,:);
            app.ImFi_TPL_FileListBox.Value=tempfile;
            ImFi_TPL_FileListBoxValueChanged(app);
        end

        % Value changed function: Im_TC_QuickAdjust
        function Im_TC_QuickAdjustValueChanged(app, event)
            if (app.Im_TC_QuickAdjust.Value)
                if (isempty(app.Im_SelectedImage{2}))
                    if (size( app.Im_SelectedImage{1},3)==3)
                        app.Im_SelectedImage{2}=imadjust(app.Im_SelectedImage{1},...
                            [0,0,0;1,1,1],[]);
                    else
                        app.Im_SelectedImage{2}=imadjust(app.Im_SelectedImage{1});
                    end
                end
                imshow(app.Im_SelectedImage{2},'Parent', app.Im_TG_SelectedImage);
            else
                imshow(app.Im_SelectedImage{1},'Parent', app.Im_TG_SelectedImage);
            end
            title(app.CurrentFile,'Parent', app.Im_TG_SelectedImage);
        end

        % Menu selected function: Fi_MM_SaveImage
        function Fi_MM_SaveImageMenuSelected(app, event)
            imsave(app.Im_TG_SelectedImage);
        end

        % Menu selected function: MeSeCU_MMM_ImageProcessing
        function MeSeCU_MMM_ImageProcessingMenuSelected(app, event)
            if (length(app.MeSeCU_MMM_ImageProcessing.Checked)==3)
                try
                    G_PrintState(app,'Activating CUDA...');
                    app.Im_SelectedImage=cellfun(@gpuArray,app.Im_SelectedImage,'UniformOutput',false);
                    app.MeSeCU_MMM_ImageProcessing.Checked='on';
                    uialert(app.U_Main,{'CUDA is Speeding up the operations.'},...
                        'CUDA being used','Icon','success');
                    G_PrintState(app,'Ready.');
                catch
                    app.MeSeCU_MMM_ImageProcessing.Checked='off';
                    uialert(app.U_Main,{'CUDA is not available.';...
                        'Switched back to the no CUDA setting.'},...
                        'CUDA not available','Icon','warning');
                    G_PrintState(app,'Ready.');
                end
            else
                G_PrintState(app,'Stopping CUDA...');
                app.Im_SelectedImage=cellfun(@gather,app.Im_SelectedImage,'UniformOutput',false);
                app.MeSeCU_MMM_ImageProcessing.Checked='off';
                uialert(app.U_Main,{'Stop using CUDA successfully.'},...
                    'CUDA stop being used','Icon','success');
                G_PrintState(app,'Ready.');
            end
        end

        % Value changed function: ImFi_TPC_TrackNew
        function ImFi_TPC_TrackNewValueChanged(app, event)
            if(app.ImFi_TPC_TrackNew.Value)
                app.Im_FileWatcher.Path=app.FileDirectory;
                app.Im_FileWatcher.EnableRaisingEvents=true;
            else
                app.Im_FileWatcher.EnableRaisingEvents=false;
            end
        end

        % Value changed function: Im_TC_RemoveBackground
        function Im_TC_RemoveBackgroundValueChanged(app, event)
            try
                app.ImFi_TPL_FileListBox.Value=app.CurrentFile;
            catch
            end
            ImFi_TPL_FileListBoxValueChanged(app);
        end

        % Button pushed function: ImBa_TPB_SelectBackground
        function ImBa_TPB_SelectBackgroundButtonPushed(app, event)
            try
                [tempfile,tempdir]=uigetfile(app.FileDirectory,'filter','*.*');
                figure(app.U_Main);
                if (tempdir~=0)
                    app.Im_SelectedImage{4}=G_ReadImage(app,[tempdir,'\',tempfile]);
                    imshow(app.Im_SelectedImage{4},'Parent',app.ImBa_TPG_BackgroundImage);
                end
            catch
                uialert(app.U_Main,{'Cannot read this file as the background.'},...
                    'Invalid file','Icon','warning');
            end
        end

        % Button pushed function: AtFi_TPB_CopyAddress
        function AtFi_TPB_CopyAddressButtonPushed(app, event)
            if (isempty(app.AtFi_TPF_AddressField.Value))
                uialert(app.U_Main,{'The address field is empty.'},...
                    'Copy address','Icon','warning');
            else
                clipboard('copy',app.AtFi_TPF_AddressField.Value);
                uialert(app.U_Main,{'Current address copied:';app.AtFi_TPF_AddressField.Value},...
                    'Copy address','Icon','success');
            end
        end

        % Button pushed function: AtFi_TPB_BrowseFiles
        function AtFi_TPB_BrowseFilesButtonPushed(app, event)
            % Get directory
            tempdir=uigetdir(app.InitialWorkingDirectory);
            % Bring the window back to the front
            figure(app.U_Main);
            
            % Display directory to the "Select Folder" edit field
            if (tempdir==0)
                app.AtFi_TPF_AddressField.Value=app.InitialWorkingDirectory;
            else
                app.AtFi_TPF_AddressField.Value=tempdir;
            end
            AtFi_TPF_AddressFieldValueChanged(app);
        end

        % Value changed function: AtFi_TPF_AddressField
        function AtFi_TPF_AddressFieldValueChanged(app, event)
            app.At_FileDirectory=app.AtFi_TPF_AddressField.Value;
            app.AtFi_TPA_FileTable.Enable='on';
            tempdir=dir(app.At_FileDirectory);
            tempdir=struct2cell(tempdir(~cellfun(@(x) x,{tempdir.isdir})));
            app.AtFi_TPA_FileTable.Data=table...
                (tempdir(1,:)',repmat({' '},size(tempdir,2),1),...
                'VariableNames',{'FileName','Time'});
            app.At_FileWatcher.Path=app.At_FileDirectory;
            app.At_FileWatcher.EnableRaisingEvents=true;
            At_ResetReferenceTime(app);
        end

        % Cell selection callback: AtFi_TPA_FileTable
        function AtFi_TPA_FileTableCellSelection(app, event)
            try
                app.At_CurrentFile=app.AtFi_TPA_FileTable.Data.FileName{event.Indices(end,1)};
                app.At_CurrentImage=G_ReadImage(app,[app.At_FileDirectory,'\',app.At_CurrentFile]);
                At_TC_QuickAdjustValueChanged(app);
                title({'Selected Image:';app.At_CurrentFile},...
                    'Parent',app.At_TG_CurrentImage);
            catch
            end
        end

        % Value changed function: At_TC_QuickAdjust
        function At_TC_QuickAdjustValueChanged(app, event)
            if (app.At_TC_QuickAdjust.Value)
                imshow(imadjust(app.At_CurrentImage),'Parent',app.At_TG_CurrentImage);
            else
                imshow(app.At_CurrentImage,'Parent',app.At_TG_CurrentImage);
            end
            tempcd=cd;
            cd(app.InitialWorkingDirectory);
            app.At_CurrentImageData=CellData(app.At_CurrentImage);
            cd(tempcd);
            % Show the table of the imformation
            app.At_TA_CurrentMeasuredData.Data=table(...
                {'Number of Cells';
                'Effective Pixels';
                'Average value';
                'Absolute Standard Deviation';
                'Relative Standard Deviation'},...
                {length(app.At_CurrentImageData.fluoLevels);
                [num2str(app.At_CurrentImageData.effectiveP*100,'%.2f'),'%'];
                app.At_CurrentImageData.mean;
                app.At_CurrentImageData.std;
                [num2str(app.At_CurrentImageData.std/app.At_CurrentImageData.mean*100,'%.2f'),'%'];
                });
            % Show the segmentation
            CC = bwconncomp(app.At_CurrentImageData.cellIm);
            L = labelmatrix(CC);
            BWoutline = bwperim(app.At_CurrentImageData.cellIm);
            Lrgb = label2rgb(L, 'jet', 'k', 'shuffle');
            Lrgb(repmat(BWoutline,1,1,3))=255;
            imshow(imadjust(app.At_CurrentImage),'Parent',app.At_TG_SegmentedImage);
            hold(app.At_TG_SegmentedImage,'on');
            himage = imshow(Lrgb,'Parent',app.At_TG_SegmentedImage);
            himage.AlphaData = 0.3;
            hold(app.At_TG_SegmentedImage,'off');
            
        end

        % Value changed function: At_TB_StartExperiment
        function At_TB_StartExperimentValueChanged(app, event)
            if (app.At_TB_StartExperiment.Value)
                app.At_TI_ExperimentState.Color='r';
                try
                    address=1:size(app.AtFi_TPA_FileTable.Data,1);
                    address=address(strcmp(app.AtFi_TPA_FileTable.Data.FileName,app.At_CurrentFile));
                    app.AtFi_TPA_FileTable.Data(address,:)=[];
                    app.AtFi_TPA_FileTable.Data=[{app.At_CurrentFile,'00:00:00'};app.AtFi_TPA_FileTable.Data];
                catch
                end
                At_ResetReferenceTime(app);
                app.AtEx_TPA_ExperimentalData.Data=table(...
                    {0;app.StepEditField.Value},...
                    {At_NextInput(app);At_NextInput(app)},...
                    {app.At_CurrentImageData.mean;[]},...
                    {app.At_CurrentImageData.std;[]},...
                    'VariableNames',{'time','input','mean','std'});
                Ac_InitialAllAxes(app);
                if (app.Ac_ActuationSystem.Ready)
                    Ac_input2motion(app,app.AtEx_TPA_ExperimentalData.Data.input{end});
                    % plot the figure
                    At_DisplayExperimentalData(app);
                else
                    app.At_TI_ExperimentState.Color='g';
                    app.At_TB_StartExperiment.Value=false;
                end
            else
                app.At_TI_ExperimentState.Color='g';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.U_Main = uifigure;
            app.U_Main.Name = 'All-In-One APP';
            app.Me_M_File = uimenu(app.U_Main);
            app.Me_M_File.Text = 'File';
            app.Fi_MM_SaveSettings = uimenu(app.Me_M_File);
            app.Fi_MM_SaveSettings.Text = 'Save Settings';
            app.Fi_MM_LoadSettings = uimenu(app.Me_M_File);
            app.Fi_MM_LoadSettings.Text = 'Load Settings';
            app.Fi_MM_SaveImage = uimenu(app.Me_M_File);
            app.Fi_MM_SaveImage.MenuSelectedFcn = createCallbackFcn(app, @Fi_MM_SaveImageMenuSelected, true);
            app.Fi_MM_SaveImage.Separator = 'on';
            app.Fi_MM_SaveImage.Text = 'Save Image';
            app.Fi_MM_Exit = uimenu(app.Me_M_File);
            app.Fi_MM_Exit.Separator = 'on';
            app.Fi_MM_Exit.Text = 'Exit';
            app.Me_M_SeSettings = uimenu(app.U_Main);
            app.Me_M_SeSettings.Text = 'Settings';
            app.MeSe_MM_CUDAMenu = uimenu(app.Me_M_SeSettings);
            app.MeSe_MM_CUDAMenu.Text = 'CUDA';
            app.MeSeCU_MMM_ImageProcessing = uimenu(app.MeSe_MM_CUDAMenu);
            app.MeSeCU_MMM_ImageProcessing.MenuSelectedFcn = createCallbackFcn(app, @MeSeCU_MMM_ImageProcessingMenuSelected, true);
            app.MeSeCU_MMM_ImageProcessing.Text = 'Image Processing';
            app.MeSeCU_MMM_ParameterEstimation = uimenu(app.MeSe_MM_CUDAMenu);
            app.MeSeCU_MMM_ParameterEstimation.Checked = 'on';
            app.MeSeCU_MMM_ParameterEstimation.Text = 'Parameter Estimation';
            app.MeSeCU_MMM_OED = uimenu(app.MeSe_MM_CUDAMenu);
            app.MeSeCU_MMM_OED.Checked = 'on';
            app.MeSeCU_MMM_OED.Text = 'OED';
            app.Me_M_Help = uimenu(app.U_Main);
            app.Me_M_Help.Text = 'Help';
            app.MeHe_MM_Menu = uimenu(app.Me_M_Help);
            app.MeHe_MM_Menu.Text = 'Menu';
            app.MeHe_MM_About = uimenu(app.Me_M_Help);
            app.MeHe_MM_About.Text = 'About';
            app.TabGroup = uitabgroup(app.U_Main);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.Se_TP_Directory = uipanel(app.SettingsTab);
            app.Se_TP_Directory.Title = 'Directory';
            app.APPDirectoryTextAreaLabel = uilabel(app.Se_TP_Directory);
            app.APPDirectoryTextAreaLabel.Text = 'APP Directory';
            app.Se_TPX_APPDirectory = uitextarea(app.Se_TP_Directory);
            app.Se_TPX_APPDirectory.Editable = 'off';
            app.AMIGOLabel = uilabel(app.Se_TP_Directory);
            app.AMIGOLabel.Text = 'AMIGO:';
            app.Se_TPF_Se_AMIGODirectory = uieditfield(app.Se_TP_Directory, 'text');
            app.ImFi_TPB_CopyAddress_2 = uibutton(app.Se_TP_Directory, 'push');
            app.ImFi_TPB_CopyAddress_2.Text = 'Copy Address';
            app.ImFi_TPB_BrowseFiles_2 = uibutton(app.Se_TP_Directory, 'push');
            app.ImFi_TPB_BrowseFiles_2.Text = 'Browse Files';
            app.ImFi_TPB_CopyAddress_3 = uibutton(app.Se_TP_Directory, 'push');
            app.ImFi_TPB_CopyAddress_3.Text = 'Copy Address';
            app.BioFormatsLabel = uilabel(app.Se_TP_Directory);
            app.BioFormatsLabel.Text = 'Bio-Formats:';
            app.ImFi_TPF_AddressField_3 = uieditfield(app.Se_TP_Directory, 'text');
            app.ImFi_TPB_BrowseFiles_3 = uibutton(app.Se_TP_Directory, 'push');
            app.ImFi_TPB_BrowseFiles_3.Text = 'Browse Files';
            app.ImFi_TPB_CopyAddress_4 = uibutton(app.Se_TP_Directory, 'push');
            app.ImFi_TPB_CopyAddress_4.Text = 'Copy Address';
            app.OEDTab = uitab(app.TabGroup);
            app.OEDTab.Title = 'OED';
            app.ActuationSystemTab = uitab(app.TabGroup);
            app.ActuationSystemTab.Title = 'Actuation System';
            app.T_ImageProcessing = uitab(app.TabGroup);
            app.T_ImageProcessing.Title = 'Image Processing';
            app.Im_TG_SelectedImage = uiaxes(app.T_ImageProcessing);
            title(app.Im_TG_SelectedImage, 'Current Image')
            app.Im_TP_Files = uipanel(app.T_ImageProcessing);
            app.Im_TP_Files.Title = 'Files';
            app.ImFi_TPB_BrowseFiles = uibutton(app.Im_TP_Files, 'push');
            app.ImFi_TPB_BrowseFiles.ButtonPushedFcn = createCallbackFcn(app, @ImFi_TPB_BrowseFilesPushed, true);
            app.ImFi_TPB_BrowseFiles.Text = 'Browse Files';
            app.ImFi_TPB_CopyAddress = uibutton(app.Im_TP_Files, 'push');
            app.ImFi_TPB_CopyAddress.ButtonPushedFcn = createCallbackFcn(app, @ImFi_TPB_CopyAddressPushed, true);
            app.ImFi_TPB_CopyAddress.Text = 'Copy Address';
            app.ImFi_TPC_TrackNew = uicheckbox(app.Im_TP_Files);
            app.ImFi_TPC_TrackNew.ValueChangedFcn = createCallbackFcn(app, @ImFi_TPC_TrackNewValueChanged, true);
            app.ImFi_TPC_TrackNew.Text = 'Track New Images';
            app.ImFi_TPL_FileListBox = uilistbox(app.Im_TP_Files);
            app.ImFi_TPL_FileListBox.Items = {};
            app.ImFi_TPL_FileListBox.ValueChangedFcn = createCallbackFcn(app, @ImFi_TPL_FileListBoxValueChanged, true);
            app.ImFi_TPL_FileListBox.Enable = 'off';
            app.ImFi_TPL_FileListBox.Value = {};
            app.AddressEditFieldLabel = uilabel(app.Im_TP_Files);
            app.AddressEditFieldLabel.Text = 'Address:';
            app.ImFi_TPF_AddressField = uieditfield(app.Im_TP_Files, 'text');
            app.ImFi_TPF_AddressField.ValueChangedFcn = createCallbackFcn(app, @ImFi_TPF_AddressFieldValueChanged, true);
            app.Im_TC_QuickAdjust = uicheckbox(app.T_ImageProcessing);
            app.Im_TC_QuickAdjust.ValueChangedFcn = createCallbackFcn(app, @Im_TC_QuickAdjustValueChanged, true);
            app.Im_TC_QuickAdjust.Text = 'Quick adjust';
            app.Im_TC_QuickAdjust.Value = true;
            app.debugareaTextAreaLabel = uilabel(app.T_ImageProcessing);
            app.debugareaTextAreaLabel.Text = 'debug area';
            app.DB_debugareaTextArea = uitextarea(app.T_ImageProcessing);
            app.Im_TP_Background = uipanel(app.T_ImageProcessing);
            app.Im_TP_Background.Title = 'Background';
            app.ImBa_TPG_BackgroundImage = uiaxes(app.Im_TP_Background);
            title(app.ImBa_TPG_BackgroundImage, 'Background')
            app.ImBa_TPA_FileTypeTable = uitable(app.Im_TP_Background);
            app.ImBa_TPA_FileTypeTable.ColumnName = {'Type'; 'Key Word'};
            app.ImBa_TPA_FileTypeTable.RowName = {};
            app.ImBa_TPA_FileTypeTable.ColumnEditable = true;
            app.ImBa_TPB_SelectBackground = uibutton(app.Im_TP_Background, 'push');
            app.ImBa_TPB_SelectBackground.ButtonPushedFcn = createCallbackFcn(app, @ImBa_TPB_SelectBackgroundButtonPushed, true);
            app.ImBa_TPB_SelectBackground.Text = 'Select Background';
            app.ImBa_TPB_AddType = uibutton(app.Im_TP_Background, 'push');
            app.ImBa_TPB_AddType.Text = 'Add';
            app.ImBa_TPB_DeleteType = uibutton(app.Im_TP_Background, 'push');
            app.ImBa_TPB_DeleteType.Text = 'Delete';
            app.Im_TC_RemoveBackground = uicheckbox(app.T_ImageProcessing);
            app.Im_TC_RemoveBackground.ValueChangedFcn = createCallbackFcn(app, @Im_TC_RemoveBackgroundValueChanged, true);
            app.Im_TC_RemoveBackground.Text = 'Remove Background';
            app.ParameterEstimationTab = uitab(app.TabGroup);
            app.ParameterEstimationTab.Title = 'Parameter Estimation';
            app.FilesPanel_2 = uipanel(app.ParameterEstimationTab);
            app.FilesPanel_2.Title = 'Files';
            app.UITable = uitable(app.FilesPanel_2);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.AutomaticInferenceTab = uitab(app.TabGroup);
            app.AutomaticInferenceTab.Title = 'Automatic Inference';
            app.At_TP_Files = uipanel(app.AutomaticInferenceTab);
            app.At_TP_Files.Title = 'Files';
            app.AtFi_TPB_BrowseFiles = uibutton(app.At_TP_Files, 'push');
            app.AtFi_TPB_BrowseFiles.ButtonPushedFcn = createCallbackFcn(app, @AtFi_TPB_BrowseFilesButtonPushed, true);
            app.AtFi_TPB_BrowseFiles.Text = 'Browse Folder';
            app.AtFi_TPB_CopyAddress = uibutton(app.At_TP_Files, 'push');
            app.AtFi_TPB_CopyAddress.ButtonPushedFcn = createCallbackFcn(app, @AtFi_TPB_CopyAddressButtonPushed, true);
            app.AtFi_TPB_CopyAddress.Text = 'Copy Address';
            app.AddressEditFieldLabel_2 = uilabel(app.At_TP_Files);
            app.AddressEditFieldLabel_2.Text = 'Address:';
            app.AtFi_TPF_AddressField = uieditfield(app.At_TP_Files, 'text');
            app.AtFi_TPF_AddressField.ValueChangedFcn = createCallbackFcn(app, @AtFi_TPF_AddressFieldValueChanged, true);
            app.AtFi_TPA_FileTable = uitable(app.At_TP_Files);
            app.AtFi_TPA_FileTable.ColumnName = {'File Name'; 'Time Created'};
            app.AtFi_TPA_FileTable.RowName = {};
            app.AtFi_TPA_FileTable.CellSelectionCallback = createCallbackFcn(app, @AtFi_TPA_FileTableCellSelection, true);
            app.At_TP_ExperimentalData = uipanel(app.AutomaticInferenceTab);
            app.At_TP_ExperimentalData.Title = 'Experimental Data';
            app.AtEx_TPG_ExperimentDataRecord = uiaxes(app.At_TP_ExperimentalData);
            title(app.AtEx_TPG_ExperimentDataRecord, 'Experimental Data')
            xlabel(app.AtEx_TPG_ExperimentDataRecord, 'Time')
            ylabel(app.AtEx_TPG_ExperimentDataRecord, 'Value')
            app.ThresholdEditFieldLabel = uilabel(app.At_TP_ExperimentalData);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.At_TP_ExperimentalData, 'numeric');
            app.ThresholdEditField.Limits = [0 Inf];
            app.ThresholdEditField.Value = 260000;
            app.StepEditFieldLabel = uilabel(app.At_TP_ExperimentalData);
            app.StepEditFieldLabel.Text = 'Step';
            app.StepEditField = uieditfield(app.At_TP_ExperimentalData, 'numeric');
            app.StepEditField.Limits = [0 Inf];
            app.StepEditField.Value = 10;
            app.AtEx_TPA_ExperimentalData = uitable(app.At_TP_ExperimentalData);
            app.AtEx_TPA_ExperimentalData.ColumnName = {'time (s)'; 'u'; 'y (mean)'; 'y (STD)'};
            app.AtEx_TPA_ExperimentalData.RowName = {};
            app.At_TG_CurrentImage = uiaxes(app.AutomaticInferenceTab);
            title(app.At_TG_CurrentImage, 'Original Image')
            app.At_TA_CurrentMeasuredData = uitable(app.AutomaticInferenceTab);
            app.At_TA_CurrentMeasuredData.ColumnName = {'Name'; 'Value'};
            app.At_TA_CurrentMeasuredData.RowName = {};
            app.At_TB_StartExperiment = uibutton(app.AutomaticInferenceTab, 'state');
            app.At_TB_StartExperiment.ValueChangedFcn = createCallbackFcn(app, @At_TB_StartExperimentValueChanged, true);
            app.At_TB_StartExperiment.Text = 'Start the Experiment';
            app.At_TI_ExperimentState = uilamp(app.AutomaticInferenceTab);
            app.At_TC_QuickAdjust = uicheckbox(app.AutomaticInferenceTab);
            app.At_TC_QuickAdjust.ValueChangedFcn = createCallbackFcn(app, @At_TC_QuickAdjustValueChanged, true);
            app.At_TC_QuickAdjust.Text = 'Quick adjust';
            app.At_TC_QuickAdjust.Value = true;
            app.At_TG_SegmentedImage = uiaxes(app.AutomaticInferenceTab);
            title(app.At_TG_SegmentedImage, 'Segmentation')
            app.AutomaticInference2Tab = uitab(app.TabGroup);
            app.AutomaticInference2Tab.Title = 'Automatic Inference2';
            app.At_TP_Files_2 = uipanel(app.AutomaticInference2Tab);
            app.At_TP_Files_2.Title = 'Files';
            app.AtFi_TPB_BrowseFiles_2 = uibutton(app.At_TP_Files_2, 'push');
            app.AtFi_TPB_BrowseFiles_2.Text = 'Browse Folder';
            app.AtFi_TPB_CopyAddress_2 = uibutton(app.At_TP_Files_2, 'push');
            app.AtFi_TPB_CopyAddress_2.Text = 'Copy Address';
            app.AddressEditFieldLabel_3 = uilabel(app.At_TP_Files_2);
            app.AddressEditFieldLabel_3.Text = 'Address:';
            app.AtFi_TPF_AddressField_2 = uieditfield(app.At_TP_Files_2, 'text');
            app.AtFi_TPA_FileTable_2 = uitable(app.At_TP_Files_2);
            app.AtFi_TPA_FileTable_2.ColumnName = {'File Name'; 'Time Created'};
            app.AtFi_TPA_FileTable_2.RowName = {};
            app.At_TP_ExperimentalData_2 = uipanel(app.AutomaticInference2Tab);
            app.At_TP_ExperimentalData_2.Title = 'Experimental Data';
            app.AtEx_TPG_ExperimentDataRecord_2 = uiaxes(app.At_TP_ExperimentalData_2);
            title(app.AtEx_TPG_ExperimentDataRecord_2, 'Experimental Data')
            xlabel(app.AtEx_TPG_ExperimentDataRecord_2, 'Time')
            ylabel(app.AtEx_TPG_ExperimentDataRecord_2, 'Value')
            app.ThresholdEditField_2Label = uilabel(app.At_TP_ExperimentalData_2);
            app.ThresholdEditField_2Label.Text = 'Threshold';
            app.ThresholdEditField_2 = uieditfield(app.At_TP_ExperimentalData_2, 'numeric');
            app.ThresholdEditField_2.Limits = [0 Inf];
            app.ThresholdEditField_2.Value = 260000;
            app.StepEditField_2Label = uilabel(app.At_TP_ExperimentalData_2);
            app.StepEditField_2Label.Text = 'Step';
            app.StepEditField_2 = uieditfield(app.At_TP_ExperimentalData_2, 'numeric');
            app.StepEditField_2.Limits = [0 Inf];
            app.StepEditField_2.Value = 10;
            app.AtEx_TPA_ExperimentalData_2 = uitable(app.At_TP_ExperimentalData_2);
            app.AtEx_TPA_ExperimentalData_2.ColumnName = {'time (s)'; 'u'; 'y (mean)'; 'y (STD)'};
            app.AtEx_TPA_ExperimentalData_2.RowName = {};
            app.At_TG_CurrentImage_2 = uiaxes(app.AutomaticInference2Tab);
            title(app.At_TG_CurrentImage_2, 'Original Image')
            app.At_TA_CurrentMeasuredData_2 = uitable(app.AutomaticInference2Tab);
            app.At_TA_CurrentMeasuredData_2.ColumnName = {'Name'; 'Value'};
            app.At_TA_CurrentMeasuredData_2.RowName = {};
            app.At_TB_StartExperiment_2 = uibutton(app.AutomaticInference2Tab, 'state');
            app.At_TB_StartExperiment_2.Text = 'Start the Experiment';
            app.At_TI_ExperimentState_2 = uilamp(app.AutomaticInference2Tab);
            app.At_TC_QuickAdjust_2 = uicheckbox(app.AutomaticInference2Tab);
            app.At_TC_QuickAdjust_2.Text = 'Quick adjust';
            app.At_TC_QuickAdjust_2.Value = true;
            app.At_TG_SegmentedImage_2 = uiaxes(app.AutomaticInference2Tab);
            title(app.At_TG_SegmentedImage_2, 'Segmentation')
            app.X_StateText = uitextarea(app.U_Main);
            app.X_StateText.Editable = 'off';
            app.X_StateText.Value = {'Ready.'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = ClosedLoop_V1_0

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.U_Main)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.U_Main)
        end
    end
end