classdef Image_Processing_V1_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        U_Main                  matlab.ui.Figure
        M_File                  matlab.ui.container.Menu
        MM_Fi_SaveSettings      matlab.ui.container.Menu
        MM_Fi_LoadSettings      matlab.ui.container.Menu
        MM_Fi_SaveImage         matlab.ui.container.Menu
        MM_Fi_Exit              matlab.ui.container.Menu
        M_Settings              matlab.ui.container.Menu
        MM_Se_UseCUDA           matlab.ui.container.Menu
        M_Help                  matlab.ui.container.Menu
        MM_He_Menu              matlab.ui.container.Menu
        MM_He_About             matlab.ui.container.Menu
        TabGroup                matlab.ui.container.TabGroup
        T_SelectImages          matlab.ui.container.Tab
        TA_Se_SelectedImage     matlab.ui.control.UIAxes
        TP_Se_Files             matlab.ui.container.Panel
        AddressEditFieldLabel   matlab.ui.control.Label
        TPF_SeFi_Field          matlab.ui.control.EditField
        TPL_SeFi_FileListBox    matlab.ui.control.ListBox
        TPB_SeFi_CopyAddress    matlab.ui.control.Button
        TPB_SeFi_BrowseFiles    matlab.ui.control.Button
        TPC_SeFi_TrackNew       matlab.ui.control.CheckBox
        TC_Se_QuickAdjust       matlab.ui.control.CheckBox
        debugareaTextAreaLabel  matlab.ui.control.Label
        debugareaTextArea       matlab.ui.control.TextArea
        ImageProcessingTab      matlab.ui.container.Tab
        FilesPanel_2            matlab.ui.container.Panel
        UITable                 matlab.ui.control.Table
        T_StateText             matlab.ui.control.TextArea
    end

    properties (Access = private)
        FileDirectory               % directory of image files
        CurrentFile                 % the file under editing
        InitialWorkingDirectory     % working directory
        CurrentImages               % A cell array storing all the images we are currently working on
        % by default, 1st - raw image, 2nd - background, 3rd - adjusted (raw or background removed depend on the situation)
        % nth - further operations
        TI_Se_SelectedImage                   % temporary image, image selection only
    end
    
    methods (Access = private)
        
        function image = ReadImage(app,fullFileName)
            % use the toolbox Bio-Formats 5.9.1. and the script I wrote ('bfReadImage.m') to load the image
            tempcd=cd;
            cd(app.InitialWorkingDirectory);
            if (exist('bfmatlab','file'))
                cd('bfmatlab');
                [~,image]=evalc(['bfopen(''',fullFileName,''')']);
                image=image{1}{1};
            else
                image=imread(fullFileName);
            end
            if (length(app.MM_Se_UseCUDA.Checked)==2) % if Use CUDA is on
                image=gpuArray(image);
            end
            cd(tempcd);
        end
        
        
        function PrintState(app,text)
            app.T_StateText.Value=text;            
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.InitialWorkingDirectory=cd;
            app.CurrentImages={};
            app.TI_Se_SelectedImage=cell(2,1);
            app.debugareaTextArea.Value=app.MM_Se_UseCUDA.Checked;
            
            try
                cd('bfmatlab');
                cd(app.InitialWorkingDirectory);
            catch
                uialert(app.U_Main,{'Bio-Formats toolbox is not included the working folder.';...
                    'You need this toolbox to read nd2 files.';...
                    '(Fine with other image types)'},...
                    'Bio-Formats toolbox is not included','Icon','warning');
            end
        end

        % Callback function: MM_Fi_Exit, U_Main
        function U_MainCloseRequest(app, event)
            delete(app);
        end

        % Button pushed function: TPB_SeFi_BrowseFiles
        function TPB_SeFi_BrowseFilesPushed(app, event)
            % Get directory
            [tempfile,tempdir]=uigetfile('*.*');
            % Bring the window back to the front
            figure(app.U_Main);
            
            % Display directory to the "Select Folder" edit field
            if (tempdir==0)
                app.FileDirectory=app.InitialWorkingDirectory;
            else
                app.FileDirectory=tempdir;
            end
            app.TPL_SeFi_FileListBox.Enable=true;
            app.TPF_SeFi_Field.Value=app.FileDirectory;
            tempdir=dir(app.FileDirectory);
            tempdir=struct2cell(tempdir(~cellfun(@(x) x,{tempdir.isdir})));
            app.TPL_SeFi_FileListBox.Items=tempdir(1,:);
            if (tempfile~=0)
                app.TPL_SeFi_FileListBox.Value=tempfile;
                TPL_SeFi_FileListBoxValueChanged(app);
            end
        end

        % Button pushed function: TPB_SeFi_CopyAddress
        function TPB_SeFi_CopyAddressPushed(app, event)
            if (isempty(app.TPF_SeFi_Field.Value))
                uialert(app.U_Main,{'The address field is empty.'},...
                    'Copy address','Icon','warning');
            else
                clipboard('copy',app.TPF_SeFi_Field.Value);
                uialert(app.U_Main,{'Current address copied:';app.TPF_SeFi_Field.Value},...
                    'Copy address','Icon','success');
            end
        end

        % Value changed function: TPL_SeFi_FileListBox
        function TPL_SeFi_FileListBoxValueChanged(app, event)
            try
                app.CurrentFile=app.TPL_SeFi_FileListBox.Value;
                if (~strcmp(app.debugareaTextArea.Value,[app.FileDirectory,app.CurrentFile]))
                    app.debugareaTextArea.Value=[app.FileDirectory,app.CurrentFile];
                    app.TI_Se_SelectedImage{1}=ReadImage(app,[app.FileDirectory,app.CurrentFile]);
                    app.TI_Se_SelectedImage{2}=[];
                    TC_Se_QuickAdjustValueChanged(app);
                end
                app.TPL_SeFi_FileListBox.Value={};
            catch
            end
        end

        % Value changed function: TPF_SeFi_Field
        function TPF_SeFi_FieldValueChanged(app, event)
            tempdir = app.TPF_SeFi_Field.Value;
            % Display directory to the "Select Folder" edit field
            if (tempdir==0)
                app.FileDirectory=app.InitialWorkingDirectory;
            else
                app.FileDirectory=tempdir;
            end
            app.TPL_SeFi_FileListBox.Enable=true;
            app.TPF_SeFi_Field.Value=app.FileDirectory;
            tempdir=dir(app.FileDirectory);
            tempdir=struct2cell(tempdir(~cellfun(@(x) x,{tempdir.isdir})));
            app.TPL_SeFi_FileListBox.Items=tempdir(1,:);
            app.TPL_SeFi_FileListBox.Value=tempfile;
            TPL_SeFi_FileListBoxValueChanged(app);
        end

        % Value changed function: TC_Se_QuickAdjust
        function TC_Se_QuickAdjustValueChanged(app, event)
            if (app.TC_Se_QuickAdjust.Value)
                if (isempty(app.TI_Se_SelectedImage{2}))
                    if (size( app.TI_Se_SelectedImage{1},3)==3)
                        app.TI_Se_SelectedImage{2}=imadjust(app.TI_Se_SelectedImage{1},...
                            [0,0,0;1,1,1],[]);
                    else
                        app.TI_Se_SelectedImage{2}=imadjust(app.TI_Se_SelectedImage{1});
                    end
                end
                imshow(app.TI_Se_SelectedImage{2},'Parent', app.TA_Se_SelectedImage);
            else
                imshow(app.TI_Se_SelectedImage{1},'Parent', app.TA_Se_SelectedImage);
            end
            title(app.CurrentFile,'Parent', app.TA_Se_SelectedImage);
        end

        % Menu selected function: MM_Se_UseCUDA
        function MM_Se_UseCUDAMenuSelected(app, event)
            if (length(app.MM_Se_UseCUDA.Checked)==3)
                try
                    PrintState(app,'Activating CUDA...');
                    app.TI_Se_SelectedImage=cellfun(@(I) gpuArray(I),app.TI_Se_SelectedImage);
                    app.MM_Se_UseCUDA.Checked='on';
                    uialert(app.U_Main,{'CUDA is Speeding up the operations.'},...
                        'CUDA being used','Icon','success');
                    PrintState(app,'Ready.');
                catch
                    app.MM_Se_UseCUDA.Checked='off';
                    uialert(app.U_Main,{'CUDA is not available.';...
                        'Switched back to the no CUDA setting.'},...
                        'CUDA not available','Icon','warning');
                    PrintState(app,'Ready.');
                end
                
            else
                PrintState(app,'Stopping CUDA...');
                app.TI_Se_SelectedImage=cellfun(@(I) gather(I),app.TI_Se_SelectedImage);
                app.MM_Se_UseCUDA.Checked='off';
                uialert(app.U_Main,{'Stop using CUDA successfully.'},...
                        'CUDA stop being used','Icon','success'); 
                PrintState(app,'Ready.');
            end
        end

        % Menu selected function: MM_Fi_SaveImage
        function MM_Fi_SaveImageMenuSelected(app, event)
            imsave(app.TA_Se_SelectedImage);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.U_Main = uifigure;
            app.U_Main.Name = 'Actuator Control';
            app.U_Main.CloseRequestFcn = createCallbackFcn(app, @U_MainCloseRequest, true);
            app.M_File = uimenu(app.U_Main);
            app.M_File.Text = 'File';
            app.MM_Fi_SaveSettings = uimenu(app.M_File);
            app.MM_Fi_SaveSettings.Text = 'Save Settings';
            app.MM_Fi_LoadSettings = uimenu(app.M_File);
            app.MM_Fi_LoadSettings.Text = 'Load Settings';
            app.MM_Fi_SaveImage = uimenu(app.M_File);
            app.MM_Fi_SaveImage.MenuSelectedFcn = createCallbackFcn(app, @MM_Fi_SaveImageMenuSelected, true);
            app.MM_Fi_SaveImage.Separator = 'on';
            app.MM_Fi_SaveImage.Text = 'Save Image';
            app.MM_Fi_Exit = uimenu(app.M_File);
            app.MM_Fi_Exit.MenuSelectedFcn = createCallbackFcn(app, @U_MainCloseRequest, true);
            app.MM_Fi_Exit.Separator = 'on';
            app.MM_Fi_Exit.Text = 'Exit';
            app.M_Settings = uimenu(app.U_Main);
            app.M_Settings.Text = 'Settings';
            app.MM_Se_UseCUDA = uimenu(app.M_Settings);
            app.MM_Se_UseCUDA.MenuSelectedFcn = createCallbackFcn(app, @MM_Se_UseCUDAMenuSelected, true);
            app.MM_Se_UseCUDA.Text = 'Use CUDA';
            app.M_Help = uimenu(app.U_Main);
            app.M_Help.Text = 'Help';
            app.MM_He_Menu = uimenu(app.M_Help);
            app.MM_He_Menu.Text = 'Menu';
            app.MM_He_About = uimenu(app.M_Help);
            app.MM_He_About.Text = 'About';
            app.TabGroup = uitabgroup(app.U_Main);
            app.T_SelectImages = uitab(app.TabGroup);
            app.T_SelectImages.Title = 'Select Images';
            app.TA_Se_SelectedImage = uiaxes(app.T_SelectImages);
            title(app.TA_Se_SelectedImage, 'Title')
            app.TP_Se_Files = uipanel(app.T_SelectImages);
            app.TP_Se_Files.Title = 'Files';
            app.AddressEditFieldLabel = uilabel(app.TP_Se_Files);
            app.AddressEditFieldLabel.Text = 'Address:';
            app.TPF_SeFi_Field = uieditfield(app.TP_Se_Files, 'text');
            app.TPF_SeFi_Field.ValueChangedFcn = createCallbackFcn(app, @TPF_SeFi_FieldValueChanged, true);
            app.TPL_SeFi_FileListBox = uilistbox(app.TP_Se_Files);
            app.TPL_SeFi_FileListBox.Items = {};
            app.TPL_SeFi_FileListBox.ValueChangedFcn = createCallbackFcn(app, @TPL_SeFi_FileListBoxValueChanged, true);
            app.TPL_SeFi_FileListBox.Enable = 'off';
            app.TPL_SeFi_FileListBox.Value = {};
            app.TPB_SeFi_CopyAddress = uibutton(app.TP_Se_Files, 'push');
            app.TPB_SeFi_CopyAddress.ButtonPushedFcn = createCallbackFcn(app, @TPB_SeFi_CopyAddressPushed, true);
            app.TPB_SeFi_CopyAddress.Text = 'Copy Address';
            app.TPB_SeFi_BrowseFiles = uibutton(app.TP_Se_Files, 'push');
            app.TPB_SeFi_BrowseFiles.ButtonPushedFcn = createCallbackFcn(app, @TPB_SeFi_BrowseFilesPushed, true);
            app.TPB_SeFi_BrowseFiles.Text = 'Browse Files';
            app.TPC_SeFi_TrackNew = uicheckbox(app.TP_Se_Files);
            app.TPC_SeFi_TrackNew.Text = 'Automatically track the new image';
            app.TC_Se_QuickAdjust = uicheckbox(app.T_SelectImages);
            app.TC_Se_QuickAdjust.ValueChangedFcn = createCallbackFcn(app, @TC_Se_QuickAdjustValueChanged, true);
            app.TC_Se_QuickAdjust.Text = 'Quick adjust';
            app.TC_Se_QuickAdjust.Value = true;
            app.debugareaTextAreaLabel = uilabel(app.T_SelectImages);
            app.debugareaTextAreaLabel.Text = 'debug area';
            app.debugareaTextArea = uitextarea(app.T_SelectImages);
            app.ImageProcessingTab = uitab(app.TabGroup);
            app.ImageProcessingTab.Title = 'Image Processing';
            app.FilesPanel_2 = uipanel(app.ImageProcessingTab);
            app.FilesPanel_2.Title = 'Files';
            app.UITable = uitable(app.FilesPanel_2);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.T_StateText = uitextarea(app.U_Main);
            app.T_StateText.Editable = 'off';
            app.T_StateText.Value = {'Ready.'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Image_Processing_V1_1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.U_Main)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.U_Main)
        end
    end
end