classdef Image_Processing_V1_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        U_Main                        matlab.ui.Figure
        M_File                        matlab.ui.container.Menu
        Fi_MM_SaveSettings            matlab.ui.container.Menu
        Fi_MM_LoadSettings            matlab.ui.container.Menu
        Fi_MM_SaveImage               matlab.ui.container.Menu
        Fi_MM_Exit                    matlab.ui.container.Menu
        M_StSettings                  matlab.ui.container.Menu
        St_MM_CUDAMenu                matlab.ui.container.Menu
        StCU_MMM_ImageProcessing      matlab.ui.container.Menu
        StCU_MMM_ParameterEstimation  matlab.ui.container.Menu
        StCU_MMM_OED                  matlab.ui.container.Menu
        M_Help                        matlab.ui.container.Menu
        He_MM_Menu                    matlab.ui.container.Menu
        He_MM_About                   matlab.ui.container.Menu
        TabGroup                      matlab.ui.container.TabGroup
        ModelTab                      matlab.ui.container.Tab
        OEDTab                        matlab.ui.container.Tab
        ActuationSystemTab            matlab.ui.container.Tab
        T_ImageProcessing             matlab.ui.container.Tab
        Im_TG_SelectedImage           matlab.ui.control.UIAxes
        Im_TP_Files                   matlab.ui.container.Panel
        ImFi_TPB_BrowseFiles          matlab.ui.control.Button
        ImFi_TPB_CopyAddress          matlab.ui.control.Button
        ImFi_TPC_TrackNew             matlab.ui.control.CheckBox
        ImFi_TPL_FileListBox          matlab.ui.control.ListBox
        AddressEditFieldLabel         matlab.ui.control.Label
        ImFi_TPF_AddressField         matlab.ui.control.EditField
        Im_TC_QuickAdjust             matlab.ui.control.CheckBox
        debugareaTextAreaLabel        matlab.ui.control.Label
        DB_debugareaTextArea          matlab.ui.control.TextArea
        Im_TP_Background              matlab.ui.container.Panel
        ImBa_TPG_BackgroundImage      matlab.ui.control.UIAxes
        ImBa_TPA_FileTypeTable        matlab.ui.control.Table
        ImBa_TPB_SelectBackground     matlab.ui.control.Button
        ImBa_TPB_AddType              matlab.ui.control.Button
        ImBa_TPB_DeleteType           matlab.ui.control.Button
        Im_TC_RemoveBackground        matlab.ui.control.CheckBox
        ParameterEstimationTab        matlab.ui.container.Tab
        FilesPanel_2                  matlab.ui.container.Panel
        UITable                       matlab.ui.control.Table
        AutomaticInferenceTab         matlab.ui.container.Tab
        At_TP_Files                   matlab.ui.container.Panel
        AtFi_TPB_BrowseFiles          matlab.ui.control.Button
        AtFi_TPB_CopyAddress          matlab.ui.control.Button
        AddressEditFieldLabel_2       matlab.ui.control.Label
        AtFi_TPF_AddressField         matlab.ui.control.EditField
        AtFi_TPA_FileTable            matlab.ui.control.Table
        At_TP_Input                   matlab.ui.container.Panel
        AtIn_TPG_InputRecord          matlab.ui.control.UIAxes
        At_TG_CurrentImage            matlab.ui.control.UIAxes
        At_TA_CurrentMeasuredData     matlab.ui.control.Table
        At_TB_StartExperiment         matlab.ui.control.StateButton
        At_TI_ExperimentState         matlab.ui.control.Lamp
        At_TC_QuickAdjust             matlab.ui.control.CheckBox
        X_StateText                   matlab.ui.control.TextArea
    end

    properties (Access = private)
        FileDirectory               % directory of image files
        CurrentFile                 % the file under editing
        InitialWorkingDirectory     % working directory
        CurrentImages               % A cell array storing all the images we are currently working on
        % by default,
        % 1st - raw/BGremoved (depends on the setting),
        % 2nd - adjusted of 1st,
        % 3rd - raw
        % 4th - Background
        % nth - further operations
        Im_SelectedImage         % temporary image, image selection only
        Im_FileWatcher              % watching the state of the working folder, System.IO.FileSystemWatcher
        At_FileDirectory            % the file directory for automatic inference
        At_FileWatcher              % watching the state of the working folder
        At_CurrentFile              % name of the current image file for automatic inference
        At_CurrentImage             % the current input image for automatic inference
        At_ReferenceTime            % The time whe the experiment is started
    end
    
    methods (Access = private)
        
        function image = ReadImage(app,fullFileName)
            % use the toolbox Bio-Formats 5.9.1. and the script I wrote ('bfReadImage.m') to load the image
            tempcd=cd;
            cd(app.InitialWorkingDirectory);
            try
                try
                    cd('bfmatlab');
                catch
                end
                [~,image]=evalc(['bfopen(''',fullFileName,''')']);
                image=image{1}{1};
            catch
                image=imread(fullFileName);
            end
            if (length(app.StCU_MMM_ImageProcessing.Checked)==2) % if Use CUDA is on
                image=gpuArray(image);
            end
            cd(tempcd);
        end
        
        
        function PrintState(app,text)
            app.X_StateText.Value=text;
        end
        
        function Im_NewFileDetected(app,~,arg)
            tempdir=dir(app.FileDirectory);
            tempdir=struct2cell(tempdir(~cellfun(@(x) x,{tempdir.isdir})));
            app.ImFi_TPL_FileListBox.Items=tempdir(1,:);
            try
                app.ImFi_TPL_FileListBox.Value=char(arg.Name);
                ImFi_TPL_FileListBoxValueChanged(app);
                title({'New file detected:';app.CurrentFile},'Parent', app.Im_TG_SelectedImage);
                app.ImFi_TPL_FileListBox.Value=string(arg.Name);
            catch
            end
            PrintState(app,string(arg.Name));
        end
        
        
        function At_NewFileDetected(app,~,arg)
            % add the new file to the file list
            app.AtFi_TPA_FileTable.Data=[{char(arg.Name),char(between(app.At_ReferenceTime,datetime))};...
                app.AtFi_TPA_FileTable.Data];
            try
                app.At_CurrentFile=char(arg.Name);
                app.At_CurrentImage=ReadImage(app,[app.At_FileDirectory,'\',app.At_CurrentFile]);
                if (app.At_TC_QuickAdjust.Value)
                    imshow(imadjust(app.At_CurrentImage),'Parent',app.At_TG_CurrentImage);
                else
                    imshow(app.At_CurrentImage,'Parent',app.At_TG_CurrentImage);
                end
                title({['New image added at: ',char(app.AtFi_TPA_FileTable.Data.Var2(1))];app.CurrentFile},...
                    'Parent',app.At_TG_CurrentImage);
            catch
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.InitialWorkingDirectory=cd;
            app.FileDirectory=app.InitialWorkingDirectory;
            app.CurrentImages={};
            app.Im_SelectedImage=cell(3,1);
            app.Im_FileWatcher=System.IO.FileSystemWatcher(app.InitialWorkingDirectory);
            app.Im_FileWatcher.EnableRaisingEvents=false;
            app.At_FileWatcher=app.Im_FileWatcher;
            addlistener(app.Im_FileWatcher,'Created',@app.Im_NewFileDetected);
            addlistener(app.At_FileWatcher,'Created',@app.At_NewFileDetected);
            app.DB_debugareaTextArea.Value=app.StCU_MMM_ImageProcessing.Checked;
            if(~exist('bfmatlab','dir'))
                uialert(app.U_Main,{'Bio-Formats toolbox is not included the working folder.';...
                    'You need this toolbox to read nd2 files.';...
                    '(Fine with other image types)'},...
                    'Bio-Formats toolbox is not included','Icon','warning');
            end
            
        end

        % Callback function: Fi_MM_Exit, U_Main
        function U_MainCloseRequest(app, event)
            delete(app);
        end

        % Button pushed function: ImFi_TPB_BrowseFiles
        function ImFi_TPB_BrowseFilesPushed(app, event)
            % Get directory
            [tempfile,tempdir]=uigetfile('*.*');
            % Bring the window back to the front
            figure(app.U_Main);
            
            % Display directory to the "Select Folder" edit field
            if (tempdir==0)
                app.FileDirectory=app.InitialWorkingDirectory;
            else
                tempdir(end)=[];
                app.FileDirectory=tempdir;
            end
            app.ImFi_TPL_FileListBox.Enable=true;
            app.ImFi_TPF_AddressField.Value=app.FileDirectory;
            tempdir=dir(app.FileDirectory);
            tempdir=struct2cell(tempdir(~cellfun(@(x) x,{tempdir.isdir})));
            app.ImFi_TPL_FileListBox.Items=tempdir(1,:);
            if (tempfile~=0)
                app.ImFi_TPL_FileListBox.Value=tempfile;
                ImFi_TPL_FileListBoxValueChanged(app);
            end
        end

        % Button pushed function: ImFi_TPB_CopyAddress
        function ImFi_TPB_CopyAddressPushed(app, event)
            if (isempty(app.ImFi_TPF_AddressField.Value))
                uialert(app.U_Main,{'The address field is empty.'},...
                    'Copy address','Icon','warning');
            else
                clipboard('copy',app.ImFi_TPF_AddressField.Value);
                uialert(app.U_Main,{'Current address copied:';app.ImFi_TPF_AddressField.Value},...
                    'Copy address','Icon','success');
            end
        end

        % Value changed function: ImFi_TPL_FileListBox
        function ImFi_TPL_FileListBoxValueChanged(app, event)
            try
                app.CurrentFile=app.ImFi_TPL_FileListBox.Value;
                app.DB_debugareaTextArea.Value=[app.FileDirectory,'\',app.CurrentFile];
                app.Im_SelectedImage{3}=ReadImage(app,[app.FileDirectory,'\',app.CurrentFile]);
                if (app.Im_TC_RemoveBackground.Value)
                    try
                        d4=double(app.Im_SelectedImage{4});
                        d3=double(app.Im_SelectedImage{3});
                        d1=d3-d4*sum(d3(:).*d4(:))/sum(sum(d4(:).^2));
                        if (isa(app.Im_SelectedImage{3},'uint8'))
                            app.Im_SelectedImage{1}=uint8(d1);
                        else
                            app.Im_SelectedImage{1}=uint16(d1);
                        end
                    catch
                        uialert(app.U_Main,{'current image size does not match with the background, ',...
                            'stop removing the background.'},...
                            'Size do not match','Icon','warning');
                        app.Im_TC_RemoveBackground.Value=false;
                        app.Im_SelectedImage{1}=app.Im_SelectedImage{3};
                    end
                else
                    app.Im_SelectedImage{1}=app.Im_SelectedImage{3};
                end
                app.Im_SelectedImage{2}=[];
                Im_TC_QuickAdjustValueChanged(app);
                app.ImFi_TPL_FileListBox.Value={};
            catch
            end
        end

        % Value changed function: ImFi_TPF_AddressField
        function ImFi_TPF_AddressFieldValueChanged(app, event)
            tempdir = app.ImFi_TPF_AddressField.Value;
            % Display directory to the "Select Folder" edit field
            if (tempdir==0)
                app.FileDirectory=[app.InitialWorkingDirectory,'\'];
            else
                app.FileDirectory=tempdir;
            end
            app.ImFi_TPL_FileListBox.Enable=true;
            app.ImFi_TPF_AddressField.Value=app.FileDirectory;
            
            tempdir=dir(app.FileDirectory);
            tempdir=struct2cell(tempdir(~cellfun(@(x) x,{tempdir.isdir})));
            app.ImFi_TPL_FileListBox.Items=tempdir(1,:);
            app.ImFi_TPL_FileListBox.Value=tempfile;
            ImFi_TPL_FileListBoxValueChanged(app);
        end

        % Value changed function: Im_TC_QuickAdjust
        function Im_TC_QuickAdjustValueChanged(app, event)
            if (app.Im_TC_QuickAdjust.Value)
                if (isempty(app.Im_SelectedImage{2}))
                    if (size( app.Im_SelectedImage{1},3)==3)
                        app.Im_SelectedImage{2}=imadjust(app.Im_SelectedImage{1},...
                            [0,0,0;1,1,1],[]);
                    else
                        app.Im_SelectedImage{2}=imadjust(app.Im_SelectedImage{1});
                    end
                end
                imshow(app.Im_SelectedImage{2},'Parent', app.Im_TG_SelectedImage);
            else
                imshow(app.Im_SelectedImage{1},'Parent', app.Im_TG_SelectedImage);
            end
            title(app.CurrentFile,'Parent', app.Im_TG_SelectedImage);
        end

        % Menu selected function: Fi_MM_SaveImage
        function Fi_MM_SaveImageMenuSelected(app, event)
            imsave(app.Im_TG_SelectedImage);
        end

        % Menu selected function: StCU_MMM_ImageProcessing
        function StCU_MMM_ImageProcessingMenuSelected(app, event)
            if (length(app.StCU_MMM_ImageProcessing.Checked)==3)
                try
                    PrintState(app,'Activating CUDA...');
                    app.Im_SelectedImage=cellfun(@gpuArray,app.Im_SelectedImage,'UniformOutput',false);
                    app.StCU_MMM_ImageProcessing.Checked='on';
                    uialert(app.U_Main,{'CUDA is Speeding up the operations.'},...
                        'CUDA being used','Icon','success');
                    PrintState(app,'Ready.');
                catch
                    app.StCU_MMM_ImageProcessing.Checked='off';
                    uialert(app.U_Main,{'CUDA is not available.';...
                        'Switched back to the no CUDA setting.'},...
                        'CUDA not available','Icon','warning');
                    PrintState(app,'Ready.');
                end
                
            else
                PrintState(app,'Stopping CUDA...');
                app.Im_SelectedImage=cellfun(@gather,app.Im_SelectedImage,'UniformOutput',false);
                app.StCU_MMM_ImageProcessing.Checked='off';
                uialert(app.U_Main,{'Stop using CUDA successfully.'},...
                    'CUDA stop being used','Icon','success');
                PrintState(app,'Ready.');
            end
        end

        % Value changed function: ImFi_TPC_TrackNew
        function ImFi_TPC_TrackNewValueChanged(app, event)
            if(app.ImFi_TPC_TrackNew.Value)
                app.Im_FileWatcher.Path=app.FileDirectory;
                app.Im_FileWatcher.EnableRaisingEvents=true;
            else
                app.Im_FileWatcher.EnableRaisingEvents=false;
            end
        end

        % Value changed function: Im_TC_RemoveBackground
        function Im_TC_RemoveBackgroundValueChanged(app, event)
            try
                app.ImFi_TPL_FileListBox.Value=app.CurrentFile;
            catch
            end
            ImFi_TPL_FileListBoxValueChanged(app);
        end

        % Button pushed function: ImBa_TPB_SelectBackground
        function ImBa_TPB_SelectBackgroundButtonPushed(app, event)
            try
                [tempfile,tempdir]=uigetfile(app.FileDirectory,'filter','*.*');
                figure(app.U_Main);
                if (tempdir~=0)
                    app.Im_SelectedImage{4}=ReadImage(app,[tempdir,'\',tempfile]);
                    imshow(app.Im_SelectedImage{4},'Parent',app.ImBa_TPG_BackgroundImage);
                end
            catch
                uialert(app.U_Main,{'Cannot read this file as the background.'},...
                    'Invalid file','Icon','warning');
            end
        end

        % Button pushed function: AtFi_TPB_CopyAddress
        function AtFi_TPB_CopyAddressButtonPushed(app, event)
            if (isempty(app.AtFi_TPF_AddressField.Value))
                uialert(app.U_Main,{'The address field is empty.'},...
                    'Copy address','Icon','warning');
            else
                clipboard('copy',app.AtFi_TPF_AddressField.Value);
                uialert(app.U_Main,{'Current address copied:';app.AtFi_TPF_AddressField.Value},...
                    'Copy address','Icon','success');
            end
        end

        % Button pushed function: AtFi_TPB_BrowseFiles
        function AtFi_TPB_BrowseFilesButtonPushed(app, event)
            % Get directory
            tempdir=uigetdir(app.InitialWorkingDirectory);
            % Bring the window back to the front
            figure(app.U_Main);
            
            % Display directory to the "Select Folder" edit field
            if (tempdir==0)
                app.AtFi_TPF_AddressField.Value=app.InitialWorkingDirectory;
            else
                app.AtFi_TPF_AddressField.Value=tempdir;
            end
            AtFi_TPF_AddressFieldValueChanged(app);
        end

        % Value changed function: AtFi_TPF_AddressField
        function AtFi_TPF_AddressFieldValueChanged(app, event)
            app.At_FileDirectory=app.AtFi_TPF_AddressField.Value;
            app.AtFi_TPA_FileTable.Enable='on';
            tempdir=dir(app.At_FileDirectory);
            tempdir=struct2cell(tempdir(~cellfun(@(x) x,{tempdir.isdir})));
            app.AtFi_TPA_FileTable.Data=table...
                (tempdir(1,:)',repmat({' '},size(tempdir,2),1));
            app.At_FileWatcher.Path=app.At_FileDirectory;
            app.At_FileWatcher.EnableRaisingEvents=true;
            app.At_ReferenceTime=datetime;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.U_Main = uifigure;
            app.U_Main.Name = 'Actuator Control';
            app.U_Main.CloseRequestFcn = createCallbackFcn(app, @U_MainCloseRequest, true);
            app.M_File = uimenu(app.U_Main);
            app.M_File.Text = 'File';
            app.Fi_MM_SaveSettings = uimenu(app.M_File);
            app.Fi_MM_SaveSettings.Text = 'Save Settings';
            app.Fi_MM_LoadSettings = uimenu(app.M_File);
            app.Fi_MM_LoadSettings.Text = 'Load Settings';
            app.Fi_MM_SaveImage = uimenu(app.M_File);
            app.Fi_MM_SaveImage.MenuSelectedFcn = createCallbackFcn(app, @Fi_MM_SaveImageMenuSelected, true);
            app.Fi_MM_SaveImage.Separator = 'on';
            app.Fi_MM_SaveImage.Text = 'Save Image';
            app.Fi_MM_Exit = uimenu(app.M_File);
            app.Fi_MM_Exit.MenuSelectedFcn = createCallbackFcn(app, @U_MainCloseRequest, true);
            app.Fi_MM_Exit.Separator = 'on';
            app.Fi_MM_Exit.Text = 'Exit';
            app.M_StSettings = uimenu(app.U_Main);
            app.M_StSettings.Text = 'Settings';
            app.St_MM_CUDAMenu = uimenu(app.M_StSettings);
            app.St_MM_CUDAMenu.Text = 'CUDA';
            app.StCU_MMM_ImageProcessing = uimenu(app.St_MM_CUDAMenu);
            app.StCU_MMM_ImageProcessing.MenuSelectedFcn = createCallbackFcn(app, @StCU_MMM_ImageProcessingMenuSelected, true);
            app.StCU_MMM_ImageProcessing.Text = 'Image Processing';
            app.StCU_MMM_ParameterEstimation = uimenu(app.St_MM_CUDAMenu);
            app.StCU_MMM_ParameterEstimation.Checked = 'on';
            app.StCU_MMM_ParameterEstimation.Text = 'Parameter Estimation';
            app.StCU_MMM_OED = uimenu(app.St_MM_CUDAMenu);
            app.StCU_MMM_OED.Checked = 'on';
            app.StCU_MMM_OED.Text = 'OED';
            app.M_Help = uimenu(app.U_Main);
            app.M_Help.Text = 'Help';
            app.He_MM_Menu = uimenu(app.M_Help);
            app.He_MM_Menu.Text = 'Menu';
            app.He_MM_About = uimenu(app.M_Help);
            app.He_MM_About.Text = 'About';
            app.TabGroup = uitabgroup(app.U_Main);
            app.ModelTab = uitab(app.TabGroup);
            app.ModelTab.Title = 'Model';
            app.OEDTab = uitab(app.TabGroup);
            app.OEDTab.Title = 'OED';
            app.ActuationSystemTab = uitab(app.TabGroup);
            app.ActuationSystemTab.Title = 'Actuation System';
            app.T_ImageProcessing = uitab(app.TabGroup);
            app.T_ImageProcessing.Title = 'Image Processing';
            app.Im_TG_SelectedImage = uiaxes(app.T_ImageProcessing);
            title(app.Im_TG_SelectedImage, 'Current Image')
            app.Im_TP_Files = uipanel(app.T_ImageProcessing);
            app.Im_TP_Files.Title = 'Files';
            app.ImFi_TPB_BrowseFiles = uibutton(app.Im_TP_Files, 'push');
            app.ImFi_TPB_BrowseFiles.ButtonPushedFcn = createCallbackFcn(app, @ImFi_TPB_BrowseFilesPushed, true);
            app.ImFi_TPB_BrowseFiles.Text = 'Browse Files';
            app.ImFi_TPB_CopyAddress = uibutton(app.Im_TP_Files, 'push');
            app.ImFi_TPB_CopyAddress.ButtonPushedFcn = createCallbackFcn(app, @ImFi_TPB_CopyAddressPushed, true);
            app.ImFi_TPB_CopyAddress.Text = 'Copy Address';
            app.ImFi_TPC_TrackNew = uicheckbox(app.Im_TP_Files);
            app.ImFi_TPC_TrackNew.ValueChangedFcn = createCallbackFcn(app, @ImFi_TPC_TrackNewValueChanged, true);
            app.ImFi_TPC_TrackNew.Text = 'Track New Images';
            app.ImFi_TPL_FileListBox = uilistbox(app.Im_TP_Files);
            app.ImFi_TPL_FileListBox.Items = {};
            app.ImFi_TPL_FileListBox.ValueChangedFcn = createCallbackFcn(app, @ImFi_TPL_FileListBoxValueChanged, true);
            app.ImFi_TPL_FileListBox.Enable = 'off';
            app.ImFi_TPL_FileListBox.Value = {};
            app.AddressEditFieldLabel = uilabel(app.Im_TP_Files);
            app.AddressEditFieldLabel.Text = 'Address:';
            app.ImFi_TPF_AddressField = uieditfield(app.Im_TP_Files, 'text');
            app.ImFi_TPF_AddressField.ValueChangedFcn = createCallbackFcn(app, @ImFi_TPF_AddressFieldValueChanged, true);
            app.Im_TC_QuickAdjust = uicheckbox(app.T_ImageProcessing);
            app.Im_TC_QuickAdjust.ValueChangedFcn = createCallbackFcn(app, @Im_TC_QuickAdjustValueChanged, true);
            app.Im_TC_QuickAdjust.Text = 'Quick adjust';
            app.Im_TC_QuickAdjust.Value = true;
            app.debugareaTextAreaLabel = uilabel(app.T_ImageProcessing);
            app.debugareaTextAreaLabel.Text = 'debug area';
            app.DB_debugareaTextArea = uitextarea(app.T_ImageProcessing);
            app.Im_TP_Background = uipanel(app.T_ImageProcessing);
            app.Im_TP_Background.Title = 'Background';
            app.ImBa_TPG_BackgroundImage = uiaxes(app.Im_TP_Background);
            title(app.ImBa_TPG_BackgroundImage, 'Background')
            app.ImBa_TPA_FileTypeTable = uitable(app.Im_TP_Background);
            app.ImBa_TPA_FileTypeTable.ColumnName = {'Type'; 'Key Word'};
            app.ImBa_TPA_FileTypeTable.RowName = {};
            app.ImBa_TPA_FileTypeTable.ColumnEditable = true;
            app.ImBa_TPB_SelectBackground = uibutton(app.Im_TP_Background, 'push');
            app.ImBa_TPB_SelectBackground.ButtonPushedFcn = createCallbackFcn(app, @ImBa_TPB_SelectBackgroundButtonPushed, true);
            app.ImBa_TPB_SelectBackground.Text = 'Select Background';
            app.ImBa_TPB_AddType = uibutton(app.Im_TP_Background, 'push');
            app.ImBa_TPB_AddType.Text = 'Add';
            app.ImBa_TPB_DeleteType = uibutton(app.Im_TP_Background, 'push');
            app.ImBa_TPB_DeleteType.Text = 'Delete';
            app.Im_TC_RemoveBackground = uicheckbox(app.T_ImageProcessing);
            app.Im_TC_RemoveBackground.ValueChangedFcn = createCallbackFcn(app, @Im_TC_RemoveBackgroundValueChanged, true);
            app.Im_TC_RemoveBackground.Text = 'Remove Background';
            app.ParameterEstimationTab = uitab(app.TabGroup);
            app.ParameterEstimationTab.Title = 'Parameter Estimation';
            app.FilesPanel_2 = uipanel(app.ParameterEstimationTab);
            app.FilesPanel_2.Title = 'Files';
            app.UITable = uitable(app.FilesPanel_2);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.AutomaticInferenceTab = uitab(app.TabGroup);
            app.AutomaticInferenceTab.Title = 'Automatic Inference';
            app.At_TP_Files = uipanel(app.AutomaticInferenceTab);
            app.At_TP_Files.Title = 'Files';
            app.AtFi_TPB_BrowseFiles = uibutton(app.At_TP_Files, 'push');
            app.AtFi_TPB_BrowseFiles.ButtonPushedFcn = createCallbackFcn(app, @AtFi_TPB_BrowseFilesButtonPushed, true);
            app.AtFi_TPB_BrowseFiles.Text = 'Browse Folder';
            app.AtFi_TPB_CopyAddress = uibutton(app.At_TP_Files, 'push');
            app.AtFi_TPB_CopyAddress.ButtonPushedFcn = createCallbackFcn(app, @AtFi_TPB_CopyAddressButtonPushed, true);
            app.AtFi_TPB_CopyAddress.Text = 'Copy Address';
            app.AddressEditFieldLabel_2 = uilabel(app.At_TP_Files);
            app.AddressEditFieldLabel_2.Text = 'Address:';
            app.AtFi_TPF_AddressField = uieditfield(app.At_TP_Files, 'text');
            app.AtFi_TPF_AddressField.ValueChangedFcn = createCallbackFcn(app, @AtFi_TPF_AddressFieldValueChanged, true);
            app.AtFi_TPA_FileTable = uitable(app.At_TP_Files);
            app.AtFi_TPA_FileTable.ColumnName = {'File Name'; 'Time Created'};
            app.AtFi_TPA_FileTable.RowName = {};
            app.AtFi_TPA_FileTable.Enable = 'off';
            app.At_TP_Input = uipanel(app.AutomaticInferenceTab);
            app.At_TP_Input.Title = 'Input';
            app.AtIn_TPG_InputRecord = uiaxes(app.At_TP_Input);
            title(app.AtIn_TPG_InputRecord, 'Title')
            xlabel(app.AtIn_TPG_InputRecord, 'X')
            ylabel(app.AtIn_TPG_InputRecord, 'Y')
            app.At_TG_CurrentImage = uiaxes(app.AutomaticInferenceTab);
            title(app.At_TG_CurrentImage, 'Current Image')
            app.At_TA_CurrentMeasuredData = uitable(app.AutomaticInferenceTab);
            app.At_TA_CurrentMeasuredData.ColumnName = {'Name'; 'Value'};
            app.At_TA_CurrentMeasuredData.RowName = {};
            app.At_TB_StartExperiment = uibutton(app.AutomaticInferenceTab, 'state');
            app.At_TB_StartExperiment.Text = 'Start the Experiment';
            app.At_TI_ExperimentState = uilamp(app.AutomaticInferenceTab);
            app.At_TC_QuickAdjust = uicheckbox(app.AutomaticInferenceTab);
            app.At_TC_QuickAdjust.Text = 'Quick adjust';
            app.At_TC_QuickAdjust.Value = true;
            app.X_StateText = uitextarea(app.U_Main);
            app.X_StateText.Editable = 'off';
            app.X_StateText.Value = {'Ready.'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Image_Processing_V1_2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.U_Main)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.U_Main)
        end
    end
end