classdef Actuator_Control_V3_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ActuatorControlUIFigure         matlab.ui.Figure
        ConfigPanel                     matlab.ui.container.Panel
        COMPortEditFieldLabel           matlab.ui.control.Label
        COMPort                         matlab.ui.control.EditField
        ConnectandInitializeallaxesButton  matlab.ui.control.Button
        NumberofAxesEditFieldLabel      matlab.ui.control.Label
        NumberofAxes                    matlab.ui.control.NumericEditField
        MaxSpeedmmsEditFieldLabel       matlab.ui.control.Label
        MaxSpeedEditField               matlab.ui.control.NumericEditField
        CurrentPositionmmEditFieldLabel  matlab.ui.control.Label
        CurrentPositionmmEditField      matlab.ui.control.NumericEditField
        AxisSelectionListBoxLabel       matlab.ui.control.Label
        AxisSelectionListBox            matlab.ui.control.ListBox
        AutoRunPanel                    matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        CustomFileTab                   matlab.ui.container.Tab
        LoadTracetoAxesButton           matlab.ui.control.Button
        EraseCurrentTraceButton         matlab.ui.control.Button
        StartAxesButton                 matlab.ui.control.Button
        StopAxesButton                  matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        SelectFileListBoxLabel          matlab.ui.control.Label
        SelectFileListBox               matlab.ui.control.ListBox
        BrowseButton                    matlab.ui.control.Button
        SelectFolderEditFieldLabel      matlab.ui.control.Label
        SelectFolderEditField           matlab.ui.control.EditField
        DrawAxis2CheckBox               matlab.ui.control.CheckBox
        CommonfunctionsTab              matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        SavetraceforcurrentaxisButton   matlab.ui.control.Button
        ErasetraceforcurrentaxisButton  matlab.ui.control.Button
        StartAllAxesButton              matlab.ui.control.Button
        StopAllAxesButton               matlab.ui.control.Button
        WaveformDropDownLabel           matlab.ui.control.Label
        WaveformDropDown                matlab.ui.control.DropDown
        PeakPositionSliderLabel         matlab.ui.control.Label
        PeakPositionSlider              matlab.ui.control.Slider
        CenterlevelmmSpinnerLabel       matlab.ui.control.Label
        CenterlevelmmSpinner            matlab.ui.control.Spinner
        AmplitudemmSpinnerLabel         matlab.ui.control.Label
        AmplitudemmSpinner              matlab.ui.control.Spinner
        PeriodsSpinnerLabel             matlab.ui.control.Label
        PeriodsSpinner                  matlab.ui.control.Spinner
        DurationsSpinnerLabel           matlab.ui.control.Label
        DurationsSpinner                matlab.ui.control.Spinner
        PhasebiasdegSpinnerLabel        matlab.ui.control.Label
        PhasebiasdegSpinner             matlab.ui.control.Spinner
        ManualControlPanel              matlab.ui.container.Panel
        GoButton                        matlab.ui.control.Button
        InchButtonPlus                  matlab.ui.control.Button
        InchButtonMinus                 matlab.ui.control.Button
        BalancedPositionmmLabel         matlab.ui.control.Label
        TargetPosition                  matlab.ui.control.NumericEditField
        InchDistancemmSliderLabel       matlab.ui.control.Label
        InchDistancemmSlider            matlab.ui.control.Slider
        StatusTextAreaLabel             matlab.ui.control.Label
        StatusTextArea                  matlab.ui.control.TextArea
    end

    
    properties (Access = public)
    end
    
    properties (Access = private)
        Directory                   % working directory
        Controllers                 % acturation system controller
        BalancedPosition            % position which the fluxes from two syringes are equal
        Custom_File_Trace_Vector    % input design from the user
        Autorun_Trace_Cell          % positions for each time and each axis
        Time_Gap                    % step length of the input
        DrawAxis2                   % draw the second axis or not
        CurrentFile                 % Description
        fileObj                     % Description
    end
    
    methods (Access = public)
        %--------------------------------------------------------------------------------%
        % This function write to the Control bit coil, refer to manual.
        % 2 input, (ON/OFF,Axis)
        % ON/OFF: 1 for MODBUS Control Enable, 0 for disable.
        
        function Set_Control_Bit(app,a,Axis)
            write(app.Controllers,'coils',1064,a,Axis); % Set control bit for given axis number
        end
        
        %--------------------------------------------------------------------------------%
        
        % This function write to the Alarm Reset coil, refer to manual.
        % 1 input (Axis)
        % This function writes to this coil, 0, 1, 0 sequentially, to create an
        % rising edge that reset the alarm.
        
        function Alarm_Reset(app,Axis)
            % Alarm Reset for given axis
            write(app.Controllers,'coils',1032,0,Axis);
            write(app.Controllers,'coils',1032,1,Axis);
            write(app.Controllers,'coils',1032,0,Axis);
        end
        %--------------------------------------------------------------------------------%
        % This function write to the Home Return coil, refer to manual.
        % 1 input (Axis)
        % This function writes to this coil, 0, 1, 0 sequentially, to create an
        % rising edge that homes the axis.
        
        function Home_Return(app,Axis)
            % Home given axis
            write(app.Controllers,'coils',1036,0,Axis);
            write(app.Controllers,'coils',1036,1,Axis);
            write(app.Controllers,'coils',1036,0,Axis);
        end
        
        %--------------------------------------------------------------------------------%
        
        % This function write to the Servo ON/Off coil, refer to manual.
        % 2 inputs (ON_OFF,Axis)
        % ON_OFF: 1 for Servo On, 0 for Off.
        
        function Servo_ON_OFF(app,a,Axis)
            write(app.Controllers,'coils',1028,a,Axis); % Set servo On/off for given axis
        end
        
        %--------------------------------------------------------------------------------%
        
        % This function convert a single decimal value to two separate decimal
        % values, so as to store in 2 16-bits registers.
        % 1 input, 2 output, both in decimal.
        
        function [reg_Hi,reg_Lo]=dec_to_regs2(app,Original)
            
            Original_Hex = dec2hex(Original,8); % Convert decimal input a into hex string with 8 digits
            
            reg_Hi_Hex = Original_Hex(1:4); % Hi register equals to the first 4 digit of Original_Hex
            reg_Hi = hex2dec(reg_Hi_Hex); % Convert hex value back to decimal for Hi reg
            
            reg_Lo_Hex = Original_Hex(5:8); % Lo register equals to the last 4 digit of Original_Hex
            reg_Lo = hex2dec(reg_Lo_Hex); % Convert hex value back to decimal for Lo reg
            
        end
        
        %--------------------------------------------------------------------------------%
        
        % This function convert two decimal values stored separately in 2 16-bit
        % registers to a single decimal value
        % 2 input, 1 output, both in decimal.
        
        function Decimal_Value=regs2_to_dec(app,reg_Hi,reg_Lo)
            reg_Hi_Hex = dec2hex(reg_Hi); % Convert the Hi register input into Hex value
            reg_Hi_Hex = pad(reg_Hi_Hex,4,'left','0'); % Pad zeros in front of Hex value to form 4 digit
            
            reg_Lo_Hex = dec2hex(reg_Lo); % Convert the Lo register input into Hex value
            reg_Lo_Hex = pad(reg_Lo_Hex,4,'left','0'); % Pad zeros in front of Hex value to form 4 digit
            
            % Combine reg_Hi_Hex, reg_Lo_Hex together to form a single Hex value
            Decimal_Value_Hex = [reg_Hi_Hex,reg_Lo_Hex];
            
            % Convert the final Hex value to decimal
            Decimal_Value = hex2dec(Decimal_Value_Hex);
        end
        
        %--------------------------------------------------------------------------------%
        % This function read the "Current Position (PNOW)"
        % registers, refer to manual.
        % Input the axis number. Output is a single decimal value unit 0.01mm.
        %
        % A correction method was added because sometimes when at home position (0mm),
        % Current_Position would be read as 400k+ (some very large number)
        % If Current_Position falls out resonable range Current_Position=0
        
        function Current_Position=Read_Current_Position(app,Axis_Number)
            % Read current position from PNOW registers into two decimal values, for
            % given axis number
            Position = read(app.Controllers,'holdingregs',36865,2,Axis_Number);
            
            % Convert 2 decimal values into 1 single decimal value
            Current_Position=regs2_to_dec(app,Position(1),Position(2));
            
            % This was added because sometimes when at home position (0mm),
            % Current_Position would be read as 400k+ (some very large number)
            % If Current_Position falls out resonable range Current_Position=0
            if ((Current_Position>200000) || (Current_Position<0))
                Current_Position=0;
            end
            
        end
        %--------------------------------------------------------------------------------%
        
        % This function will read and display current position to CurrentPositionEditField
        % every 0.5s for "Current_Axis" until PEND for "Current_Axis" is reached.
        
        function Read_Display_Current_Position_To_EditField(app,Current_Axis)
            while (1)
                % Read current position from actuators and display it to the edit field
                Current_Position=Read_Current_Position(app,Current_Axis);
                app.CurrentPositionmmEditField.Value=Current_Position/100; % Display unit in mm
                
                
                % read position compelete status from current axis
                PEND=read(app.Controllers,'coils',269,1,Current_Axis);
                if (PEND==1)
                    break;
                end
                
                pause(0.5); % Pause 0.5s between each cycle
            end
        end
        
        %--------------------------------------------------------------------------------%
        
        % This function write to the "Target Position Specification (PCMD)"
        % registers, refer to manual.
        % Input the target position in a single decimal value, and axis number actuators will start to move
        % immediately after the execution of this funciton.
        
        function Write_Target_Position(app,Target_Position,Axis_Number)
            % Convert the input target position value to 2 values so as to write in 2 16-bit
            % registers
            [Target_Position_reg_Hi , Target_Position_reg_Lo] = dec_to_regs2(app,Target_Position);
            
            % write the data to registers for given axis number
            write(app.Controllers,'holdingregs',39169,[Target_Position_reg_Hi,Target_Position_reg_Lo],Axis_Number);
            
        end
        
        %--------------------------------------------------------------------------------%
        
        % This function write to the "Acceleration/Deceleration Specification Register (ACMD)"
        % refer to manual.
        % Input the speed in a single decimal value unit 0.01G. And Axis number.
        
        function Write_Acc(app,Acceleration,Axis_Number)
            % write the data to registers for given axis number
            write(app.Controllers,'holdingregs',39175,Acceleration,Axis_Number);
            
        end
        
        %--------------------------------------------------------------------------------%
        
        % This function write to the "Speed Specification Register (VCMD)"
        % refer to manual.
        % Input the speed in a single decimal value unit 0.01mm/s. And Axis number
        
        function Write_Speed(app,Speed,Axis_Number)
            % Convert the input speed value to 2 values so as to write in 2 16-bit
            % registers
            [Speed_reg_Hi , Speed_reg_Lo] = dec_to_regs2(app,Speed);
            
            % write the data to registers for given axis number
            write(app.Controllers,'holdingregs',39173,[Speed_reg_Hi,Speed_reg_Lo],Axis_Number);
            
        end
        
        %--------------------------------------------------------------------------------%
        % Set up and initial the axis.
        % 1 input (total attached axis number)
        % Turn on Control bit, then turn on servo, then home all axis.
        % This function will now wait for all axes to home before jump out
        
        function Initial_Axis(app,Axis_Number)
            global Current_Axis
            
            % Set Control Bit ON (Enable MODBUS control) for all axis
            for i=1:Axis_Number
                Set_Control_Bit(app,1,i);
            end
            pause(0.2); % Actuators need some time between the rising edges, refer to IAI manual
            
            % Reset Alarms, in case any alarm were present. So that subsequent operations can be performed
            for i=1:Axis_Number
                Alarm_Reset(app,i);
            end
            pause(0.2); % Actuators need some time between the rising edges, refer to IAI manual
            
            % Turn on the Servo for all axis
            for i=1:Axis_Number
                Servo_ON_OFF(app,1,i);
                pause(0.2); % Give it a gap between servo turn on
            end
            pause(0.5); % Actuators need some time between the rising edges, refer to IAI manual
            
            % Home return all axis
            for i=1:Axis_Number
                Home_Return(app,i);
            end
            
            app.StatusTextArea.Value='Done, waiting for axis to home...'; % Display Status
            
            % check home return status
            HEND=zeros(1,Axis_Number);
            while (1)
                for i=1:Axis_Number
                    HEND(i)=read(app.Controllers,'coils',268,1,i); % read home return status from all axis
                end
                
                % Read and display current position
                Current_Position=Read_Current_Position(app,Current_Axis);
                app.CurrentPositionmmEditField.Value=Current_Position/100;
                
                % pause 0.3s between each read cycle. Also after the last cycle
                pause(0.3);
                
                %jump out of the loop once all axis have homed
                if (all(HEND)==1)
                    break;
                end
                
            end
            
            app.StatusTextArea.Value='All axes home complete!'; % Display Status
        end
        
        %--------------------------------------------------------------------------------%
        % redraw the input pattern. drawcase: 0 design only,1 design + real time
        
        function RedrawInput(app, drawcase)
            
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear;
            clear global;
            
            % declearation
            global Target_Position Current_Axis
            Target_Position=0;
            % Set Current_Axis default value to 1
            Current_Axis=1;
            app.CurrentAxisEditField.Value=Current_Axis;
        end

        % Button pushed function: InchButtonPlus
        function InchButtonPlusPushed(app, event)
            global Target_Position Current_Axis
            
            % inch distance cannot have decimal part
            Inch_Distance=round(app.InchDistancemmSlider.Value*100);
            
            % Add try catch structure here to solve the problem if pressing inch too
            % quickly, actuators may not respond correct CRC, but it will still work
            try
                Current_Position=Read_Current_Position(app,Current_Axis);
                
                Target_Position=Current_Position+Inch_Distance;
                if (Target_Position > 80000)
                    Target_Position=80000;
                end
                %app.TargetPosition.Value=Target_Position;
                
                Write_Target_Position(app,Target_Position,Current_Axis);
            catch
            end
            
            Read_Display_Current_Position_To_EditField(app,Current_Axis);
        end

        % Button pushed function: InchButtonMinus
        function InchButtonMinusPushed(app, event)
            global Target_Position Current_Axis
            
            % inch distance cannot have decimal part
            Inch_Distance=round(app.InchDistancemmSlider.Value*100);
            
            % Add try catch structure here to solve the problem if pressing inch too
            % quickly, actuators may not respond correct CRC, but it will still work
            try
                Current_Position=Read_Current_Position(app,Current_Axis);
                
                Target_Position=Current_Position-Inch_Distance;
                if (Target_Position < 0)
                    Target_Position=0;
                end
                %app.TargetPosition.Value=Target_Position;
                
                Write_Target_Position(app,Target_Position,Current_Axis);
            catch
            end
            
            Read_Display_Current_Position_To_EditField(app,Current_Axis);
        end

        % Button pushed function: ConnectandInitializeallaxesButton
        function ConnectInitializeallaxesButtonPushed(app, event)
            global COM_Port Axis_Number
            
            COM_Port=app.COMPort.Value;          % Write COM port to global varible
            Axis_Number=app.NumberofAxes.Value;  % Write Axis number to global varible
            Preset_Speed=app.MaxSpeedEditField.Value*100; % Read Preset Speed value
            
            
            try
                % Connect to actuators
                Baudrate = 38400; % Set it to the value the actuators is using
                % Create MODBUS object for actuators and connect to it
                
                app.Controllers = modbus('serialrtu',COM_Port,'BaudRate',Baudrate);
                
                % Initialize all axes
                Initial_Axis(app,Axis_Number);
                
                pause(0.3);
                
                % Preset speed
                app.StatusTextArea.Value=sprintf('Preseting speed to %d mm/s',Preset_Speed/100); % Display Status
                for i=1:Axis_Number
                    Write_Speed(app,Preset_Speed,i);
                end
                pause(0.3)
                app.StatusTextArea.Value='Done!'; % Display Status
                pause(0.3)
                app.StatusTextArea.Value='Ready!'; % Display Status
                
                % Set the Axis Selection Drop Down items, adaptive to Axis_Number
                Items_Cell_Array=cell(1,Axis_Number); % Preallocation
                Items_Data_Array =zeros(1,Axis_Number); % Preallocation
                for i=1:Axis_Number
                    Items_Cell_Array{i}=sprintf('Axis %d',i);
                    Items_Data_Array(i)=i;
                end
                app.AxisSelectionListBox.Items     = Items_Cell_Array;
                app.AxisSelectionListBox.ItemsData = Items_Data_Array;
                app.AxisSelectionListBox.Value     = 1; % Set default to select Axis 1


                % Show Status Textarea
                app.StatusTextArea.Visible='on';
                app.StatusTextAreaLabel.Visible='on';
                delete(app.ConfigPanel);             % Get the config panel out of the way
            catch
                uialert(app.ActuatorControlUIFigure,'The device is not connected!','Cannot Initialize');
            end
        end

        % Value changed function: AxisSelectionListBox
        function AxisSelectionListBoxValueChanged(app, event)
            global Current_Axis
            Current_Axis = app.AxisSelectionListBox.Value;
            
            Read_Display_Current_Position_To_EditField(app,Current_Axis);
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            global Target_Position Current_Axis
            if (length(app.StatusTextAreaLabel.Visible)==2)
                
                app.BalancedPosition=100*app.TargetPosition.Value;
                Target_Position=app.TargetPosition.Value*100; % Input unit in mm
                % Write to the selected axis target position registers
                Write_Target_Position(app,app.BalancedPosition,1);
                Write_Target_Position(app,app.BalancedPosition,2);
                
                Read_Display_Current_Position_To_EditField(app,Current_Axis);
            else
                uialert(app.ActuatorControlUIFigure,'The device has not been initialized!','Invalid Command','Icon','warning');
            end
            
        end

        % Callback function: AmplitudemmSpinner, 
        % CenterlevelmmSpinner, DurationsSpinner, PeakPositionSlider, 
        % PeriodsSpinner, PhasebiasdegSpinner, WaveformDropDown
        function WaveformSpinnerValueChanged(app, event)
            % Note: All Wave form related spinner callbacks use this callback
            
            global Common_Functions_Trace_Vector
            Center_Level= app.CenterlevelmmSpinner.Value*100;
            Amplitude   = app.AmplitudemmSpinner.Value*100;
            Period      = app.PeriodsSpinner.Value;
            Phase_Bias  = app.PhasebiasdegSpinner.Value;
            Duration    = app.DurationsSpinner.Value;
            Wave_Form   = app.WaveformDropDown.Value;
            
            switch (Wave_Form)
                case {'Sin'}
                    app.PeakPositionSlider.Visible='off';
                    app.PeakPositionSliderLabel.Visible='off';
                    % Common_Functions_Trace_Vector is actually longer than 3 cycle
                    x=0:Duration;
                    Common_Functions_Trace_Vector=round(Center_Level+Amplitude*sin((2*pi/Period)*x+(Phase_Bias/360)*2*pi));
                    
                    % Show wave forms of 3 cycle
                    x_Disp=0:3*Period;
                    y=Center_Level/100+(Amplitude/100)*sin((2*pi/Period)*x_Disp+(Phase_Bias/360)*2*pi);
                case {'Triangle'}
                    app.PeakPositionSlider.Visible='on';
                    app.PeakPositionSliderLabel.Visible='on';
                    %app.PeakPositionSliderLabel='Peak Position';
                    Peak_Position=app.PeakPositionSlider.Value;
                    % Common_Functions_Trace_Vector is actually longer than 3 cycle
                    x=0:Duration;
                    Common_Functions_Trace_Vector=round(Center_Level+Amplitude*sawtooth((2*pi/Period)*x+(Phase_Bias/360)*2*pi,Peak_Position));
                    
                    % Show wave forms of 3 cycle
                    x_Disp=0:3*Period;
                    y=Center_Level/100+(Amplitude/100)*sawtooth((2*pi/Period)*x_Disp+(Phase_Bias/360)*2*pi,Peak_Position);
                case {'Square'}
                    app.PeakPositionSlider.Visible='on';
                    app.PeakPositionSliderLabel.Visible='on';
                    %app.PeakPositionSliderLabel='Duty Ratio';
                    Duty_Ratio=app.PeakPositionSlider.Value;
                    % Common_Functions_Trace_Vector is actually longer than 3 cycle
                    x=0:Duration;
                    Common_Functions_Trace_Vector=round(Center_Level+Amplitude*square((2*pi/Period)*x+(Phase_Bias/360)*2*pi,100*Duty_Ratio));
                    
                    % Show wave forms of 3 cycle
                    x_Disp=0:3*Period;
                    y=Center_Level/100+(Amplitude/100)*square((2*pi/Period)*x_Disp+(Phase_Bias/360)*2*pi,100*Duty_Ratio);
                otherwise
                    app.PeakPositionSlider.Visible='off';
                    app.PeakPositionSliderLabel.Visible='off';
                    % Common_Functions_Trace_Vector is actually longer than 3 cycle
                    x=0:Duration;
                    Common_Functions_Trace_Vector=Center_Level+0*x;
                    
                    % Show wave forms of 3 cycle
                    x_Disp=0:3*Period;
                    y=Center_Level/100+0*x_Disp;
                    
            end
            
            plot(app.UIAxes,x_Disp,y); % Show wave forms of 3 cycle.
        end

        % Button pushed function: StartAllAxesButton, StartAxesButton
        function StartAllAxesButtonPushed(app, event)
            global Axis_Number Auto_Run_Stop
            
            Auto_Run_Stop=0; % Reset stop sign
            Axis_Number=2; %%%%
            % All axes go to start position
            for i=1:Axis_Number
                Write_Target_Position(app,app.Autorun_Trace_Cell{i}(1),i);
            end
            
            % Wait for all axes to be in start position
            PEND=zeros(1,Axis_Number);
            while (true)
                % read position compelete status from all axes
                for i=1:Axis_Number
                    PEND(i)=read(app.Controllers,'coils',269,1,i);
                end
                
                pause(0.2);
                
                % If all in position, break and continue
                if (all(PEND)==1)
                    break;
                end
            end
            app.StatusTextArea.Value='All axes at starting position!'; % Display Status
            
            % Start to run by waveforms
            % Determine the longest element in Autorun_Trace_Cell
            L_Max=0;
            for i=1:Axis_Number
                L=length(app.Autorun_Trace_Cell{i});
                if (L>L_Max)
                    L_Max=L;
                end
            end
            
            pause(0.2);
            
            Time=app.Time_Gap*[0,...
                repelem(1:length(app.Custom_File_Trace_Vector)-1,1,2),...
                length(app.Custom_File_Trace_Vector)];
            local_Autorun_Trace_Cell=...
                {repelem(app.Autorun_Trace_Cell{1}/100,1,2),...
                repelem(app.Autorun_Trace_Cell{2}/100,1,2)};
            
            Title=app.UIAxes_2.Title.String;
            
            app.StatusTextArea.Value={'Running!','Please do Not use Manual control when axes are moving'}; % Display Status
            for j=1:L_Max
                % Write position to all axes
                for i=1:Axis_Number
                    % Add try catch structure, in case Autorun_Trace_Cell has elements with different length
                    try
                        Write_Target_Position(app,app.Autorun_Trace_Cell{i}(j),i);
                    catch
                    end
                end
                
                Current_Position=Read_Current_Position(app,1);
                app.CurrentPositionmmEditField.Value=Current_Position/100;
                
                if (ceil(Time(end))>0)
                    app.UIAxes_2.XLim=[0,ceil(Time(end))];
                end
                if (app.DrawAxis2)
                    plot(app.UIAxes_2,Time,local_Autorun_Trace_Cell{1},'r',...
                        Time,local_Autorun_Trace_Cell{2},'b');
                    hold(app.UIAxes_2,'on');
                    plot(app.UIAxes_2,Time(1:2*j),local_Autorun_Trace_Cell{1}(1:2*j),'-m','LineWidth',1.5);
                    plot(app.UIAxes_2,Time(1:2*j),local_Autorun_Trace_Cell{2}(1:2*j),'-c','LineWidth',1.5);
                    plot(app.UIAxes_2,Time([2*j,2*j]),app.UIAxes_2.YLim,'--k','LineWidth',1);
                    legend(app.UIAxes_2,{'Axis 1 (u)','Axis 2','Progress 1','Progress 2'},'location','southoutside','NumColumns',2);
                    title(app.UIAxes_x,Title);
                    hold(app.UIAxes_2,'off');
                else
                    plot(app.UIAxes_2,Time,local_Autorun_Trace_Cell{1},'r');
                    hold(app.UIAxes_2,'on');
                    plot(app.UIAxes_2,Time(1:2*j),local_Autorun_Trace_Cell{1}(1:2*j),'-m','LineWidth',1.5);
                    plot(app.UIAxes_2,Time([2*j,2*j]),app.UIAxes_2.YLim,'--k','LineWidth',1);
                    legend(app.UIAxes_2,{'Axis 1 (u)','Progress'},'location','southoutside','NumColumns',2);
                    title(app.UIAxes_x,Title);
                    hold(app.UIAxes_2,'off');
                end
                
                pause(app.Time_Gap); % Actuator moves once every second
                
                if (Auto_Run_Stop==1)
                    break
                end
            end
            app.StatusTextArea.Value='Finished!'; % Display Status
            pause(0.2);
            app.StatusTextArea.Value='Ready!'; % Display Status
        end

        % Button pushed function: StopAllAxesButton, StopAxesButton
        function StopAllAxesButtonPushed(app, event)
            global Auto_Run_Stop
            Auto_Run_Stop=1;
        end

        % Button pushed function: EraseCurrentTraceButton, 
        % ErasetraceforcurrentaxisButton
        function ErasetraceforcurrentaxisButtonPushed(app, event)
            app.SelectFileListBox.Value={};
            app.Autorun_Trace_Cell={zeros,zeros};
            plot(app.UIAxes_2,0,0);
            legend(app.UIAxes_2,'off')
            title(app.UIAxes_2,'')
            app.StatusTextArea.Value=sprintf('Trace erased!'); % Display Status
        end

        % Close request function: ActuatorControlUIFigure
        function ActuatorControlUIFigureCloseRequest(app, event)
            delete(app)
            clear;
            clear global;
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            
            % Get directory
            tempdir=uigetdir();
            % Bring the window back to the front
            figure(app.ActuatorControlUIFigure);
            
            % Display directory to the "Select Folder" edit field
            if (tempdir==0)
                app.Directory=cd;
            else
                app.Directory=tempdir;
            end
            app.SelectFolderEditField.Value=app.Directory;
            
            % Display all txt file in the "Select File" List box
            % Read the list of all txt files
            Directory_Plus_txt_Only=[app.Directory,'\*.txt'];
            txt_File_List=dir(Directory_Plus_txt_Only);
            
            Items_Cell_Array=cell(1,length(txt_File_List)); % Preallocation
            % Write all txt file names to Items_Cell_Array
            for i=1:length(txt_File_List)
                Items_Cell_Array{i}=txt_File_List(i).name;
            end
            % Write Items_Cell_Array to Select File list box
            app.SelectFileListBox.Items=Items_Cell_Array;
        end

        % Value changed function: SelectFileListBox
        function SelectFileListBoxValueChanged(app, event)
            % Read the file into Custom_File_Trace_Vector
            Directory_File=[app.Directory,'\',app.SelectFileListBox.Value];
            try
                Input_Vector=dlmread(Directory_File);
                
                app.Time_Gap=Input_Vector(1);
                app.Custom_File_Trace_Vector=round(Input_Vector(2:end));
                
                % Plot selected file on UIAxes
                Time=app.Time_Gap*[0,...
                    repelem(1:length(app.Custom_File_Trace_Vector)-1,1,2),...
                    length(app.Custom_File_Trace_Vector)];
                if (isempty(app.BalancedPosition))
                    tempBalancedPosition=max(app.Custom_File_Trace_Vector)/2;
                else
                    tempBalancedPosition=app.BalancedPosition;
                end
                if (app.DrawAxis2)
                    plot(app.UIAxes_2,Time,repelem(app.Custom_File_Trace_Vector,1,2)/100,'r',...
                        Time,repelem(2*tempBalancedPosition-app.Custom_File_Trace_Vector,1,2)/100,'b');
                    legend(app.UIAxes_2,{'Axis 1 (u)','Axis 2'},'location','southoutside','NumColumns',2);
                else
                    plot(app.UIAxes_2,Time,repelem(app.Custom_File_Trace_Vector,1,2)/100,'r');
                    legend(app.UIAxes_2,'Axis 1 (u)','location','southoutside');
                end
                app.UIAxes_2.XLim=[0,ceil(Time(end))];
                title(app.UIAxes_2,app.SelectFileListBox.Value);
                app.CurrentFile=app.SelectFileListBox.Value;
            catch
            end
            app.SelectFileListBox.Value={};
        end

        % Button pushed function: LoadTracetoAxesButton
        function LoadTracetoAxesButtonPushed(app, event)
            % Save Custom_File_Trace_Vector to the "Current_Axis"th row of the Autorun_Trace_Cell
            if (isempty(app.BalancedPosition))
                app.BalancedPosition=max(app.Custom_File_Trace_Vector)/2;
            end
            app.Autorun_Trace_Cell{1}=app.Custom_File_Trace_Vector;
            app.Autorun_Trace_Cell{2}=2*app.BalancedPosition-app.Custom_File_Trace_Vector;
            app.StatusTextArea.Value=sprintf('Trace saved!'); % Display Status
        end

        % Value changed function: DrawAxis2CheckBox
        function DrawAxis2CheckBoxValueChanged(app, event)
            app.DrawAxis2 = app.DrawAxis2CheckBox.Value;
            try
                app.SelectFileListBox.Value=app.CurrentFile;
                SelectFileListBoxValueChanged(app, event);
            catch
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ActuatorControlUIFigure = uifigure;
            app.ActuatorControlUIFigure.Name = 'Actuator Control';
            app.ActuatorControlUIFigure.CloseRequestFcn = createCallbackFcn(app, @ActuatorControlUIFigureCloseRequest, true);
            app.ConfigPanel = uipanel(app.ActuatorControlUIFigure);
            app.ConfigPanel.Title = 'Config';
            app.COMPortEditFieldLabel = uilabel(app.ConfigPanel);
            app.COMPortEditFieldLabel.Text = 'COM Port';
            app.COMPort = uieditfield(app.ConfigPanel, 'text');
            app.COMPort.Value = 'COM5';
            app.ConnectandInitializeallaxesButton = uibutton(app.ConfigPanel, 'push');
            app.ConnectandInitializeallaxesButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectInitializeallaxesButtonPushed, true);
            app.ConnectandInitializeallaxesButton.Text = 'Connect and Initialize all axes';
            app.NumberofAxesEditFieldLabel = uilabel(app.ConfigPanel);
            app.NumberofAxesEditFieldLabel.Text = 'Number of Axes';
            app.NumberofAxes = uieditfield(app.ConfigPanel, 'numeric');
            app.NumberofAxes.Limits = [1 16];
            app.NumberofAxes.Value = 2;
            app.MaxSpeedmmsEditFieldLabel = uilabel(app.ConfigPanel);
            app.MaxSpeedmmsEditFieldLabel.Text = 'Max Speed (mm/s)';
            app.MaxSpeedEditField = uieditfield(app.ConfigPanel, 'numeric');
            app.MaxSpeedEditField.Limits = [0 999];
            app.MaxSpeedEditField.ValueDisplayFormat = '%.0f';
            app.MaxSpeedEditField.Value = 120;
            app.CurrentPositionmmEditFieldLabel = uilabel(app.ActuatorControlUIFigure);
            app.CurrentPositionmmEditFieldLabel.Text = 'Current Position (mm)';
            app.CurrentPositionmmEditField = uieditfield(app.ActuatorControlUIFigure, 'numeric');
            app.CurrentPositionmmEditField.ValueDisplayFormat = '%.1f';
            app.CurrentPositionmmEditField.Editable = 'off';
            app.AxisSelectionListBoxLabel = uilabel(app.ActuatorControlUIFigure);
            app.AxisSelectionListBoxLabel.Text = 'Axis Selection';
            app.AxisSelectionListBox = uilistbox(app.ActuatorControlUIFigure);
            app.AxisSelectionListBox.Items = {};
            app.AxisSelectionListBox.ValueChangedFcn = createCallbackFcn(app, @AxisSelectionListBoxValueChanged, true);
            app.AxisSelectionListBox.Value = {};
            app.AutoRunPanel = uipanel(app.ActuatorControlUIFigure);
            app.AutoRunPanel.Title = 'Auto Run';
            app.TabGroup = uitabgroup(app.AutoRunPanel);
            app.CustomFileTab = uitab(app.TabGroup);
            app.CustomFileTab.Title = 'Custom File';
            app.LoadTracetoAxesButton = uibutton(app.CustomFileTab, 'push');
            app.LoadTracetoAxesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTracetoAxesButtonPushed, true);
            app.LoadTracetoAxesButton.Text = 'Load Trace to Axes';
            app.EraseCurrentTraceButton = uibutton(app.CustomFileTab, 'push');
            app.EraseCurrentTraceButton.ButtonPushedFcn = createCallbackFcn(app, @ErasetraceforcurrentaxisButtonPushed, true);
            app.EraseCurrentTraceButton.Text = 'Erase Current Trace';
            app.StartAxesButton = uibutton(app.CustomFileTab, 'push');
            app.StartAxesButton.ButtonPushedFcn = createCallbackFcn(app, @StartAllAxesButtonPushed, true);
            app.StartAxesButton.Text = ' Start Axes';
            app.StopAxesButton = uibutton(app.CustomFileTab, 'push');
            app.StopAxesButton.ButtonPushedFcn = createCallbackFcn(app, @StopAllAxesButtonPushed, true);
            app.StopAxesButton.Text = 'Stop Axes';
            app.UIAxes_2 = uiaxes(app.CustomFileTab);
            xlabel(app.UIAxes_2, 'Time (sec)')
            ylabel(app.UIAxes_2, 'Position (mm)')
            app.SelectFileListBoxLabel = uilabel(app.CustomFileTab);
            app.SelectFileListBoxLabel.Text = 'Select File';
            app.SelectFileListBox = uilistbox(app.CustomFileTab);
            app.SelectFileListBox.Items = {};
            app.SelectFileListBox.ValueChangedFcn = createCallbackFcn(app, @SelectFileListBoxValueChanged, true);
            app.SelectFileListBox.Value = {};
            app.BrowseButton = uibutton(app.CustomFileTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SelectFolderEditFieldLabel = uilabel(app.CustomFileTab);
            app.SelectFolderEditFieldLabel.Text = 'Select Folder';
            app.SelectFolderEditField = uieditfield(app.CustomFileTab, 'text');
            app.SelectFolderEditField.Editable = 'off';
            app.DrawAxis2CheckBox = uicheckbox(app.CustomFileTab);
            app.DrawAxis2CheckBox.ValueChangedFcn = createCallbackFcn(app, @DrawAxis2CheckBoxValueChanged, true);
            app.DrawAxis2CheckBox.Text = 'Draw Axis 2';
            app.CommonfunctionsTab = uitab(app.TabGroup);
            app.CommonfunctionsTab.Title = 'Common functions';
            app.UIAxes = uiaxes(app.CommonfunctionsTab);
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Position')
            app.SavetraceforcurrentaxisButton = uibutton(app.CommonfunctionsTab, 'push');
            app.SavetraceforcurrentaxisButton.Text = 'Save trace for current axis';
            app.ErasetraceforcurrentaxisButton = uibutton(app.CommonfunctionsTab, 'push');
            app.ErasetraceforcurrentaxisButton.ButtonPushedFcn = createCallbackFcn(app, @ErasetraceforcurrentaxisButtonPushed, true);
            app.ErasetraceforcurrentaxisButton.Text = 'Erase trace for current axis';
            app.StartAllAxesButton = uibutton(app.CommonfunctionsTab, 'push');
            app.StartAllAxesButton.ButtonPushedFcn = createCallbackFcn(app, @StartAllAxesButtonPushed, true);
            app.StartAllAxesButton.Text = ' Start All Axes';
            app.StopAllAxesButton = uibutton(app.CommonfunctionsTab, 'push');
            app.StopAllAxesButton.ButtonPushedFcn = createCallbackFcn(app, @StopAllAxesButtonPushed, true);
            app.StopAllAxesButton.Text = 'Stop All Axes';
            app.WaveformDropDownLabel = uilabel(app.CommonfunctionsTab);
            app.WaveformDropDownLabel.Text = 'Wave form';
            app.WaveformDropDown = uidropdown(app.CommonfunctionsTab);
            app.WaveformDropDown.Items = {'Sin', 'Triangle', 'Square'};
            app.WaveformDropDown.ValueChangedFcn = createCallbackFcn(app, @WaveformSpinnerValueChanged, true);
            app.WaveformDropDown.Value = 'Sin';
            app.PeakPositionSliderLabel = uilabel(app.CommonfunctionsTab);
            app.PeakPositionSliderLabel.Visible = 'off';
            app.PeakPositionSliderLabel.Text = 'Peak Position';
            app.PeakPositionSlider = uislider(app.CommonfunctionsTab);
            app.PeakPositionSlider.Limits = [0 1];
            app.PeakPositionSlider.ValueChangedFcn = createCallbackFcn(app, @WaveformSpinnerValueChanged, true);
            app.PeakPositionSlider.Visible = 'off';
            app.PeakPositionSlider.Value = 0.5;
            app.CenterlevelmmSpinnerLabel = uilabel(app.CommonfunctionsTab);
            app.CenterlevelmmSpinnerLabel.Text = 'Center level (mm)';
            app.CenterlevelmmSpinner = uispinner(app.CommonfunctionsTab);
            app.CenterlevelmmSpinner.ValueChangingFcn = createCallbackFcn(app, @WaveformSpinnerValueChanged, true);
            app.CenterlevelmmSpinner.Limits = [0 800];
            app.CenterlevelmmSpinner.ValueDisplayFormat = '%.0f';
            app.CenterlevelmmSpinner.ValueChangedFcn = createCallbackFcn(app, @WaveformSpinnerValueChanged, true);
            app.CenterlevelmmSpinner.Value = 400;
            app.AmplitudemmSpinnerLabel = uilabel(app.CommonfunctionsTab);
            app.AmplitudemmSpinnerLabel.Text = 'Amplitude (mm)';
            app.AmplitudemmSpinner = uispinner(app.CommonfunctionsTab);
            app.AmplitudemmSpinner.ValueChangingFcn = createCallbackFcn(app, @WaveformSpinnerValueChanged, true);
            app.AmplitudemmSpinner.Limits = [0 800];
            app.AmplitudemmSpinner.ValueDisplayFormat = '%.0f';
            app.AmplitudemmSpinner.ValueChangedFcn = createCallbackFcn(app, @WaveformSpinnerValueChanged, true);
            app.AmplitudemmSpinner.Value = 200;
            app.PeriodsSpinnerLabel = uilabel(app.CommonfunctionsTab);
            app.PeriodsSpinnerLabel.Text = 'Period (s)';
            app.PeriodsSpinner = uispinner(app.CommonfunctionsTab);
            app.PeriodsSpinner.ValueChangingFcn = createCallbackFcn(app, @WaveformSpinnerValueChanged, true);
            app.PeriodsSpinner.ValueDisplayFormat = '%.0f';
            app.PeriodsSpinner.ValueChangedFcn = createCallbackFcn(app, @WaveformSpinnerValueChanged, true);
            app.PeriodsSpinner.Value = 20;
            app.DurationsSpinnerLabel = uilabel(app.CommonfunctionsTab);
            app.DurationsSpinnerLabel.Text = 'Duration (s)';
            app.DurationsSpinner = uispinner(app.CommonfunctionsTab);
            app.DurationsSpinner.ValueChangingFcn = createCallbackFcn(app, @WaveformSpinnerValueChanged, true);
            app.DurationsSpinner.Limits = [0 Inf];
            app.DurationsSpinner.ValueDisplayFormat = '%.0f';
            app.DurationsSpinner.ValueChangedFcn = createCallbackFcn(app, @WaveformSpinnerValueChanged, true);
            app.DurationsSpinner.Value = 60;
            app.PhasebiasdegSpinnerLabel = uilabel(app.CommonfunctionsTab);
            app.PhasebiasdegSpinnerLabel.Text = 'Phase bias (deg)';
            app.PhasebiasdegSpinner = uispinner(app.CommonfunctionsTab);
            app.PhasebiasdegSpinner.ValueChangingFcn = createCallbackFcn(app, @WaveformSpinnerValueChanged, true);
            app.PhasebiasdegSpinner.ValueDisplayFormat = '%.0f';
            app.PhasebiasdegSpinner.ValueChangedFcn = createCallbackFcn(app, @WaveformSpinnerValueChanged, true);
            app.ManualControlPanel = uipanel(app.ActuatorControlUIFigure);
            app.ManualControlPanel.Title = 'Manual Control';
            app.GoButton = uibutton(app.ManualControlPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go!';
            app.InchButtonPlus = uibutton(app.ManualControlPanel, 'push');
            app.InchButtonPlus.ButtonPushedFcn = createCallbackFcn(app, @InchButtonPlusPushed, true);
            app.InchButtonPlus.Text = 'Inch+';
            app.InchButtonMinus = uibutton(app.ManualControlPanel, 'push');
            app.InchButtonMinus.ButtonPushedFcn = createCallbackFcn(app, @InchButtonMinusPushed, true);
            app.InchButtonMinus.Text = 'Inch-';
            app.BalancedPositionmmLabel = uilabel(app.ManualControlPanel);
            app.BalancedPositionmmLabel.Text = 'Balanced Position (mm)';
            app.TargetPosition = uieditfield(app.ManualControlPanel, 'numeric');
            app.TargetPosition.Limits = [0 80000];
            app.TargetPosition.ValueDisplayFormat = '%.0f';
            app.InchDistancemmSliderLabel = uilabel(app.ManualControlPanel);
            app.InchDistancemmSliderLabel.Text = 'Inch Distance (mm)';
            app.InchDistancemmSlider = uislider(app.ManualControlPanel);
            app.InchDistancemmSlider.Limits = [0 50];
            app.InchDistancemmSlider.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50];
            app.InchDistancemmSlider.Value = 25;
            app.StatusTextAreaLabel = uilabel(app.ActuatorControlUIFigure);
            app.StatusTextAreaLabel.Visible = 'off';
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.ActuatorControlUIFigure);
            app.StatusTextArea.Editable = 'off';
            app.StatusTextArea.Visible = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Actuator_Control_V3_1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ActuatorControlUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ActuatorControlUIFigure)
        end
    end
end