classdef Image_Processing_V1_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ActuatorControlUIFigure  matlab.ui.Figure
        M_File                   matlab.ui.container.Menu
        MM_Fi_SaveSettings       matlab.ui.container.Menu
        MM_Fi_LoadSettings       matlab.ui.container.Menu
        MM_Fi_Exit               matlab.ui.container.Menu
        M_Settings               matlab.ui.container.Menu
        MM_Se_UseCUDA            matlab.ui.container.Menu
        M_Help                   matlab.ui.container.Menu
        MM_He_Menu               matlab.ui.container.Menu
        MM_He_About              matlab.ui.container.Menu
        TabGroup                 matlab.ui.container.TabGroup
        T_SelectImages           matlab.ui.container.Tab
        TA_Se_SelectImages       matlab.ui.control.UIAxes
        TP_Se_Files              matlab.ui.container.Panel
        AddressEditFieldLabel    matlab.ui.control.Label
        TPF_SeFi_Field           matlab.ui.control.EditField
        TPL_SeFi_FileListBox     matlab.ui.control.ListBox
        TPB_SeFi_CopyAddress     matlab.ui.control.Button
        TPB_SeFi_BrowseFiles     matlab.ui.control.Button
        TPC_SeFi_TrackNew        matlab.ui.control.CheckBox
        TC_Se_Quickadjust        matlab.ui.control.CheckBox
        TextAreaLabel            matlab.ui.control.Label
        TextArea                 matlab.ui.control.TextArea
        ImageProcessingTab       matlab.ui.container.Tab
        FilesPanel_2             matlab.ui.container.Panel
        UITable                  matlab.ui.control.Table
    end

    properties (Access = private)
        FileDirectory               % directory of image files
        CurrentFile                 % the file under editing
        WorkingDirectory            % working directory
        CurrentImages               % A cell array storing all the images we are currently working on
        % by default, 1st - raw image, 2nd - background, 3rd - background removed
        % nth - further operations
    end
    
    methods (Access = private)
        
        function image = ReadImage(app,fullFileName)
            % use the toolbox Bio-Formats 5.9.1. and the script I wrote ('bfReadImage.m') to load the image
            tempcd=cd;
            cd(app.WorkingDirectory);
            cd('bfmatlab');
            image=bfReadImage(fullFileName);
            cd(tempcd);
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.WorkingDirectory=cd;
        end

        % Callback function: ActuatorControlUIFigure, MM_Fi_Exit
        function ActuatorControlUIFigureCloseRequest(app, event)
            delete(app);
        end

        % Button pushed function: TPB_SeFi_BrowseFiles
        function TPB_SeFi_BrowseFilesPushed(app, event)
            
            % Get directory
            [tempfile,tempdir]=uigetfile();
            % Bring the window back to the front
            figure(app.ActuatorControlUIFigure);
            
            % Display directory to the "Select Folder" edit field
            if (tempdir==0)
                app.FileDirectory=app.WorkingDirectory;
            else
                app.FileDirectory=tempdir;
            end
            app.TPL_SeFi_FileListBox.Enable=true;
            app.TPF_SeFi_Field.Value=app.FileDirectory;
            tempdir=dir(app.FileDirectory);
            tempdir=struct2cell(tempdir(~cellfun(@(x) x,{tempdir.isdir})));
            app.TPL_SeFi_FileListBox.Items=tempdir(1,:);
            app.TPL_SeFi_FileListBox.Value=tempfile;
            TPL_SeFi_FileListBoxValueChanged(app);
        end

        % Button pushed function: TPB_SeFi_CopyAddress
        function TPB_SeFi_CopyAddressPushed(app, event)
            if (isempty(app.TPF_SeFi_Field.Value))
                uialert(app.ActuatorControlUIFigure,{'The address field is empty.'},...
                    'Copy address','Icon','warning');
            else
                clipboard('copy',app.TPF_SeFi_Field.Value);
                uialert(app.ActuatorControlUIFigure,{'Current address copied:';app.TPF_SeFi_Field.Value},...
                    'Copy address','Icon','success');
            end
        end

        % Value changed function: TPL_SeFi_FileListBox
        function TPL_SeFi_FileListBoxValueChanged(app, event)
            %try
            app.CurrentFile=app.TPL_SeFi_FileListBox.Value;
            app.TextArea.Value=[app.FileDirectory,'\',app.CurrentFile];
                tempimage=ReadImage(app,[app.FileDirectory,'\',app.CurrentFile]);
            if (app.TC_Se_Quickadjust.Value)

            else
                imshow(tempimage,'Parent', app.TA_Se_SelectImages);
            end
            app.TPL_SeFi_FileListBox.Value={};
            %catch
            %end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ActuatorControlUIFigure = uifigure;
            app.ActuatorControlUIFigure.Name = 'Actuator Control';
            app.ActuatorControlUIFigure.CloseRequestFcn = createCallbackFcn(app, @ActuatorControlUIFigureCloseRequest, true);
            app.M_File = uimenu(app.ActuatorControlUIFigure);
            app.M_File.Text = 'File';
            app.MM_Fi_SaveSettings = uimenu(app.M_File);
            app.MM_Fi_SaveSettings.Text = 'Save Settings';
            app.MM_Fi_LoadSettings = uimenu(app.M_File);
            app.MM_Fi_LoadSettings.Text = 'Load Settings';
            app.MM_Fi_Exit = uimenu(app.M_File);
            app.MM_Fi_Exit.MenuSelectedFcn = createCallbackFcn(app, @ActuatorControlUIFigureCloseRequest, true);
            app.MM_Fi_Exit.Text = 'Exit';
            app.M_Settings = uimenu(app.ActuatorControlUIFigure);
            app.M_Settings.Text = 'Settings';
            app.MM_Se_UseCUDA = uimenu(app.M_Settings);
            app.MM_Se_UseCUDA.Checked = 'on';
            app.MM_Se_UseCUDA.Text = 'Use CUDA';
            app.M_Help = uimenu(app.ActuatorControlUIFigure);
            app.M_Help.Text = 'Help';
            app.MM_He_Menu = uimenu(app.M_Help);
            app.MM_He_Menu.Text = 'Menu';
            app.MM_He_About = uimenu(app.M_Help);
            app.MM_He_About.Text = 'About';
            app.TabGroup = uitabgroup(app.ActuatorControlUIFigure);
            app.T_SelectImages = uitab(app.TabGroup);
            app.T_SelectImages.Title = 'Select Images';
            app.TA_Se_SelectImages = uiaxes(app.T_SelectImages);
            title(app.TA_Se_SelectImages, 'Title')
            xlabel(app.TA_Se_SelectImages, 'X')
            ylabel(app.TA_Se_SelectImages, 'Y')
            app.TP_Se_Files = uipanel(app.T_SelectImages);
            app.TP_Se_Files.Title = 'Files';
            app.AddressEditFieldLabel = uilabel(app.TP_Se_Files);
            app.AddressEditFieldLabel.Text = 'Address:';
            app.TPF_SeFi_Field = uieditfield(app.TP_Se_Files, 'text');
            app.TPF_SeFi_Field.Editable = 'off';
            app.TPL_SeFi_FileListBox = uilistbox(app.TP_Se_Files);
            app.TPL_SeFi_FileListBox.Items = {};
            app.TPL_SeFi_FileListBox.ValueChangedFcn = createCallbackFcn(app, @TPL_SeFi_FileListBoxValueChanged, true);
            app.TPL_SeFi_FileListBox.Enable = 'off';
            app.TPL_SeFi_FileListBox.Value = {};
            app.TPB_SeFi_CopyAddress = uibutton(app.TP_Se_Files, 'push');
            app.TPB_SeFi_CopyAddress.ButtonPushedFcn = createCallbackFcn(app, @TPB_SeFi_CopyAddressPushed, true);
            app.TPB_SeFi_CopyAddress.Text = 'Copy Address';
            app.TPB_SeFi_BrowseFiles = uibutton(app.TP_Se_Files, 'push');
            app.TPB_SeFi_BrowseFiles.ButtonPushedFcn = createCallbackFcn(app, @TPB_SeFi_BrowseFilesPushed, true);
            app.TPB_SeFi_BrowseFiles.Text = 'Browse Files';
            app.TPC_SeFi_TrackNew = uicheckbox(app.TP_Se_Files);
            app.TPC_SeFi_TrackNew.Text = 'Automatically track the new image';
            app.TC_Se_Quickadjust = uicheckbox(app.T_SelectImages);
            app.TC_Se_Quickadjust.Text = 'Quick adjust';
            app.TC_Se_Quickadjust.Value = true;
            app.TextAreaLabel = uilabel(app.T_SelectImages);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.T_SelectImages);
            app.ImageProcessingTab = uitab(app.TabGroup);
            app.ImageProcessingTab.Title = 'Image Processing';
            app.FilesPanel_2 = uipanel(app.ImageProcessingTab);
            app.FilesPanel_2.Title = 'Files';
            app.UITable = uitable(app.FilesPanel_2);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Image_Processing_V1_0

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ActuatorControlUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ActuatorControlUIFigure)
        end
    end
end